classdef SignalVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalVisualizerUIFigure      matlab.ui.Figure
        SignalVisualizerMenu          matlab.ui.container.Menu
        InfoMenu                      matlab.ui.container.Menu
        SalirMenu                     matlab.ui.container.Menu
        GenerarMenu                   matlab.ui.container.Menu
        TonopuroMenu                  matlab.ui.container.Menu
        SntesisdearmnicosMenu         matlab.ui.container.Menu
        SealesperidicasconocidasMenu  matlab.ui.container.Menu
        OndacuadradaMenu              matlab.ui.container.Menu
        DientedesierraMenu            matlab.ui.container.Menu
        PulsodeRosenbergMenu          matlab.ui.container.Menu
        RuidoMenu                     matlab.ui.container.Menu
        InputMenu                     matlab.ui.container.Menu
        CargarMenu                    matlab.ui.container.Menu
        GrabarMenu                    matlab.ui.container.Menu
        OpcionesMenu                  matlab.ui.container.Menu
        Panel                         matlab.ui.container.Panel
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        Button_6                      matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        Button_9                      matlab.ui.control.Button
        Button_10                     matlab.ui.control.Button
        Button_11                     matlab.ui.control.Button
        Button_12                     matlab.ui.control.Button
        Button_13                     matlab.ui.control.Button
        Button_14                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        DialogApp % Cuadro de diálogo (módulo de generación, de importación o de configuración)
        PlotApp1 % PlotAppx -> Ventanas de visualización
        PlotApp2
        PlotApp3
        PlotApp4
        PlotApp5
        PlotApp6
        PlotApp7
        PlotApp8
        PlotApp9
        PlotApp10
        PlotApp11
        PlotApp12
        PlotApp13
        PlotApp14
        HelpApp % Ventana de ayuda de usuario        
        fs = 48000; % Frecuencia de muestreo por defecto
        language = 1; % Idioma por defecto (1 -> inglés)
        plotAppArray % Cell array con strings que nombran los PlotApp que se van a utilizar.
        wMax % Número máximo de ventanas
        userData % Configuración personalizada (struct)
        
        windowOriginalWidth = 630; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 549; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        FILENAME1 % Path del fichero donde se guarda la configuración
    end
    
    properties (Access = public)
        signal = [];
        time % Array de tiempo que acompaña a la señal
        spectrum = [];
        freq = []; % Array de frecuencias que acompaña al espectro
        plotApp_counter % Número de ventanas de visualización abiertas
    end
    
    methods (Access = public)
        
        function success = createPlot(app,signal,t,name,varargin)
            % Crea una nueva ventana de visualización
            % Esta función es llamada por los módulos de generación e
            % importación
            
            % Comprueba si ya están abiertas el número máximo posible de
            % ventanas
            if app.plotApp_counter == app.userData.w_max
                success = 0;
                return
            end
            
            % Busca una variable PlotApp vacía o de una ventana ya cerrada
            % para crear una nueva
            for i = 1:app.userData.w_max
                if isempty(app.(app.plotAppArray{i}))   % Elije el primer bloque vacío
                    app.(app.plotAppArray{i}) = visualization_App(app,app.userData,signal,t,i,name,varargin{:});
                    app.plotApp_counter = app.plotApp_counter + 1;
                    app.Panel.Children(length(app.Panel.Children) - i + 1).Enable = 'on';
                    app.Panel.Children(length(app.Panel.Children) - i + 1).Text = name;
                    break
                elseif ~isvalid(app.(app.plotAppArray{i})) % Puede ser que el bloque haya sido usado, pero que la ventana ya haya sido cerrada
                    app.(app.plotAppArray{i}) = visualization_App(app,app.userData,signal,t,i,name,varargin{:});
                    app.plotApp_counter = app.plotApp_counter + 1;
                    app.Panel.Children(length(app.Panel.Children) - i + 1).Enable = 'on';
                    app.Panel.Children(length(app.Panel.Children) - i + 1).Text = name;
                    break
                end
            end
            
            success = 1;
        end
        
        function createHelp(app,module)
            % Crea la ventana de ayuda o, si ya está abierta, actualiza su
            % contenido para que muestre la ayuda del módulo que la ha
            % llamado
            
            if isempty(app.HelpApp)   % Si aún no se ha abierto la ventana de ayuda
                app.HelpApp = helpApp(module,app.userData.lang_ind);
            elseif ~isvalid(app.HelpApp) % Si se ha abierto y se ha cerrado
                app.HelpApp = helpApp(module,app.userData.lang_ind);
            else % Si está abierta, la trae al frente y carga el contenido del módulo que la ha llamado
                app.HelpApp.update_module(module);
                app.HelpApp.UIFigure.Visible = 'off';
                app.HelpApp.UIFigure.Visible = 'on';
            end
        end
        
        function update1(app,language,w_max)
            % Guarda la configuración personalizada del lenguaje y del
            % número máximo de ventanas de visualización que pueden abrirse
            % Traduce el módulo principal al idioma seleccionado
            % Esta función es llamada por el módulo de configuración
            
            app.userData.lang_ind = language;
            app.translate(language);
            app.userData.w_max = w_max;
            
            user_data = app.userData;
            
            save(app.FILENAME1,'user_data')
            clear("user_data")
        end
        
        function update2(app,bg_color,plot_color,start_color,end_color,selection_color)
            % Guarda la configuración personalizada del color de los
            % gráficos
            % Esta función es llamada por el módulo de configuración
            
            app.userData.bg_color = bg_color;
            app.userData.plot_color = plot_color;
            app.userData.start_color = start_color;
            app.userData.end_color = end_color;
            app.userData.selection_color = selection_color;
            
            user_data = app.userData;
            save(app.FILENAME1,'user_data')
            clear("user_data")
        end
        
        function update3(app,spectrogram_colormap)
            % Guarda la configuración personalizada del mapa de color de
            % los espectrogramas
            % Esta función es llamada por el módulo de configuración
            
            app.userData.spectrogram_colormap = spectrogram_colormap;
            
            user_data = app.userData;
            save(app.FILENAME1,'user_data')
            clear("user_data")
        end
    end
    
    methods (Access = private)
        
        
        function resizeWindow(app)
            % Adapta el tamaño de la interfaz a la resolución del monitor
            
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 630;
            originalh = 571;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.SignalVisualizerUIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element)
            % Adapta el tamaño del elemento element al nuevo tamaño de la
            % interfaz
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.SignalVisualizerUIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            % Adapta el tamaño de fuente
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
        end
        
        function translate(app,language)
            % Traduce la interfaz
            
            load('language.mat','main')
            
            app.SalirMenu.Text = main.exit{language};
            app.GenerarMenu.Text = main.generate{language};
            app.TonopuroMenu.Text = main.pure_tone{language};
            app.SntesisdearmnicosMenu.Text = main.harmonics_synthesis{language};
            app.SealesperidicasconocidasMenu.Text = main.known_periodic_signals{language};
            app.OndacuadradaMenu.Text = main.square_wave{language};
            app.DientedesierraMenu.Text = main.sawtooth_wave{language};
            app.PulsodeRosenbergMenu.Text = main.rosenberg_pulse{language};
            app.RuidoMenu.Text = main.noise{language};
            
            app.CargarMenu.Text = main.load{language};
            app.GrabarMenu.Text = main.record{language};
            
            app.OpcionesMenu.Text = main.options{language};
            
            buttonMax = length(app.Panel.Children); % Número total de botones existentes (14)
            
            for i = 1:buttonMax
                if strcmp(app.Panel.Children(buttonMax - i + 1).Enable,'off')
                    app.Panel.Children(buttonMax - i + 1).Text = strcat(main.window{language}," ",int2str(i));
                end
            end
            
            clear('main')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Comprobar si hay datos de usuario guardados
            if isdeployed
                % Si la aplicación ya ha sido instalada en local, busca el
                % fichero en la carpeta de datos de usuario
                % In deployed mode, all file under CTFRoot in the path are loaded
                % by full path name or relative to $ctfroot.
                SAVEPATH = strcat(getenv('APPDATA'),filesep,'SV User Data');
                if ( ~isfolder(SAVEPATH))
                    mkdir(SAVEPATH);
                end
                app.FILENAME1 = strcat(SAVEPATH,filesep,'user_data.mat');
            else
                % Si se está probando la aplicación en Matlab
                app.FILENAME1 = 'user_data.mat';
            end
            
            % Si no hay datos guardados, carga la configuración por defecto
            if isfile(app.FILENAME1) ~= 1
                app.userData.lang_ind = 1;
                app.userData.w_max = 4;
                app.userData.plot_color = [0.00,0.45,0.74];
                app.userData.bg_color = [1,1,1];
                app.userData.start_color = [1,0,0];
                app.userData.end_color = [0,0,1];
                app.userData.selection_color = [.9,.9,.9];
                app.userData.spectrogram_colormap = 'jet';
                user_data = app.userData;
                
                save(app.FILENAME1,'user_data')
                clear("user_data")
            else
                load(app.FILENAME1,'user_data')
                app.userData = user_data;
                clear("user_data")
            end
            
            app.translate(app.userData.lang_ind); % Traduce la interfaz           
            
            % Crea un cell array de string para referenciar a las
            % propiedades PlotApp donde se guardarán las ventanas de visualización...
            app.plotApp_counter = 0;
            app.plotAppArray = {'PlotApp1','PlotApp2','PlotApp3','PlotApp4','PlotApp5','PlotApp6','PlotApp7','PlotApp8','PlotApp9','PlotApp10','PlotApp11','PlotApp12','PlotApp13','PlotApp14'};
            app.plotAppArray = app.plotAppArray(1:app.userData.w_max);            
            
            % ...y hace visibles los botones correspondientes
            buttonMax = length(app.Panel.Children); % Número total de botones existentes (14)
            
            for i = 1:buttonMax
                if i <= app.userData.w_max
                    app.Panel.Children(buttonMax - i + 1).Visible = 'on';
                else
                    break
                end
            end
        end

        % Menu selected function: TonopuroMenu
        function TonopuroMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de generación de
            % tonos puros
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = tone_App(app,app.userData);
        end

        % Close request function: SignalVisualizerUIFigure
        function SignalVisualizerUIFigureCloseRequest(app, event)
            % Se ejecuta al solicitar el cierre del módulo principal
            
            delete(app.DialogApp)
            
            % Busca y cierra las ventanas de visualización abiertas
            for i = 1:app.userData.w_max
                if ~isempty(app.(app.plotAppArray{i}))
                    if isvalid(app.(app.plotAppArray{i}))
                        delete(app.(app.plotAppArray{i}))
                    end
                end
            end
            
            if ~isempty(app.HelpApp)
                if isvalid(app.HelpApp)
                    delete(app.HelpApp)
                end
            end
            
            % Cierra la app principal, deberían estar todas las ventanas
            % cerradas
            delete(app)
        end

        % Menu selected function: SntesisdearmnicosMenu
        function SntesisdearmnicosMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de suma libre de
            % tonos puros
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = harmonics_synthesis_App(app,app.userData);
        end

        % Menu selected function: OndacuadradaMenu
        function OndacuadradaMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de generación de
            % ondas cuadradas
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = square_App(app,app.userData);
        end

        % Menu selected function: DientedesierraMenu
        function DientedesierraMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de generación de
            % ondas de diente de sierra
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = sawtooth_App(app,app.userData);
        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            % Misma función que pulsando botón de cierre de ventana
            SignalVisualizerUIFigureCloseRequest(app,event);
        end

        % Menu selected function: PulsodeRosenbergMenu
        function PulsodeRosenbergMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de generación de
            % pulsos de Rosenberg
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = rosenberg_App(app,app.userData);
        end

        % Menu selected function: RuidoMenu
        function RuidoMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de generación de
            % ruido
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = noise_app(app,app.userData);
        end

        % Size changed function: SignalVisualizerUIFigure
        function SignalVisualizerUIFigureSizeChanged(app, event)
            % Se ejecuta al abrir el módulo principal
            
            % La ventana se ajusta a la resolución de la pantalla. Sólo al 
            % abrir la ventana por primera vez
            if app.windowResized == 0
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            % Se redimensiona el tamaño de los elementos de la interfaz
            % Excepto los menús, que no pueden ser redimensionados
            for i = 1:length(app.SignalVisualizerUIFigure.Children)
                if strcmp(app.SignalVisualizerUIFigure.Children(i).Type,'uimenu') == 0
                    app.resizeelement(app.SignalVisualizerUIFigure.Children(i));
                end
            end
            
            % Se redimensionan los botones
            for i = 1:length(app.Panel.Children)
                app.resizeelement(app.Panel.Children(i));
            end
            
            position = app.SignalVisualizerUIFigure.Position;
            % Se actualiza la info sobre el tamaño actual
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Al pulsar el botón, se trae la ventana de visualización
            % asociada al frente
            
            app.PlotApp1.UIFigure.Visible = 'off';
            app.PlotApp1.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.PlotApp2.UIFigure.Visible = 'off';
            app.PlotApp2.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.PlotApp3.UIFigure.Visible = 'off';
            app.PlotApp3.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.PlotApp4.UIFigure.Visible = 'off';
            app.PlotApp4.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.PlotApp5.Visible = 'off';
            app.PlotApp5.Visible = 'on';
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.PlotApp6.UIFigure.Visible = 'off';
            app.PlotApp6.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.PlotApp7.UIFigure.Visible = 'off';
            app.PlotApp7.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.PlotApp8.UIFigure.Visible = 'off';
            app.PlotApp8.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.PlotApp9.UIFigure.Visible = 'off';
            app.PlotApp9.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.PlotApp10.UIFigure.Visible = 'off';
            app.PlotApp10.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.PlotApp11.UIFigure.Visible = 'off';
            app.PlotApp11.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.PlotApp12.UIFigure.Visible = 'off';
            app.PlotApp12.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.PlotApp13.UIFigure.Visible = 'off';
            app.PlotApp13.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.PlotApp14.UIFigure.Visible = 'off';
            app.PlotApp14.UIFigure.Visible = 'on';
        end

        % Menu selected function: CargarMenu
        function CargarMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de carga de
            % ficheros de audio
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
                        
            app.DialogApp = load_App(app,app.userData);
        end

        % Menu selected function: GrabarMenu
        function GrabarMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de grabación
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = record_App(app,app.userData);
        end

        % Menu selected function: OpcionesMenu
        function OpcionesMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de configuración
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = control_App(app,app.userData);
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            % Deshabilita los menús y crea abre el módulo de los créditos
            % de la aplicación
            
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.InputMenu.Enable = 'off';
            app.OpcionesMenu.Enable = 'off';
            
            
            app.DialogApp = info_App(app,app.userData.lang_ind);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalVisualizerUIFigure = uifigure('Visible', 'off');
            app.SignalVisualizerUIFigure.Name = 'Signal Visualizer';
            app.SignalVisualizerUIFigure.CloseRequestFcn = createCallbackFcn(app, @SignalVisualizerUIFigureCloseRequest, true);
            app.SignalVisualizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @SignalVisualizerUIFigureSizeChanged, true);
            app.SignalVisualizerMenu = uimenu(app.SignalVisualizerUIFigure);
            app.SignalVisualizerMenu.Text = 'Signal Visualizer';
            app.InfoMenu = uimenu(app.SignalVisualizerMenu);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.SalirMenu = uimenu(app.SignalVisualizerMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            app.GenerarMenu = uimenu(app.SignalVisualizerUIFigure);
            app.GenerarMenu.Text = 'Generar';
            app.TonopuroMenu = uimenu(app.GenerarMenu);
            app.TonopuroMenu.MenuSelectedFcn = createCallbackFcn(app, @TonopuroMenuSelected, true);
            app.TonopuroMenu.Text = 'Tono puro';
            app.SntesisdearmnicosMenu = uimenu(app.GenerarMenu);
            app.SntesisdearmnicosMenu.MenuSelectedFcn = createCallbackFcn(app, @SntesisdearmnicosMenuSelected, true);
            app.SntesisdearmnicosMenu.Text = 'Síntesis de armónicos';
            app.SealesperidicasconocidasMenu = uimenu(app.GenerarMenu);
            app.SealesperidicasconocidasMenu.Text = 'Señales periódicas conocidas';
            app.OndacuadradaMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.OndacuadradaMenu.MenuSelectedFcn = createCallbackFcn(app, @OndacuadradaMenuSelected, true);
            app.OndacuadradaMenu.Text = 'Onda cuadrada';
            app.DientedesierraMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.DientedesierraMenu.MenuSelectedFcn = createCallbackFcn(app, @DientedesierraMenuSelected, true);
            app.DientedesierraMenu.Text = 'Diente de sierra';
            app.PulsodeRosenbergMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.PulsodeRosenbergMenu.MenuSelectedFcn = createCallbackFcn(app, @PulsodeRosenbergMenuSelected, true);
            app.PulsodeRosenbergMenu.Text = 'Pulso de Rosenberg';
            app.RuidoMenu = uimenu(app.GenerarMenu);
            app.RuidoMenu.MenuSelectedFcn = createCallbackFcn(app, @RuidoMenuSelected, true);
            app.RuidoMenu.Text = 'Ruido';
            app.InputMenu = uimenu(app.SignalVisualizerUIFigure);
            app.InputMenu.Text = 'Input';
            app.CargarMenu = uimenu(app.InputMenu);
            app.CargarMenu.MenuSelectedFcn = createCallbackFcn(app, @CargarMenuSelected, true);
            app.CargarMenu.Text = 'Cargar';
            app.GrabarMenu = uimenu(app.InputMenu);
            app.GrabarMenu.MenuSelectedFcn = createCallbackFcn(app, @GrabarMenuSelected, true);
            app.GrabarMenu.Text = 'Grabar';
            app.OpcionesMenu = uimenu(app.SignalVisualizerUIFigure);
            app.OpcionesMenu.MenuSelectedFcn = createCallbackFcn(app, @OpcionesMenuSelected, true);
            app.OpcionesMenu.Text = 'Opciones';
            app.Panel = uipanel(app.SignalVisualizerUIFigure);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Visible = 'off';
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Visible = 'off';
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Visible = 'off';
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Visible = 'off';
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Visible = 'off';
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Enable = 'off';
            app.Button_7.Visible = 'off';
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Enable = 'off';
            app.Button_8.Visible = 'off';
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.Panel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Enable = 'off';
            app.Button_9.Visible = 'off';
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.Panel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Enable = 'off';
            app.Button_10.Visible = 'off';
            app.Button_10.Text = '10';
            app.Button_11 = uibutton(app.Panel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Enable = 'off';
            app.Button_11.Visible = 'off';
            app.Button_11.Text = '11';
            app.Button_12 = uibutton(app.Panel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Enable = 'off';
            app.Button_12.Visible = 'off';
            app.Button_12.Text = '12';
            app.Button_13 = uibutton(app.Panel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Enable = 'off';
            app.Button_13.Visible = 'off';
            app.Button_13.Text = '13';
            app.Button_14 = uibutton(app.Panel, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Enable = 'off';
            app.Button_14.Visible = 'off';
            app.Button_14.Text = '14';
            % Show the figure after all components are created
            app.SignalVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalVisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalVisualizerUIFigure)
        end
    end
end