classdef control_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        Language                      matlab.ui.control.DropDown
        IdiomaLabel                   matlab.ui.control.Label
        Windows                       matlab.ui.control.DropDown
        MxventanasLabel               matlab.ui.control.Label
        GuardarcambiosGeneralButton   matlab.ui.control.Button
        ResetGeneralButton            matlab.ui.control.Button
        SealTab                       matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        ColordelasealPanel            matlab.ui.container.Panel
        plot_color1Button             matlab.ui.control.Button
        plot_color2Button             matlab.ui.control.Button
        plot_color3Button             matlab.ui.control.Button
        plot_color4Button             matlab.ui.control.Button
        plot_color5Button             matlab.ui.control.Button
        plot_color6Button             matlab.ui.control.Button
        plot_color7Button             matlab.ui.control.Button
        plot_color8Button             matlab.ui.control.Button
        plot_color9Button             matlab.ui.control.Button
        plot_color10Button            matlab.ui.control.Button
        plot_color11Button            matlab.ui.control.Button
        plot_color12Button            matlab.ui.control.Button
        plot_color13Button            matlab.ui.control.Button
        plot_color14Button            matlab.ui.control.Button
        ColordelfondoPanel            matlab.ui.container.Panel
        bg_color1Button               matlab.ui.control.Button
        bg_color2Button               matlab.ui.control.Button
        bg_color3Button               matlab.ui.control.Button
        bg_color4Button               matlab.ui.control.Button
        bg_color5Button               matlab.ui.control.Button
        bg_color6Button               matlab.ui.control.Button
        bg_color7Button               matlab.ui.control.Button
        bg_color8Button               matlab.ui.control.Button
        bg_color9Button               matlab.ui.control.Button
        bg_color10Button              matlab.ui.control.Button
        bg_color11Button              matlab.ui.control.Button
        bg_color12Button              matlab.ui.control.Button
        bg_color13Button              matlab.ui.control.Button
        GuardarcambiosPlotButton      matlab.ui.control.Button
        ResetPlotButton               matlab.ui.control.Button
        ComienzoseleccinPanel         matlab.ui.container.Panel
        start_color1Button            matlab.ui.control.Button
        start_color2Button            matlab.ui.control.Button
        start_color4Button            matlab.ui.control.Button
        start_color3Button            matlab.ui.control.Button
        start_color8Button            matlab.ui.control.Button
        start_color7Button            matlab.ui.control.Button
        start_color5Button            matlab.ui.control.Button
        start_color6Button            matlab.ui.control.Button
        FinseleccinPanel              matlab.ui.container.Panel
        end_color2Button              matlab.ui.control.Button
        end_color1Button              matlab.ui.control.Button
        end_color4Button              matlab.ui.control.Button
        end_color3Button              matlab.ui.control.Button
        end_color8Button              matlab.ui.control.Button
        end_color7Button              matlab.ui.control.Button
        end_color5Button              matlab.ui.control.Button
        end_color6Button              matlab.ui.control.Button
        SeleccinVentanaPanel          matlab.ui.container.Panel
        selection_color2Button        matlab.ui.control.Button
        selection_color1Button        matlab.ui.control.Button
        selection_color4Button        matlab.ui.control.Button
        selection_color3Button        matlab.ui.control.Button
        selection_color7Button        matlab.ui.control.Button
        selection_color8Button        matlab.ui.control.Button
        selection_color5Button        matlab.ui.control.Button
        selection_color6Button        matlab.ui.control.Button
        EspectrogramaTab              matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        JetButton                     matlab.ui.control.Button
        HotButton                     matlab.ui.control.Button
        HSVButton                     matlab.ui.control.Button
        AutumnButton                  matlab.ui.control.Button
        SummerButton                  matlab.ui.control.Button
        ParulaButton                  matlab.ui.control.Button
        WinterButton                  matlab.ui.control.Button
        CoolButton                    matlab.ui.control.Button
        CopperButton                  matlab.ui.control.Button
        PinkButton                    matlab.ui.control.Button
        BoneButton                    matlab.ui.control.Button
        GrayButton                    matlab.ui.control.Button
        GuardarcambiosColormapButton  matlab.ui.control.Button
        ResetColormapButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        saved_user_data % Refleja la configuración que está guardada en el fichero
        
        % Colores de los gráficos
        bg_color % Fondo
        plot_color % Trazo
        start_color % Cursor de inicio de selección
        end_color % Cursor de fin de selección
        selection_color % Color del segmento seleccionado, o del segmento enventanado
        
        lang_ind % Idioma: 1 -> Inglés; 2 -> Castellano; 3 -> Euskera
        FILENAME1 % Path del fichero donde se guarda la configuración
        
        % Señal de ejemplo para probar los colores del plot
        t % Array de tiempos
        x % Señal
        
        % Mensajes de warning
        save_warning1 = ['Los cambios se han guardado.' newline 'Es necesario reiniciar la aplicación para que se aplique el cambio en el número máximo de ventanas.'];
        save_warning2 = 'Los cambios se han guardado'
        spectrogram_colormap % Mapa de color utilizado en el espectrograma
        
        % Dimensiones originales de la ventana
        windowOriginalHeight = 523;
        windowOriginalWidth = 640;
        
        CallingApp % App principal
    end
    
    methods (Access = private)
        
        function resizeWindow(app)
            % Esta función adapta el tamaño de la ventana a la resolución
            % de la pantalla utilizada
            % En este módulo, la ventana sólo se redimensiona al abrir el
            % módulo. No es posible cambiar el tamaño de la ventana
            
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 640;
            originalh = 523;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
            
            % Redimensiona los elementos que contiene la ventana
            app.resizeelement(app.TabGroup);
            
            for i = 1:length(app.GeneralTab.Children)
                app.resizeelement(app.GeneralTab.Children(i));
            end
            
            for i = 1:length(app.SealTab.Children)
                app.resizeelement(app.SealTab.Children(i));
            end
            
            for i = 1:length(app.ColordelasealPanel.Children)
                app.resizeelement(app.ColordelasealPanel.Children(i));
            end
            
            for i = 1:length(app.ColordelfondoPanel.Children)
                app.resizeelement(app.ColordelfondoPanel.Children(i));
            end
            
            for i = 1:length(app.ComienzoseleccinPanel.Children)
                app.resizeelement(app.ComienzoseleccinPanel.Children(i));
            end
            
            for i = 1:length(app.FinseleccinPanel.Children)
                app.resizeelement(app.FinseleccinPanel.Children(i));
            end
            
            for i = 1:length(app.SeleccinVentanaPanel.Children)
                app.resizeelement(app.SeleccinVentanaPanel.Children(i));
            end
            
            for i = 1:length(app.EspectrogramaTab.Children)
                app.resizeelement(app.EspectrogramaTab.Children(i));
            end
            
            % Guarda el nuevo tamaño de la ventana
            position = app.UIFigure.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end
        
        function resizeelement(app,element)
            % Redimensiona el elemento element para adaptarlo al nuevo
            % tamaño de la ventana
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            if ~isempty(findprop(element,'FontSize'))
                element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            end
            
        end
        
        function translate(app,language)
            load('language.mat','controlTranslate')
            
            % Traducción de la sección 'General'
            app.GeneralTab.Title = controlTranslate.general{language};
            app.SealTab.Title = controlTranslate.signal{language};
            app.EspectrogramaTab.Title = controlTranslate.spectrogram{language};
            app.IdiomaLabel.Text = controlTranslate.language{language};
            app.MxventanasLabel.Text = controlTranslate.wMax{language};
            
            % Traducción de la sección 'Señal'
            app.ColordelasealPanel.Title = controlTranslate.signalColor{language};
            app.ColordelfondoPanel.Title = controlTranslate.bgColor{language};
            app.ComienzoseleccinPanel.Title = controlTranslate.selectionStart{language};
            app.FinseleccinPanel.Title = controlTranslate.selectionEnd{language};
            app.SeleccinVentanaPanel.Title = controlTranslate.selectionWindow{language};
            
            % Mensajes warning
            app.save_warning1 = controlTranslate.save_warning1{language};
            app.save_warning2 = controlTranslate.save_warning2{language};
            
            % Botones de guardado
            app.GuardarcambiosGeneralButton.Text = controlTranslate.saveChanges{language};
            app.GuardarcambiosPlotButton.Text = controlTranslate.saveChanges{language};
            app.GuardarcambiosColormapButton.Text = controlTranslate.saveChanges{language};
            
            clear('controlTranslate')
        end
        
        function updatePlot(app)
            % Actualiza el plot para que refleje los cambios
            
            % Limpia el plot
            cla(app.UIAxes);
            hold(app.UIAxes,'off');
            
            % Dibuja el fondo de la selección
            rectangle(app.UIAxes,'Position',[0.33,-1,0.33,2],"FaceColor",app.selection_color,'LineStyle',"none");
            
            % Dibuja la señal superpuesta
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.t,app.x,"Color",app.plot_color)
            
            % Dibuja los cursores de inicio y fin de selección
            plot(app.UIAxes,[0.33,0.33],app.UIAxes.YLim,"Color",app.start_color)
            plot(app.UIAxes,[0.66,0.66],app.UIAxes.YLim,"Color",app.end_color)
            
            % Establece el color de fondo del plot
            app.UIAxes.Color = app.bg_color;
        end
        
        function updateSpectrogram(app)
            % Actualiza el espectrograma para que refleje el cambio de
            % colormap
            
            % Los datos necesarios para dibujar el espectrograma están
            % guardados en 'espectrograma.mat'
            load('espectrograma.mat','s_espectrograma',"f_espectrograma",'t_espectrograma')
            imagesc(app.UIAxes2,t_espectrograma,f_espectrograma,s_espectrograma);
            colormap(app.UIAxes2,app.spectrogram_colormap);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, user_data)
            app.CallingApp = mainApp; % Referencia a la app principal
            
            resizeWindow(app); % Ajusta la ventana al tamaño de pantalla
            
            % Carga la configuración de usuario guardada
            app.saved_user_data = user_data;
            app.plot_color = app.saved_user_data.plot_color;
            app.bg_color = app.saved_user_data.bg_color;
            app.start_color = app.saved_user_data.start_color;
            app.end_color = app.saved_user_data.end_color;
            app.selection_color = app.saved_user_data.selection_color;
            app.spectrogram_colormap = app.saved_user_data.spectrogram_colormap;
            clear('user_data')
            
            app.translate(app.saved_user_data.lang_ind) % Traduce el módulo
            
            % Actualiza a los valores cargados
            app.Language.Value = app.Language.Items{app.saved_user_data.lang_ind};
            app.Windows.Value = app.Windows.Items{app.saved_user_data.w_max};
            
            % Datos para dibujar el plot de muestra en 'Señal'
            app.t = linspace(0,1,48000);
            app.x = sin(4*pi*app.t);
            
            % Dibuja el plot de miestra
            rectangle(app.UIAxes,'Position',[0.33,-1,0.33,2],"FaceColor",app.selection_color,'LineStyle',"none");
            
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.t,app.x,"Color",app.plot_color)
            plot(app.UIAxes,[0.33,0.33],app.UIAxes.YLim,"Color",app.start_color)
            plot(app.UIAxes,[0.66,0.66],app.UIAxes.YLim,"Color",app.end_color)
            app.UIAxes.Color = app.bg_color;
            
            app.UIAxes.Toolbar.Visible = 'off'; % Deshabilita las herramientas de UIAxes
            
            % Dibuja el espectrograma de muestra
            load('espectrograma.mat','s_espectrograma',"f_espectrograma",'t_espectrograma')
            imagesc(app.UIAxes2,t_espectrograma,f_espectrograma,s_espectrograma);
            set(app.UIAxes2,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            app.UIAxes2.YLim = [0,1000];
            
            app.UIAxes2.Toolbar.Visible = 'off'; % Deshabilita las herramientas de UIAxes2
            colormap(app.UIAxes2,app.spectrogram_colormap);
        end

        % Button pushed function: plot_color1Button
        function plot_color1ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color1Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color2Button
        function plot_color2ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color2Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color3Button
        function plot_color3ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color3Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color4Button
        function plot_color4ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color4Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color5Button
        function plot_color5ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color5Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color13Button
        function plot_color13ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color13Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color12Button
        function plot_color12ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color12Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color6Button
        function plot_color6ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color6Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color7Button
        function plot_color7ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color7Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color8Button
        function plot_color8ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color8Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color9Button
        function plot_color9ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color9Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color10Button
        function plot_color10ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color10Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color14Button
        function plot_color14ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color14Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: plot_color11Button
        function plot_color11ButtonButtonPushed(app, event)
            app.plot_color = app.plot_color11Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color10Button
        function bg_color10ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color10Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color12Button
        function bg_color12ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color12Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color3Button
        function bg_color3ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color3Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color4Button
        function bg_color4ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color4Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color5Button
        function bg_color5ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color5Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color2Button
        function bg_color2ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color2Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color1Button
        function bg_color1ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color1Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color11Button
        function bg_color11ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color11Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color8Button
        function bg_color8ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color8Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color9Button
        function bg_color9ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color9Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color6Button
        function bg_color6ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color6Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color13Button
        function bg_color13ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color13Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: bg_color7Button
        function bg_color7ButtonButtonPushed(app, event)
            app.bg_color = app.bg_color7Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: GuardarcambiosPlotButton
        function GuardarcambiosPlotButtonPushed(app, event)
            % Guarda los cambios de la ventana 'Signal'
            app.saved_user_data.bg_color = app.bg_color;
            app.saved_user_data.plot_color = app.plot_color;
            app.saved_user_data.start_color = app.start_color;
            app.saved_user_data.end_color = app.end_color;
            app.saved_user_data.selection_color = app.selection_color;
            
            % Utiliza la función update2 de la app principal
            update2(app.CallingApp,app.bg_color,app.plot_color,app.start_color,app.end_color,app.selection_color)
            
            % Muestra mensaje de confirmación
            warn = msgbox(app.save_warning2);
            uiwait(warn);
        end

        % Button pushed function: ResetPlotButton
        function ResetPlotButtonPushed(app, event)
            % Restablece los colores de plot originales
            
            app.plot_color = [0.00,0.45,0.74];
            app.bg_color = [1,1,1];
            app.start_color = [1,0,0];
            app.end_color = [0,0,1];
            app.selection_color = [.9,.9,.9];
            updatePlot(app)
        end

        % Button pushed function: JetButton
        function JetButtonPushed(app, event)
            app.spectrogram_colormap = 'jet';
            updateSpectrogram(app)
        end

        % Button pushed function: HotButton
        function HotButtonPushed(app, event)
            app.spectrogram_colormap = 'hot';
            updateSpectrogram(app)
        end

        % Button pushed function: HSVButton
        function HSVButtonPushed(app, event)
            app.spectrogram_colormap = 'hsv';
            updateSpectrogram(app)
        end

        % Button pushed function: AutumnButton
        function AutumnButtonPushed(app, event)
            app.spectrogram_colormap = 'autumn';
            updateSpectrogram(app)
        end

        % Button pushed function: SummerButton
        function SummerButtonPushed(app, event)
            app.spectrogram_colormap = 'summer';
            updateSpectrogram(app)
        end

        % Button pushed function: ParulaButton
        function ParulaButtonPushed(app, event)
            app.spectrogram_colormap = 'parula';
            updateSpectrogram(app)
        end

        % Button pushed function: WinterButton
        function WinterButtonPushed(app, event)
            app.spectrogram_colormap = 'winter';
            updateSpectrogram(app)
        end

        % Button pushed function: CoolButton
        function CoolButtonPushed(app, event)
            app.spectrogram_colormap = 'cool';
            updateSpectrogram(app)
        end

        % Button pushed function: CopperButton
        function CopperButtonPushed(app, event)
            app.spectrogram_colormap = 'copper';
            updateSpectrogram(app)
        end

        % Button pushed function: BoneButton
        function BoneButtonPushed(app, event)
            app.spectrogram_colormap = 'bone';
            updateSpectrogram(app)
        end

        % Button pushed function: GrayButton
        function GrayButtonPushed(app, event)
            app.spectrogram_colormap = 'gray';
            updateSpectrogram(app)
        end

        % Button pushed function: PinkButton
        function PinkButtonPushed(app, event)
            app.spectrogram_colormap = 'pink';
            updateSpectrogram(app)
        end

        % Button pushed function: GuardarcambiosColormapButton
        function GuardarcambiosColormapButtonPushed(app, event)
            app.saved_user_data.spectrogram_colormap = app.spectrogram_colormap;
            
            update3(app.CallingApp,app.saved_user_data.spectrogram_colormap)
            
            warn = msgbox(app.save_warning2);
            uiwait(warn);
        end

        % Button pushed function: ResetColormapButton
        function ResetColormapButtonPushed(app, event)
            app.spectrogram_colormap = 'jet';
            updateSpectrogram(app)
        end

        % Button pushed function: GuardarcambiosGeneralButton
        function GuardarcambiosGeneralButtonPushed(app, event)
            % Guarda los cambios en la pestaña 'General'
            
            app.saved_user_data.lang_ind = find(contains(app.Language.Items,app.Language.Value));
            app.saved_user_data.w_max = find(contains(app.Windows.Items,app.Windows.Value));
            
            app.translate(app.saved_user_data.lang_ind); % Aplica los cambios de traducción en este módulo
            
            % Utiliza la función update1 de la app principal
            update1(app.CallingApp,app.saved_user_data.lang_ind,app.saved_user_data.w_max)
            
            % Muestra mensaje de confirmación
            warn = msgbox(app.save_warning1);
            uiwait(warn);
        end

        % Button pushed function: ResetGeneralButton
        function ResetGeneralButtonPushed(app, event)
            % Se restauran los valalores originales de la pestaña 'General'
            
            app.Windows.Value = app.Windows.Items{4};
            app.Language.Value = app.Language.Items{1};
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Esta función se ejecuta al pulsar el botón de cierre de
            % ventana
            
            % Si está disponible la app principal, reactiva los menús
            % superiores
            if isvalid(app.CallingApp)
                app.CallingApp.SignalVisualizerMenu.Enable = 'on';
                app.CallingApp.GenerarMenu.Enable = 'on';
                app.CallingApp.InputMenu.Enable = 'on';
                app.CallingApp.OpcionesMenu.Enable = 'on';
            end
            
            % Cierra la ventana actual
            delete(app)
        end

        % Button pushed function: start_color1Button
        function start_color1ButtonPushed(app, event)
            app.start_color = app.start_color1Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: start_color2Button
        function start_color2ButtonPushed(app, event)
            app.start_color = app.start_color2Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: start_color4Button
        function start_color4ButtonPushed(app, event)
            app.start_color = app.start_color4Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: start_color3Button
        function start_color3ButtonPushed(app, event)
            app.start_color = app.start_color3Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: start_color8Button
        function start_color8ButtonPushed(app, event)
            app.start_color = app.start_color8Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: start_color7Button
        function start_color7ButtonPushed(app, event)
            app.start_color = app.start_color7Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: start_color5Button
        function start_color5ButtonPushed(app, event)
            app.start_color = app.start_color5Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: start_color6Button
        function start_color6ButtonPushed(app, event)
            app.start_color = app.start_color6Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: end_color2Button
        function end_color2ButtonPushed(app, event)
            app.end_color = app.end_color2Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: end_color1Button
        function end_color1ButtonPushed(app, event)
            app.end_color = app.end_color1Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: end_color4Button
        function end_color4ButtonPushed(app, event)
            app.end_color = app.end_color4Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: end_color3Button
        function end_color3ButtonPushed(app, event)
            app.end_color = app.end_color3Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: end_color8Button
        function end_color8ButtonPushed(app, event)
            app.end_color = app.end_color8Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: end_color7Button
        function end_color7ButtonPushed(app, event)
            app.end_color = app.end_color7Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: end_color5Button
        function end_color5ButtonPushed(app, event)
            app.end_color = app.end_color5Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: end_color6Button
        function end_color6ButtonPushed(app, event)
            app.end_color = app.end_color6Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: selection_color2Button
        function selection_color2ButtonPushed(app, event)
            app.selection_color = app.selection_color2Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: selection_color1Button
        function selection_color1ButtonPushed(app, event)
            app.selection_color = app.selection_color1Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: selection_color4Button
        function selection_color4ButtonPushed(app, event)
            app.selection_color = app.selection_color4Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: selection_color3Button
        function selection_color3ButtonPushed(app, event)
            app.selection_color = app.selection_color3Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: selection_color7Button
        function selection_color7ButtonPushed(app, event)
            app.selection_color = app.selection_color7Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: selection_color8Button
        function selection_color8ButtonPushed(app, event)
            app.selection_color = app.selection_color8Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: selection_color5Button
        function selection_color5ButtonPushed(app, event)
            app.selection_color = app.selection_color5Button.BackgroundColor;
            updatePlot(app)
        end

        % Button pushed function: selection_color6Button
        function selection_color6ButtonPushed(app, event)
            app.selection_color = app.selection_color6Button.BackgroundColor;
            updatePlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.Language = uidropdown(app.GeneralTab);
            app.Language.Items = {'English', 'Castellano', 'Euskara'};
            app.Language.Value = 'English';
            app.IdiomaLabel = uilabel(app.GeneralTab);
            app.IdiomaLabel.Text = 'Idioma';
            app.Windows = uidropdown(app.GeneralTab);
            app.Windows.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.Windows.Value = '4';
            app.MxventanasLabel = uilabel(app.GeneralTab);
            app.MxventanasLabel.Text = 'Máx. ventanas';
            app.GuardarcambiosGeneralButton = uibutton(app.GeneralTab, 'push');
            app.GuardarcambiosGeneralButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarcambiosGeneralButtonPushed, true);
            app.GuardarcambiosGeneralButton.Text = 'Guardar cambios';
            app.ResetGeneralButton = uibutton(app.GeneralTab, 'push');
            app.ResetGeneralButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGeneralButtonPushed, true);
            app.ResetGeneralButton.Text = 'Reset';
            app.SealTab = uitab(app.TabGroup);
            app.SealTab.Title = 'Señal';
            app.UIAxes = uiaxes(app.SealTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ColordelasealPanel = uipanel(app.SealTab);
            app.ColordelasealPanel.Title = 'Color de la señal';
            app.plot_color1Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color1Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color1ButtonButtonPushed, true);
            app.plot_color1Button.Text = '';
            app.plot_color2Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color2Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color2ButtonButtonPushed, true);
            app.plot_color2Button.Text = '';
            app.plot_color3Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color3Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color3ButtonButtonPushed, true);
            app.plot_color3Button.Text = '';
            app.plot_color4Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color4Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color4ButtonButtonPushed, true);
            app.plot_color4Button.Text = '';
            app.plot_color5Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color5Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color5ButtonButtonPushed, true);
            app.plot_color5Button.Text = '';
            app.plot_color6Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color6Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color6ButtonButtonPushed, true);
            app.plot_color6Button.Text = '';
            app.plot_color7Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color7Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color7ButtonButtonPushed, true);
            app.plot_color7Button.Text = '';
            app.plot_color8Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color8Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color8ButtonButtonPushed, true);
            app.plot_color8Button.Text = '';
            app.plot_color9Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color9Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color9ButtonButtonPushed, true);
            app.plot_color9Button.Text = '';
            app.plot_color10Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color10Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color10ButtonButtonPushed, true);
            app.plot_color10Button.Text = '';
            app.plot_color11Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color11Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color11ButtonButtonPushed, true);
            app.plot_color11Button.Text = '';
            app.plot_color12Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color12Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color12ButtonButtonPushed, true);
            app.plot_color12Button.Text = '';
            app.plot_color13Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color13Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color13ButtonButtonPushed, true);
            app.plot_color13Button.Text = '';
            app.plot_color14Button = uibutton(app.ColordelasealPanel, 'push');
            app.plot_color14Button.ButtonPushedFcn = createCallbackFcn(app, @plot_color14ButtonButtonPushed, true);
            app.plot_color14Button.Text = '';
            app.ColordelfondoPanel = uipanel(app.SealTab);
            app.ColordelfondoPanel.Title = 'Color del fondo';
            app.bg_color1Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color1Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color1ButtonButtonPushed, true);
            app.bg_color1Button.Text = '';
            app.bg_color2Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color2Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color2ButtonButtonPushed, true);
            app.bg_color2Button.Text = '';
            app.bg_color3Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color3Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color3ButtonButtonPushed, true);
            app.bg_color3Button.Text = '';
            app.bg_color4Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color4Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color4ButtonButtonPushed, true);
            app.bg_color4Button.Text = '';
            app.bg_color5Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color5Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color5ButtonButtonPushed, true);
            app.bg_color5Button.Text = '';
            app.bg_color6Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color6Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color6ButtonButtonPushed, true);
            app.bg_color6Button.Text = '';
            app.bg_color7Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color7Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color7ButtonButtonPushed, true);
            app.bg_color7Button.Text = '';
            app.bg_color8Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color8Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color8ButtonButtonPushed, true);
            app.bg_color8Button.Text = '';
            app.bg_color9Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color9Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color9ButtonButtonPushed, true);
            app.bg_color9Button.Text = '';
            app.bg_color10Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color10Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color10ButtonButtonPushed, true);
            app.bg_color10Button.Text = '';
            app.bg_color11Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color11Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color11ButtonButtonPushed, true);
            app.bg_color11Button.Text = '';
            app.bg_color12Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color12Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color12ButtonButtonPushed, true);
            app.bg_color12Button.Text = '';
            app.bg_color13Button = uibutton(app.ColordelfondoPanel, 'push');
            app.bg_color13Button.ButtonPushedFcn = createCallbackFcn(app, @bg_color13ButtonButtonPushed, true);
            app.bg_color13Button.Text = '';
            app.GuardarcambiosPlotButton = uibutton(app.SealTab, 'push');
            app.GuardarcambiosPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarcambiosPlotButtonPushed, true);
            app.GuardarcambiosPlotButton.Text = 'Guardar cambios';
            app.ResetPlotButton = uibutton(app.SealTab, 'push');
            app.ResetPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotButtonPushed, true);
            app.ResetPlotButton.Text = 'Reset';
            app.ComienzoseleccinPanel = uipanel(app.SealTab);
            app.ComienzoseleccinPanel.Title = 'Comienzo selección';
            app.start_color1Button = uibutton(app.ComienzoseleccinPanel, 'push');
            app.start_color1Button.ButtonPushedFcn = createCallbackFcn(app, @start_color1ButtonPushed, true);
            app.start_color1Button.Text = '';
            app.start_color2Button = uibutton(app.ComienzoseleccinPanel, 'push');
            app.start_color2Button.ButtonPushedFcn = createCallbackFcn(app, @start_color2ButtonPushed, true);
            app.start_color2Button.Text = '';
            app.start_color4Button = uibutton(app.ComienzoseleccinPanel, 'push');
            app.start_color4Button.ButtonPushedFcn = createCallbackFcn(app, @start_color4ButtonPushed, true);
            app.start_color4Button.Text = '';
            app.start_color3Button = uibutton(app.ComienzoseleccinPanel, 'push');
            app.start_color3Button.ButtonPushedFcn = createCallbackFcn(app, @start_color3ButtonPushed, true);
            app.start_color3Button.Text = '';
            app.start_color8Button = uibutton(app.ComienzoseleccinPanel, 'push');
            app.start_color8Button.ButtonPushedFcn = createCallbackFcn(app, @start_color8ButtonPushed, true);
            app.start_color8Button.Text = '';
            app.start_color7Button = uibutton(app.ComienzoseleccinPanel, 'push');
            app.start_color7Button.ButtonPushedFcn = createCallbackFcn(app, @start_color7ButtonPushed, true);
            app.start_color7Button.Text = '';
            app.start_color5Button = uibutton(app.ComienzoseleccinPanel, 'push');
            app.start_color5Button.ButtonPushedFcn = createCallbackFcn(app, @start_color5ButtonPushed, true);
            app.start_color5Button.Text = '';
            app.start_color6Button = uibutton(app.ComienzoseleccinPanel, 'push');
            app.start_color6Button.ButtonPushedFcn = createCallbackFcn(app, @start_color6ButtonPushed, true);
            app.start_color6Button.Text = '';
            app.FinseleccinPanel = uipanel(app.SealTab);
            app.FinseleccinPanel.Title = 'Fin selección';
            app.end_color2Button = uibutton(app.FinseleccinPanel, 'push');
            app.end_color2Button.ButtonPushedFcn = createCallbackFcn(app, @end_color2ButtonPushed, true);
            app.end_color2Button.Text = '';
            app.end_color1Button = uibutton(app.FinseleccinPanel, 'push');
            app.end_color1Button.ButtonPushedFcn = createCallbackFcn(app, @end_color1ButtonPushed, true);
            app.end_color1Button.Text = '';
            app.end_color4Button = uibutton(app.FinseleccinPanel, 'push');
            app.end_color4Button.ButtonPushedFcn = createCallbackFcn(app, @end_color4ButtonPushed, true);
            app.end_color4Button.Text = '';
            app.end_color3Button = uibutton(app.FinseleccinPanel, 'push');
            app.end_color3Button.ButtonPushedFcn = createCallbackFcn(app, @end_color3ButtonPushed, true);
            app.end_color3Button.Text = '';
            app.end_color8Button = uibutton(app.FinseleccinPanel, 'push');
            app.end_color8Button.ButtonPushedFcn = createCallbackFcn(app, @end_color8ButtonPushed, true);
            app.end_color8Button.Text = '';
            app.end_color7Button = uibutton(app.FinseleccinPanel, 'push');
            app.end_color7Button.ButtonPushedFcn = createCallbackFcn(app, @end_color7ButtonPushed, true);
            app.end_color7Button.Text = '';
            app.end_color5Button = uibutton(app.FinseleccinPanel, 'push');
            app.end_color5Button.ButtonPushedFcn = createCallbackFcn(app, @end_color5ButtonPushed, true);
            app.end_color5Button.Text = '';
            app.end_color6Button = uibutton(app.FinseleccinPanel, 'push');
            app.end_color6Button.ButtonPushedFcn = createCallbackFcn(app, @end_color6ButtonPushed, true);
            app.end_color6Button.Text = '';
            app.SeleccinVentanaPanel = uipanel(app.SealTab);
            app.SeleccinVentanaPanel.Title = 'Selección/Ventana';
            app.selection_color2Button = uibutton(app.SeleccinVentanaPanel, 'push');
            app.selection_color2Button.ButtonPushedFcn = createCallbackFcn(app, @selection_color2ButtonPushed, true);
            app.selection_color2Button.Text = '';
            app.selection_color1Button = uibutton(app.SeleccinVentanaPanel, 'push');
            app.selection_color1Button.ButtonPushedFcn = createCallbackFcn(app, @selection_color1ButtonPushed, true);
            app.selection_color1Button.Text = '';
            app.selection_color4Button = uibutton(app.SeleccinVentanaPanel, 'push');
            app.selection_color4Button.ButtonPushedFcn = createCallbackFcn(app, @selection_color4ButtonPushed, true);
            app.selection_color4Button.Text = '';
            app.selection_color3Button = uibutton(app.SeleccinVentanaPanel, 'push');
            app.selection_color3Button.ButtonPushedFcn = createCallbackFcn(app, @selection_color3ButtonPushed, true);
            app.selection_color3Button.Text = '';
            app.selection_color7Button = uibutton(app.SeleccinVentanaPanel, 'push');
            app.selection_color7Button.ButtonPushedFcn = createCallbackFcn(app, @selection_color7ButtonPushed, true);
            app.selection_color7Button.Text = '';
            app.selection_color8Button = uibutton(app.SeleccinVentanaPanel, 'push');
            app.selection_color8Button.ButtonPushedFcn = createCallbackFcn(app, @selection_color8ButtonPushed, true);
            app.selection_color8Button.Text = '';
            app.selection_color5Button = uibutton(app.SeleccinVentanaPanel, 'push');
            app.selection_color5Button.ButtonPushedFcn = createCallbackFcn(app, @selection_color5ButtonPushed, true);
            app.selection_color5Button.Text = '';
            app.selection_color6Button = uibutton(app.SeleccinVentanaPanel, 'push');
            app.selection_color6Button.ButtonPushedFcn = createCallbackFcn(app, @selection_color6ButtonPushed, true);
            app.selection_color6Button.Text = '';
            app.EspectrogramaTab = uitab(app.TabGroup);
            app.EspectrogramaTab.Title = 'Espectrograma';
            app.UIAxes2 = uiaxes(app.EspectrogramaTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.JetButton = uibutton(app.EspectrogramaTab, 'push');
            app.JetButton.ButtonPushedFcn = createCallbackFcn(app, @JetButtonPushed, true);
            app.JetButton.Icon = 'colormap_jet.png';
            app.JetButton.Text = '';
            app.HotButton = uibutton(app.EspectrogramaTab, 'push');
            app.HotButton.ButtonPushedFcn = createCallbackFcn(app, @HotButtonPushed, true);
            app.HotButton.Icon = 'colormap_hot.png';
            app.HotButton.Text = '';
            app.HSVButton = uibutton(app.EspectrogramaTab, 'push');
            app.HSVButton.ButtonPushedFcn = createCallbackFcn(app, @HSVButtonPushed, true);
            app.HSVButton.Icon = 'colormap_hsv.png';
            app.HSVButton.Text = '';
            app.AutumnButton = uibutton(app.EspectrogramaTab, 'push');
            app.AutumnButton.ButtonPushedFcn = createCallbackFcn(app, @AutumnButtonPushed, true);
            app.AutumnButton.Icon = 'colormap_autumn.png';
            app.AutumnButton.Text = '';
            app.SummerButton = uibutton(app.EspectrogramaTab, 'push');
            app.SummerButton.ButtonPushedFcn = createCallbackFcn(app, @SummerButtonPushed, true);
            app.SummerButton.Icon = 'colormap_summer.png';
            app.SummerButton.Text = '';
            app.ParulaButton = uibutton(app.EspectrogramaTab, 'push');
            app.ParulaButton.ButtonPushedFcn = createCallbackFcn(app, @ParulaButtonPushed, true);
            app.ParulaButton.Icon = 'colormap_parula.png';
            app.ParulaButton.Text = '';
            app.WinterButton = uibutton(app.EspectrogramaTab, 'push');
            app.WinterButton.ButtonPushedFcn = createCallbackFcn(app, @WinterButtonPushed, true);
            app.WinterButton.Icon = 'colormap_winter.png';
            app.WinterButton.Text = '';
            app.CoolButton = uibutton(app.EspectrogramaTab, 'push');
            app.CoolButton.ButtonPushedFcn = createCallbackFcn(app, @CoolButtonPushed, true);
            app.CoolButton.Icon = 'colormap_cool.png';
            app.CoolButton.Text = '';
            app.CopperButton = uibutton(app.EspectrogramaTab, 'push');
            app.CopperButton.ButtonPushedFcn = createCallbackFcn(app, @CopperButtonPushed, true);
            app.CopperButton.Icon = 'colormap_copper.png';
            app.CopperButton.Text = '';
            app.PinkButton = uibutton(app.EspectrogramaTab, 'push');
            app.PinkButton.ButtonPushedFcn = createCallbackFcn(app, @PinkButtonPushed, true);
            app.PinkButton.Icon = 'colormap_pink.png';
            app.PinkButton.Text = '';
            app.BoneButton = uibutton(app.EspectrogramaTab, 'push');
            app.BoneButton.ButtonPushedFcn = createCallbackFcn(app, @BoneButtonPushed, true);
            app.BoneButton.Icon = 'colormap_bone.png';
            app.BoneButton.Text = '';
            app.GrayButton = uibutton(app.EspectrogramaTab, 'push');
            app.GrayButton.ButtonPushedFcn = createCallbackFcn(app, @GrayButtonPushed, true);
            app.GrayButton.Icon = 'colormap_gray.png';
            app.GrayButton.Text = '';
            app.GuardarcambiosColormapButton = uibutton(app.EspectrogramaTab, 'push');
            app.GuardarcambiosColormapButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarcambiosColormapButtonPushed, true);
            app.GuardarcambiosColormapButton.Text = 'Guardar cambios';
            app.ResetColormapButton = uibutton(app.EspectrogramaTab, 'push');
            app.ResetColormapButton.ButtonPushedFcn = createCallbackFcn(app, @ResetColormapButtonPushed, true);
            app.ResetColormapButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end