classdef harmonics_synthesis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        amp1_slider                 matlab.ui.control.Slider
        frec1_box                   matlab.ui.control.Spinner
        amp2_slider                 matlab.ui.control.Slider
        frec2_box                   matlab.ui.control.Spinner
        amp3_slider                 matlab.ui.control.Slider
        frec3_box                   matlab.ui.control.Spinner
        amp4_slider                 matlab.ui.control.Slider
        frec4_box                   matlab.ui.control.Spinner
        amp5_slider                 matlab.ui.control.Slider
        frec5_box                   matlab.ui.control.Spinner
        amp6_slider                 matlab.ui.control.Slider
        frec6_box                   matlab.ui.control.Spinner
        AmplitudLabel               matlab.ui.control.Label
        FrecuenciaLabel             matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        SumalibredetonospurosLabel  matlab.ui.control.Label
        GenerarButton               matlab.ui.control.Button
        CButton                     matlab.ui.control.Button
        DButton                     matlab.ui.control.Button
        EButton                     matlab.ui.control.Button
        FButton                     matlab.ui.control.Button
        GButton                     matlab.ui.control.Button
        AButton                     matlab.ui.control.Button
        BButton                     matlab.ui.control.Button
        CDbButton                   matlab.ui.control.Button
        DEbButton                   matlab.ui.control.Button
        FGbButton                   matlab.ui.control.Button
        GAbButton                   matlab.ui.control.Button
        ABbButton                   matlab.ui.control.Button
        Spinner                     matlab.ui.control.Spinner
        OctavaLabel                 matlab.ui.control.Label
        infoButton                  matlab.ui.control.Button
        time_slider                 matlab.ui.control.Slider
        time_label                  matlab.ui.control.Label
        time_box                    matlab.ui.control.EditField
        text12                      matlab.ui.control.Label
        playButton                  matlab.ui.control.Button
        stopButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        t % Array de tiempo
        current_signal % Señal actual
        fs % Frecuencia de muestreo
        windowOriginalWidth = 708; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 950; % Altura de la ventana antes de redimensionar
        windowResized = 0; % 1 si ya se ha ejecutado la función windowResized        
        
        % Mensajer de warning predefinidos, por si falla función
        % translate()
        frec_warning = 'La frecuencia debe estar entre 0 y 20000';
        time_warning = 'La duración debe estar entre 1 y 30 segundos';
        numerical_warning = 'El valor introducido debe ser numérico';
        window_warning = 'Cierra una ventana de visualización antes de abrir otra';
        visualizationName = 'Armónicos';
        
        player; % Para guardar audioplayer
        
        plot_color;        
    end
    
    methods (Access = private)
        
        function resizeWindow(app) % Ajusta el tamaño de la ventana al tamaño de la pantalla
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 708;
            originalh = 950;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element) % Adapta el tamaño de un elemento al nuevo tamaño de ventana
            
            warning('off') % Muestra warning pq no se puede cambiar height de los slider horizontales.
            % No merece la pena solucionar el problema.
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
            warning('on')
            
        end
        
        function translate(app,language) % Traducir módulo
            load('language.mat','harmonic_synthesis')
            
            % Nombre de la ventana
            app.UIFigure.Name = harmonic_synthesis.name{language};
            
            % Títulos de los ejes
            app.UIAxes.YLabel.String = harmonic_synthesis.amplitude{language};
            app.UIAxes.XLabel.String = harmonic_synthesis.time{language};
            
            % Título del módulo
            app.SumalibredetonospurosLabel.Text = harmonic_synthesis.title{language};
            
            % Etiquetas de los slider
            app.FrecuenciaLabel.Text = harmonic_synthesis.frec{language};
            app.AmplitudLabel.Text = harmonic_synthesis.amplitude{language};
            app.OctavaLabel.Text = harmonic_synthesis.octave{language};
            app.time_label.Text = harmonic_synthesis.duration{language};
            
            % Botón
            app.GenerarButton.Text = harmonic_synthesis.generate{language};
            
            % Mensajes de warning
            app.frec_warning = harmonic_synthesis.frec_warning{language};
            app.time_warning = harmonic_synthesis.time_warning{language};
            app.numerical_warning = harmonic_synthesis.numerical_warning{language};
            app.window_warning = harmonic_synthesis.window_warning{language};
            
            % Título que irá en la bentana de visualizacio´n y en el botón
            % del módulo principal
            app.visualizationName = harmonic_synthesis.visualizationName{language};
                        
            clear('harmonic_synthesis')
        end
        
        function notes_harmonics(app,note) % Calcula 5 armónicos de la nota fundamental
            % Calcula frecuencia fundamenal de la nota
            oct = app.Spinner.Value;
            frecf = 440* exp(((oct-4)+(note-10)/12)*log(2));
            
            % Configura frec. fundamental en el slider
            app.frec1_box.Value = round(frecf,2);
            app.amp1_slider.Value = 1;
            
            % 2o armónico
            frec1 = frecf*2;
            
            app.frec2_box.Value = round(frec1,2);
            app.amp2_slider.Value = 0.83;
            
            % 3er armónico
            frec1 = frecf*3;
            
            app.frec3_box.Value = round(frec1,2);
            app.amp3_slider.Value = 0.67;
            
            % 4o armónico
            frec1 = frecf*4;
            
            app.frec4_box.Value = round(frec1,2);
            app.amp4_slider.Value = 0.5;
            
            % 5o armónico
            frec1 = frecf*5;
            
            app.frec5_box.Value = round(frec1,2);
            app.amp5_slider.Value = 0.33;
            
            % 6o armónico 
            frec1 = frecf*6;
            
            app.frec6_box.Value = round(frec1,2);
            app.amp6_slider.Value = 0.17;
            
            % Actualiza la señal y la dibuja
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, user_data)
            
            app.CallingApp = mainApp; % Referencia al módulo principal
            
            app.translate(user_data.lang_ind);
            app.plot_color = user_data.plot_color;
            
            % Se inicializan las variables y se representa la señal
            % predeterminada
            app.fs = 48000;
            time = 1;
            app.t = linspace(0,time,app.fs*time);
            app.time_box.Value = num2str(time);
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
            app.UIAxes.Color = user_data.bg_color;
        end

        % Value changed function: frec1_box
        function frec1_boxValueChanged(app, event)
            % Valida el valor introducido
            if isempty(app.frec1_box.Value) || isnan(app.frec1_box.Value)
                app.frec1_box.Value = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif app.frec1_box.Value < 0 || app.frec1_box.Value > 20000
                app.frec1_box.Value = 0;
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            
            % Actualiza el valor de la caja
            app.frec1_box.Value = round(app.frec1_box.Value,2);
            
            % Actualiza la señal y la dibuja
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
        end

        % Value changed function: frec2_box
        function frec2_boxValueChanged(app, event)
            % Valida el valor introducido
            if isempty(app.frec2_box.Value) || isnan(app.frec2_box.Value)
                app.frec2_box.Value = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif app.frec2_box.Value < 0 || app.frec2_box.Value > 20000
                app.frec2_box.Value = 0;
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            
            % Actualiza el valor de la caja
            app.frec2_box.Value = round(app.frec2_box.Value);
            
            % Actualiza la señal y la dibuja
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
            
        end

        % Value changed function: frec3_box
        function frec3_boxValueChanged(app, event)
            % Checks if it's a valid value
            if isempty(app.frec3_box.Value) || isnan(app.frec3_box.Value)
                app.frec1_box.Value = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif app.frec3_box.Value < 0 || app.frec3_box.Value > 20000
                app.frec3_box.Value = 0;
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            % Actualiza el valor de la caja
            app.frec3_box.Value = round(app.frec3_box.Value);
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
        end

        % Value changed function: frec4_box
        function frec4_boxValueChanged(app, event)
            % Checks if it's a valid value
            if isempty(app.frec4_box.Value) || isnan(app.frec4_box.Value)
                app.frec4_box.Value = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif app.frec4_box.Value < 0 || app.frec4_box.Value > 20000
                app.frec4_box.Value = 0;
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            % Redondea actualiza el valor de la caja
            app.frec4_box.Value = round(app.frec4_box.Value);
            
            % Calcula y dibuja la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
        end

        % Value changed function: frec5_box
        function frec5_boxValueChanged(app, event)
            % Checks if it's a valid value
            if isempty(app.frec5_box.Value) || isnan(app.frec5_box.Value)
                app.frec5_box.Value = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif app.frec5_box.Value < 0 || app.frec5_box.Value > 24000
                app.frec5_box.Value = 0;
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            % Redondea y actualiza el valor de la caja
            app.frec5_box.Value = round(app.frec5_box.Value);
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
        end

        % Value changed function: frec6_box
        function frec6_boxValueChanged(app, event)
            % Checks if it's a valid value
            if isempty(app.frec6_box.Value) || isnan(app.frec6_box.Value)
                app.frec6_box.Value = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif app.frec6_box.Value < 0 || app.frec6_box.Value > 24000
                app.frec6_box.Value = 0;
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            % Redondea y actualiza el valor de la caja
            app.frec6_box.Value = round(app.frec6_box.Value);
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
        end

        % Value changed function: amp1_slider
        function amp1_sliderValueChanged(app, event)
            app.amp1_slider.Value = round(app.amp1_slider.Value,2);
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
            
        end

        % Value changed function: amp2_slider
        function amp2_sliderValueChanged(app, event)
            app.amp2_slider.Value = round(app.amp2_slider.Value,2);
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);

            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
            
        end

        % Value changed function: amp3_slider
        function amp3_sliderValueChanged(app, event)
            app.amp3_slider.Value = round(app.amp3_slider.Value,2);
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);

            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
            
        end

        % Value changed function: amp4_slider
        function amp4_sliderValueChanged(app, event)
            app.amp4_slider.Value = round(app.amp4_slider.Value,2);
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);

            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
        end

        % Value changed function: amp5_slider
        function amp5_sliderValueChanged(app, event)
            app.amp5_slider.Value = round(app.amp5_slider.Value,2);
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);

            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
            
        end

        % Value changed function: amp6_slider
        function amp6_sliderValueChanged(app, event)
            app.amp1_slider.Value = round(app.amp1_slider.Value,2);
            
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            % Envía la señal a la aplicación principal y cierra
            
            success = createPlot(app.CallingApp,app.current_signal,app.t,app.visualizationName);
            
            % Si no puede abrir más ventanas de visualización, muestra un
            % mensaje de advertencia y sale de la función
            if success == 0
                warn = warndlg(app.window_warning);
                uiwait(warn);
                return
            end
            
            % Habilita los menús de la aplicacíon principal
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Al cerrar la ventana pulsando el botón
            
            % Habilita los menús de la aplicación principal
            if isvalid(app.CallingApp)
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            end
            
            delete(app)
            
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.notes_harmonics(1);
        end

        % Button pushed function: CDbButton
        function CDbButtonPushed(app, event)
            app.notes_harmonics(2);
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.notes_harmonics(3);
        end

        % Button pushed function: DEbButton
        function DEbButtonPushed(app, event)
            app.notes_harmonics(4);
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            app.notes_harmonics(5);
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            app.notes_harmonics(6);
        end

        % Button pushed function: FGbButton
        function FGbButtonPushed(app, event)
            app.notes_harmonics(7);
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            app.notes_harmonics(8);
        end

        % Button pushed function: GAbButton
        function GAbButtonPushed(app, event)
            app.notes_harmonics(9);
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            app.notes_harmonics(10);
        end

        % Button pushed function: ABbButton
        function ABbButtonPushed(app, event)
            app.notes_harmonics(11);
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            app.notes_harmonics(12);
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            % Redimensiona todos los elementos del módulo
            for i = 1:length(app.UIFigure.Children)
                app.resizeelement(app.UIFigure.Children(i));
            end
            
            % Actualiza en las variables el nuevo tamaño de la ventana
            position = app.UIFigure.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            app.CallingApp.createHelp(2); % 2 = Harmonics
        end

        % Value changed function: time_slider
        function time_sliderValueChanged(app, event)
             % Redondea y actualiza la caja
            time = round(app.time_slider.Value);
            app.t = linspace(0,time,app.fs*time);
            app.time_box.Value = num2str(time);                        
            
            % Calcula y representa la nueva señal
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
            xlim(app.UIAxes,[0,time])
        end

        % Value changed function: time_box
        function time_boxValueChanged(app, event)
            str = app.time_box.Value;
            num = str2double(str);
            
            % Validar el valor introducido
            if isempty(num) || isnan(num)
                num = 1;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 1 || num > 30
                num = 1;
                warn = warndlg(app.time_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza los elementos UI
            time = round(num);
            app.t = linspace(0,time,app.fs*time);
            app.time_box.Value = num2str(time);
            app.time_slider.Value = time;
            
            % Actualiza la señal y la dibuja
            app.current_signal = app.amp1_slider.Value*sin(2*pi*app.frec1_box.Value*app.t) + app.amp2_slider.Value*sin(2*pi*app.frec2_box.Value*app.t) + app.amp3_slider.Value*sin(2*pi*app.frec3_box.Value*app.t) + app.amp4_slider.Value*sin(2*pi*app.frec4_box.Value*app.t) + app.amp5_slider.Value*sin(2*pi*app.frec5_box.Value*app.t) + app.amp6_slider.Value*sin(2*pi*app.frec6_box.Value*app.t);
            
            plot(app.UIAxes,app.t,app.current_signal,"Color",app.plot_color)
            xlim(app.UIAxes,[0,time])
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            signalAux = app.current_signal;
            if(max(abs(signalAux)) > 1) % Normaliza la señal si su amplitud es > 1
                signalAux = signalAux./max(abs(signalAux));
            end
            
            app.player = audioplayer(signalAux,app.fs);
            play(app.player)
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % Comprueba si el elemento audioplayer ya ha sido creado antes
            % de detener la reproducción en curso
            if ~isempty(app.player)
                stop(app.player)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-6 6];
            app.amp1_slider = uislider(app.UIFigure);
            app.amp1_slider.Limits = [0 1];
            app.amp1_slider.ValueChangedFcn = createCallbackFcn(app, @amp1_sliderValueChanged, true);
            app.amp1_slider.Value = 0.5;
            app.frec1_box = uispinner(app.UIFigure);
            app.frec1_box.Limits = [0 20000];
            app.frec1_box.ValueChangedFcn = createCallbackFcn(app, @frec1_boxValueChanged, true);
            app.frec1_box.Value = 2;
            app.amp2_slider = uislider(app.UIFigure);
            app.amp2_slider.Limits = [0 1];
            app.amp2_slider.ValueChangedFcn = createCallbackFcn(app, @amp2_sliderValueChanged, true);
            app.frec2_box = uispinner(app.UIFigure);
            app.frec2_box.Limits = [0 20000];
            app.frec2_box.ValueChangedFcn = createCallbackFcn(app, @frec2_boxValueChanged, true);
            app.amp3_slider = uislider(app.UIFigure);
            app.amp3_slider.Limits = [0 1];
            app.amp3_slider.ValueChangedFcn = createCallbackFcn(app, @amp3_sliderValueChanged, true);
            app.frec3_box = uispinner(app.UIFigure);
            app.frec3_box.Limits = [0 20000];
            app.frec3_box.ValueChangedFcn = createCallbackFcn(app, @frec3_boxValueChanged, true);
            app.amp4_slider = uislider(app.UIFigure);
            app.amp4_slider.Limits = [0 1];
            app.amp4_slider.ValueChangedFcn = createCallbackFcn(app, @amp4_sliderValueChanged, true);
            app.frec4_box = uispinner(app.UIFigure);
            app.frec4_box.Limits = [0 20000];
            app.frec4_box.ValueChangedFcn = createCallbackFcn(app, @frec4_boxValueChanged, true);
            app.amp5_slider = uislider(app.UIFigure);
            app.amp5_slider.Limits = [0 1];
            app.amp5_slider.ValueChangedFcn = createCallbackFcn(app, @amp5_sliderValueChanged, true);
            app.frec5_box = uispinner(app.UIFigure);
            app.frec5_box.Limits = [0 24000];
            app.frec5_box.ValueChangedFcn = createCallbackFcn(app, @frec5_boxValueChanged, true);
            app.amp6_slider = uislider(app.UIFigure);
            app.amp6_slider.Limits = [0 1];
            app.amp6_slider.ValueChangedFcn = createCallbackFcn(app, @amp6_sliderValueChanged, true);
            app.frec6_box = uispinner(app.UIFigure);
            app.frec6_box.Limits = [0 24000];
            app.frec6_box.ValueChangedFcn = createCallbackFcn(app, @frec6_boxValueChanged, true);
            app.AmplitudLabel = uilabel(app.UIFigure);
            app.AmplitudLabel.Text = 'Amplitud';
            app.FrecuenciaLabel = uilabel(app.UIFigure);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6';
            app.SumalibredetonospurosLabel = uilabel(app.UIFigure);
            app.SumalibredetonospurosLabel.Text = 'Suma libre de tonos puros';
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.EButton = uibutton(app.UIFigure, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CDbButton = uibutton(app.UIFigure, 'push');
            app.CDbButton.ButtonPushedFcn = createCallbackFcn(app, @CDbButtonPushed, true);
            app.CDbButton.Text = {'C#'; 'Db'};
            app.DEbButton = uibutton(app.UIFigure, 'push');
            app.DEbButton.ButtonPushedFcn = createCallbackFcn(app, @DEbButtonPushed, true);
            app.DEbButton.Text = {'D#'; 'Eb'};
            app.FGbButton = uibutton(app.UIFigure, 'push');
            app.FGbButton.ButtonPushedFcn = createCallbackFcn(app, @FGbButtonPushed, true);
            app.FGbButton.Text = {'F#'; 'Gb'};
            app.GAbButton = uibutton(app.UIFigure, 'push');
            app.GAbButton.ButtonPushedFcn = createCallbackFcn(app, @GAbButtonPushed, true);
            app.GAbButton.Text = {'G#'; 'Ab'};
            app.ABbButton = uibutton(app.UIFigure, 'push');
            app.ABbButton.ButtonPushedFcn = createCallbackFcn(app, @ABbButtonPushed, true);
            app.ABbButton.Text = {'A#'; 'Bb'};
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 6];
            app.Spinner.Editable = 'off';
            app.Spinner.Value = 1;
            app.OctavaLabel = uilabel(app.UIFigure);
            app.OctavaLabel.Text = 'Octava';
            app.infoButton = uibutton(app.UIFigure, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Icon = 'info.png';
            app.infoButton.Text = '';
            app.time_slider = uislider(app.UIFigure);
            app.time_slider.Limits = [1 30];
            app.time_slider.MajorTicks = [];
            app.time_slider.ValueChangedFcn = createCallbackFcn(app, @time_sliderValueChanged, true);
            app.time_slider.MinorTicks = [];
            app.time_slider.Value = 1;
            app.time_label = uilabel(app.UIFigure);
            app.time_label.Text = {'Duración'; 'total'};
            app.time_box = uieditfield(app.UIFigure, 'text');
            app.time_box.ValueChangedFcn = createCallbackFcn(app, @time_boxValueChanged, true);
            app.time_box.Value = 'Edit Text';
            app.text12 = uilabel(app.UIFigure);
            app.text12.Text = 's';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play_button.png';
            app.playButton.Text = '';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop_button.png';
            app.stopButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = harmonics_synthesis_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end