classdef helpApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ModulesPanel           matlab.ui.container.Panel
        PuretoneButton         matlab.ui.control.Button
        AdditionoftonesButton  matlab.ui.control.Button
        SquarewaveButton       matlab.ui.control.Button
        SawtoothwaveButton     matlab.ui.control.Button
        RosenbergpulseButton   matlab.ui.control.Button
        NoiseButton            matlab.ui.control.Button
        LoadfileButton         matlab.ui.control.Button
        RecordsoundButton      matlab.ui.control.Button
        VisualizationButton    matlab.ui.control.Button
        ESButton               matlab.ui.control.Button
        ENButton               matlab.ui.control.Button
        EUSButton              matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        HTML                   matlab.ui.control.HTML
    end

    
    properties (Access = private)
        windowOriginalWidth = 794; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 617; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        lang_ind % Lenguaje actual
        % 1 = en; 2 = es; 3 = eus;
        module % Módulo actual
        % 1 = Pure tone; 2 = Harmonics; 3 = Square wave
        % 4 = Sawtooth wave; 5 = Rosenberg pulse; 6 = Noise;
        % 7 = Load file; 8 = Record sound; 9 = Visualization
        
        % Directorio en el que se encuentra la carpeta Help files
        root = strcat(getenv('PROGRAMFILES'),filesep,'Aholab',filesep,'SignalVisualizer',filesep,'application',filesep);
        
        % Localización de los ficheros de ayuda dentro de Help files
        en_url = {'Help files/en/Generate pure tone/Generatepuretone.html',...
            'Help files/en/Harmonic synthesis/Harmonicsynthesis.html',...
            'Help files/en/Generate square wave/Generatesquarewave.html',...
            'Help files/en/Generate sawtooth signal/Generatesawtoothsignal.html',...
            'Help files/en/Generate Rosenberg pulse/GenerateRosenbergpulse.html',...
            'Help files/en/Generate noise/Generatenoise.html',...
            'Help files/en/Load audio file/Loadaudiofile.html',...
            'Help files/en/Record audio/Recordaudio.html',...
            'Help files/en/Visualization window/Visualizationwindow.html'};
        es_url = {'Help files/es/Generar tono puro/Generartonopuro.html',...
            'Help files/es/Síntesis de armónicos/Sntesisdearmnicos.html',...
            'Help files/es/Generar onda cuadrada/Generarondacuadrada.html',...
            'Help files/es/Generar diente de sierra/Generardientedesierra.html',...
            'Help files/es/Generar pulso de Rosenberg/GenerarpulsodeRosenberg.html',...
            'Help files/es/Generar ruido/Generarruido.html',...
            'Help files/es/Cargar fichero de audio/Cargarficherodeaudio.html',...
            'Help files/es/Grabar audio/Grabaraudio.html',...
            'Help files/es/Ventana de visualización/Ventanadevisualizacin.html'};
        eus_url = {'Help files/eus/Tono purua sortu/Tonopuruasortu.html',...
            'Help files/eus/Harmoniko-sintesia/Harmonikosintesia.html',...
            'Help files/eus/Uhin karratua sortu/Uhinkarratuasortu.html',...
            'Help files/eus/Zerra-hortza sortu/Zerrahortzasortu.html',...
            'Help files/eus/Rosenberg-en pulsua sortu/Rosenbergenpulsuasortu.html',...
            'Help files/eus/Zarata sortu/Zaratasortu.html',...
            'Help files/eus/Soinu-fitxategia kargatu/Soinufitxategiakargatu.html',...
            'Help files/eus/Soinua grabatu/Soinuagrabatu.html',...
            'Help files/eus/Bistaratze-leihoa/Bistaratzeleihoa.html'};
        
        % Nombres traducidos de los botones
        en_modules = {'Pure tone','Addition of tones','Square wave','Sawtooth wave','Rosenberg pulse','Noise','Load file','Record sound','Visualization'};
        es_modules = {'Tono puro','Suma de tonos','Señal cuadrada','Diente de sierra','Pulso de Rosenberg','Ruido','Cargar fichero','Grabar sonido','Visualización'};
        eus_modules = {'Tonu purua','Tonuen gehiketa','Seinale karratua','Zerra-hotza','Rosenberg pulsua','Zarata','Kargatu fitxategia','Soinua grabatu','Bistaratzea'};
        
    end
    
    methods (Access = private)
        
        function resizewindow(app)
            % Esta función adapta el tamaño de la ventana a la resolución
            % de la pantalla que se esté utilizando
            
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 794;
            originalh = 617;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
        end
        
        function resizeElement(app,element)
            % Esta función redimensiona el elemento element para adaptarlo
            % al nuevo tamaño de ventana
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
        end
        
        function update_help(app)
            % Esta función carga el fichero de HTML correspondiente al
            % módulo en el elemento app.HTML
            
            % Elige el arbol de directorios correspondiente al idioma
            switch app.lang_ind
                case 1 % Inglés
                    lang_url = app.en_url;
                case 2 % Castellano
                    lang_url = app.es_url;
                case 3 % Euskera
                    lang_url = app.eus_url;
            end
            
            % Compone la dirección URL completa y lo carga
            url = strcat(app.root,lang_url{app.module});
            app.HTML.HTMLSource = url;
        end
        
        function translate(app)
            % Traduce los botones de la ventana del módulo
            
            % Carga la traducción de los botones en el idioma elegido
            switch app.lang_ind
                case 1 % Inglés
                    modules_aux = app.en_modules;
                case 2 % Castellano
                    modules_aux = app.es_modules;
                case 3 % Euskera
                    modules_aux = app.eus_modules;
            end
            
            % Aplica las traducciones a los botones
            app.PuretoneButton.Text = modules_aux{1};
            app.AdditionoftonesButton.Text = modules_aux{2};
            app.SquarewaveButton.Text = modules_aux{3};
            app.SawtoothwaveButton.Text = modules_aux{4};
            app.RosenbergpulseButton.Text = modules_aux{5};
            app.NoiseButton.Text = modules_aux{6};
            app.LoadfileButton.Text = modules_aux{7};
            app.RecordsoundButton.Text = modules_aux{8};
            app.VisualizationButton.Text = modules_aux{9};
        end
    end
    
    methods (Access = public)
        
        function update_module(app,module)
            % Cambia el fichero de ayuda mostrado por el del módulo module
            % Es una función pública, que es llamada por la app principal
            % cuando comprueba que este módulo está abierto
            
            app.module = module;
            app.update_help();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, module, lang_ind)
            
            app.module = module; % Módulo desde el que se ha pulsado el botón de ayuda
            app.lang_ind = lang_ind; % Idioma en que está configurada la app
            
            % Si se está ejecutando desde Matlab, usa ficheros de ayuda en
            % la carpeta
            if ~isdeployed
                app.root = "";
            end
            
            % Cambia el carácter separador a uno adecuado para el sistema.
            if ~strcmp('\',filesep)
                app.en_url = strrep(app.en_url,'\',filesep);
                app.es_url = strrep(app.es_url,'\',filesep);
                app.eus_url = strrep(app.eus_url,'\',filesep);
            end
            
            app.translate(); % Traduce los botones al idioma configurado
            app.update_help(); % Carga el fichero de ayuda
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % Esta fución es llamada al abrir el módulo o al cambiar el
            % tamaño de la ventana
            
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizewindow();
                app.windowResized = 1;
            end
            
            % Redimensiona los elementos que están dentro de paneles
            for i = 1:length(app.UIFigure.Children)
                app.resizeElement(app.UIFigure.Children(i));
            end
            
            for i = 1:length(app.ModulesPanel.Children)
                app.resizeElement(app.ModulesPanel.Children(i));
            end
            
            % Redimensiona el elemento HTML
            app.resizeElement(app.HTML);
            
            % Guarda el nuevo tamaño de ventana para que sirva de
            % referencia para futuros cambios
            position = app.UIFigure.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
            
        end

        % Button pushed function: PuretoneButton
        function PuretoneButtonPushed(app, event)
            app.module = 1;
            app.update_help();
        end

        % Button pushed function: AdditionoftonesButton
        function AdditionoftonesButtonPushed(app, event)
            app.module = 2;
            app.update_help();
        end

        % Button pushed function: SquarewaveButton
        function SquarewaveButtonPushed(app, event)
            app.module = 3;
            app.update_help();
        end

        % Button pushed function: SawtoothwaveButton
        function SawtoothwaveButtonPushed(app, event)
            app.module = 4;
            app.update_help();
        end

        % Button pushed function: RosenbergpulseButton
        function RosenbergpulseButtonPushed(app, event)
            app.module = 5;
            app.update_help();
        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            app.module = 6;
            app.update_help();
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            app.module = 7;
            app.update_help();
        end

        % Button pushed function: RecordsoundButton
        function RecordsoundButtonPushed(app, event)
            app.module = 8;
            app.update_help();
        end

        % Button pushed function: VisualizationButton
        function VisualizationButtonPushed(app, event)
            app.module = 9;
            app.update_help();
        end

        % Button pushed function: ESButton
        function ESButtonPushed(app, event)
            app.lang_ind = 2;
            app.translate();
            app.update_help();
        end

        % Button pushed function: ENButton
        function ENButtonPushed(app, event)
            app.lang_ind = 1;
            app.translate();
            app.update_help();
        end

        % Button pushed function: EUSButton
        function EUSButtonPushed(app, event)
            app.lang_ind = 3;
            app.translate();
            app.update_help();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.ModulesPanel = uipanel(app.UIFigure);
            app.ModulesPanel.Title = 'Modules';
            app.PuretoneButton = uibutton(app.ModulesPanel, 'push');
            app.PuretoneButton.ButtonPushedFcn = createCallbackFcn(app, @PuretoneButtonPushed, true);
            app.PuretoneButton.Text = 'Pure tone';
            app.AdditionoftonesButton = uibutton(app.ModulesPanel, 'push');
            app.AdditionoftonesButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionoftonesButtonPushed, true);
            app.AdditionoftonesButton.Text = 'Addition of tones';
            app.SquarewaveButton = uibutton(app.ModulesPanel, 'push');
            app.SquarewaveButton.ButtonPushedFcn = createCallbackFcn(app, @SquarewaveButtonPushed, true);
            app.SquarewaveButton.Text = 'Square wave';
            app.SawtoothwaveButton = uibutton(app.ModulesPanel, 'push');
            app.SawtoothwaveButton.ButtonPushedFcn = createCallbackFcn(app, @SawtoothwaveButtonPushed, true);
            app.SawtoothwaveButton.Text = 'Sawtooth wave';
            app.RosenbergpulseButton = uibutton(app.ModulesPanel, 'push');
            app.RosenbergpulseButton.ButtonPushedFcn = createCallbackFcn(app, @RosenbergpulseButtonPushed, true);
            app.RosenbergpulseButton.Text = 'Rosenberg pulse';
            app.NoiseButton = uibutton(app.ModulesPanel, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.LoadfileButton = uibutton(app.ModulesPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.RecordsoundButton = uibutton(app.ModulesPanel, 'push');
            app.RecordsoundButton.ButtonPushedFcn = createCallbackFcn(app, @RecordsoundButtonPushed, true);
            app.RecordsoundButton.Text = 'Record sound';
            app.VisualizationButton = uibutton(app.ModulesPanel, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizationButtonPushed, true);
            app.VisualizationButton.Text = 'Visualization';
            app.ESButton = uibutton(app.UIFigure, 'push');
            app.ESButton.ButtonPushedFcn = createCallbackFcn(app, @ESButtonPushed, true);
            app.ESButton.Text = 'ES';
            app.ENButton = uibutton(app.UIFigure, 'push');
            app.ENButton.ButtonPushedFcn = createCallbackFcn(app, @ENButtonPushed, true);
            app.ENButton.Text = 'EN';
            app.EUSButton = uibutton(app.UIFigure, 'push');
            app.EUSButton.ButtonPushedFcn = createCallbackFcn(app, @EUSButtonPushed, true);
            app.EUSButton.Text = 'EUS';
            app.Panel = uipanel(app.UIFigure);
            app.HTML = uihtml(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = helpApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end