classdef info_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InformacinPanel                 matlab.ui.container.Panel
        SignalVisualizerLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DirectoradelproyectoLabel       matlab.ui.control.Label
        InmaHernezRiojaLabel            matlab.ui.control.Label
        DesarrolladorLabel              matlab.ui.control.Label
        EderdelBlancoSierraLabel        matlab.ui.control.Label
        LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel  matlab.ui.control.Label
        AsesordeMusikeneLabel           matlab.ui.control.Label
        JosMaraBretosLabel_5            matlab.ui.control.Label
        IconoylogodelprogramaLabel      matlab.ui.control.Label
        SergioSantamaraMartnezLabel_5   matlab.ui.control.Label
        OtroscolaboradoresLabel         matlab.ui.control.Label
        MikelDezGarcaLabel_5            matlab.ui.control.Label
        LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_2  matlab.ui.control.Label
        InformazioaPanel                matlab.ui.container.Panel
        SignalVisualizerLabel_2         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ProiektuarenzuzendariaLabel     matlab.ui.control.Label
        InmaHernezRiojaLabel_2          matlab.ui.control.Label
        GaratzaileaLabel                matlab.ui.control.Label
        EderdelBlancoSierraLabel_2      matlab.ui.control.Label
        LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_3  matlab.ui.control.Label
        MusikenekoaholkulariaLabel      matlab.ui.control.Label
        JosMaraBretosLabel_4            matlab.ui.control.Label
        ProgramarenikonoaetalogoaLabel  matlab.ui.control.Label
        SergioSantamaraMartnezLabel_4   matlab.ui.control.Label
        BestekolaboratzaileakLabel      matlab.ui.control.Label
        MikelDezGarcaLabel_4            matlab.ui.control.Label
        LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_4  matlab.ui.control.Label
        InformationPanel                matlab.ui.container.Panel
        SignalVisualizerLabel_3         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ProjectleaderLabel              matlab.ui.control.Label
        InmaHernezRiojaLabel_3          matlab.ui.control.Label
        DeveloperLabel                  matlab.ui.control.Label
        EderdelBlancoSierraLabel_3      matlab.ui.control.Label
        LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_5  matlab.ui.control.Label
        ConsultantfromMusikeneLabel     matlab.ui.control.Label
        JosMaraBretosLabel_3            matlab.ui.control.Label
        ProgramiconandlogomadebyLabel   matlab.ui.control.Label
        SergioSantamaraMartnezLabel_3   matlab.ui.control.Label
        OthercollaboratorsLabel         matlab.ui.control.Label
        MikelDezGarcaLabel_3            matlab.ui.control.Label
        LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_6  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp % App principal
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, lang_ind)
            app.callingApp = mainApp; % Referencia a la app principal
            
            switch lang_ind % Muestra el panel correspondiente al idioma
                case 1
                    app.InformationPanel.Visible = 'on';
                case 2
                    app.InformacinPanel.Visible = 'on';
                case 3
                    app.InformazioaPanel.Visible = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Al cerrar la ventana con el botón
            % Habilita los menús de la app principal
            if isvalid(app.callingApp)
                app.callingApp.SignalVisualizerMenu.Enable = 'on';
                app.callingApp.GenerarMenu.Enable = 'on';
                app.callingApp.InputMenu.Enable = 'on';
                app.callingApp.OpcionesMenu.Enable = 'on';
            end
            
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InformacinPanel = uipanel(app.UIFigure);
            app.InformacinPanel.Title = 'Información';
            app.InformacinPanel.Visible = 'off';
            app.SignalVisualizerLabel = uilabel(app.InformacinPanel);
            app.SignalVisualizerLabel.Text = 'Signal Visualizer';
            app.Label = uilabel(app.InformacinPanel);
            app.Label.Text = {'Colaboración entre Universidad del País Vasco/Euskal Herriko Unibertsitatea'; 'y Musikene, Centro Superior de Música del País Vasco.'};
            app.DirectoradelproyectoLabel = uilabel(app.InformacinPanel);
            app.DirectoradelproyectoLabel.Text = 'Directora del proyecto:';
            app.InmaHernezRiojaLabel = uilabel(app.InformacinPanel);
            app.InmaHernezRiojaLabel.Text = 'Inma Hernáez Rioja';
            app.DesarrolladorLabel = uilabel(app.InformacinPanel);
            app.DesarrolladorLabel.Text = 'Desarrollador:';
            app.EderdelBlancoSierraLabel = uilabel(app.InformacinPanel);
            app.EderdelBlancoSierraLabel.Text = 'Eder del Blanco Sierra';
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel = uilabel(app.InformacinPanel);
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel.Text = 'Laboratorio de procesamiento de señal AHOLAB (UPV/EHU)';
            app.AsesordeMusikeneLabel = uilabel(app.InformacinPanel);
            app.AsesordeMusikeneLabel.Text = 'Asesor de Musikene:';
            app.JosMaraBretosLabel_5 = uilabel(app.InformacinPanel);
            app.JosMaraBretosLabel_5.Text = 'José María Bretos';
            app.IconoylogodelprogramaLabel = uilabel(app.InformacinPanel);
            app.IconoylogodelprogramaLabel.Text = 'Icono y logo del programa:';
            app.SergioSantamaraMartnezLabel_5 = uilabel(app.InformacinPanel);
            app.SergioSantamaraMartnezLabel_5.Text = 'Sergio Santamaría Martínez';
            app.OtroscolaboradoresLabel = uilabel(app.InformacinPanel);
            app.OtroscolaboradoresLabel.Text = 'Otros colaboradores:';
            app.MikelDezGarcaLabel_5 = uilabel(app.InformacinPanel);
            app.MikelDezGarcaLabel_5.Text = 'Mikel Díez García';
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_2 = uilabel(app.InformacinPanel);
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_2.Text = {'Referencias:'; 'Función para generar ruido marrón (o rojo) creada por  Hristo Zhivomirov'; 'Hristo Zhivomirov (2020). Pink, Red, Blue and Violet Noise Generation with Matlab'; '(https://www.mathworks.com/matlabcentral/fileexchange/42919-pink-red-blue-and-violet-noise-generation-with-matlab),'; 'MATLAB Central File Exchange. Retrieved August 4, 2020.'; ''; 'Función para generar tren de pulsos de Rosenberg creada por Matt Montag:'; 'Matt Montag (sin fecha). Matt Montag - EEN 540 Speech Signal Processing - Project 2'; '(https://www.mattmontag.com/projects-page/academic/speech) Retrieved August 4, 2020.'; 'Función modificada por Eder del Blanco.'};
            app.InformazioaPanel = uipanel(app.UIFigure);
            app.InformazioaPanel.Title = 'Informazioa';
            app.InformazioaPanel.Visible = 'off';
            app.SignalVisualizerLabel_2 = uilabel(app.InformazioaPanel);
            app.SignalVisualizerLabel_2.Text = 'Signal Visualizer';
            app.Label_2 = uilabel(app.InformazioaPanel);
            app.Label_2.Text = {'Universidad del País Vasco/Euskal Herriko Unibertsitatea-ren'; 'eta Musikene-ren (Euskal Herriko Goi-mailako Musika Ikastegia) elkarlana.'};
            app.ProiektuarenzuzendariaLabel = uilabel(app.InformazioaPanel);
            app.ProiektuarenzuzendariaLabel.Text = 'Proiektuaren zuzendaria:';
            app.InmaHernezRiojaLabel_2 = uilabel(app.InformazioaPanel);
            app.InmaHernezRiojaLabel_2.Text = 'Inma Hernáez Rioja';
            app.GaratzaileaLabel = uilabel(app.InformazioaPanel);
            app.GaratzaileaLabel.Text = 'Garatzailea:';
            app.EderdelBlancoSierraLabel_2 = uilabel(app.InformazioaPanel);
            app.EderdelBlancoSierraLabel_2.Text = 'Eder del Blanco Sierra';
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_3 = uilabel(app.InformazioaPanel);
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_3.Text = 'AHOLAB Seinale Prozesaketako Laborategia (UPV/EHU)';
            app.MusikenekoaholkulariaLabel = uilabel(app.InformazioaPanel);
            app.MusikenekoaholkulariaLabel.Text = 'Musikeneko aholkularia:';
            app.JosMaraBretosLabel_4 = uilabel(app.InformazioaPanel);
            app.JosMaraBretosLabel_4.Text = 'José María Bretos';
            app.ProgramarenikonoaetalogoaLabel = uilabel(app.InformazioaPanel);
            app.ProgramarenikonoaetalogoaLabel.Text = 'Programaren ikonoa eta logoa:';
            app.SergioSantamaraMartnezLabel_4 = uilabel(app.InformazioaPanel);
            app.SergioSantamaraMartnezLabel_4.Text = {'Sergio Santamaría Martínez'; ''};
            app.BestekolaboratzaileakLabel = uilabel(app.InformazioaPanel);
            app.BestekolaboratzaileakLabel.Text = 'Beste kolaboratzaileak:';
            app.MikelDezGarcaLabel_4 = uilabel(app.InformazioaPanel);
            app.MikelDezGarcaLabel_4.Text = 'Mikel Díez García';
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_4 = uilabel(app.InformazioaPanel);
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_4.Text = {'Aipamenak:'; 'Zarata marroia (edo gorria) sortzeko funtzioa, Hristo Zhivomirov-ek sortuta:'; 'Hristo Zhivomirov (2020). Pink, Red, Blue and Violet Noise Generation with Matlab'; '(https://www.mathworks.com/matlabcentral/fileexchange/42919-pink-red-blue-and-violet-noise-generation-with-matlab),'; 'MATLAB Central File Exchange. Retrieved August 4, 2020.'; ''; 'Rosenberg-eko pultsuko trenak sortzeko funtzioa, Matt Montag-ek sortuta:'; 'Matt Montag (sin fecha). Matt Montag - EEN 540 Speech Signal Processing - Project 2'; '(https://www.mattmontag.com/projects-page/academic/speech) Retrieved August 4, 2020.'; 'Funtzioa Eder del Blanco-k moldatu du.'};
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.InformationPanel.Visible = 'off';
            app.SignalVisualizerLabel_3 = uilabel(app.InformationPanel);
            app.SignalVisualizerLabel_3.Text = 'Signal Visualizer';
            app.Label_3 = uilabel(app.InformationPanel);
            app.Label_3.Text = {'A collaboration between University of the Basque Country (UPV/EHU)'; 'and Musikene, Higher School of Music of the Basque Country'};
            app.ProjectleaderLabel = uilabel(app.InformationPanel);
            app.ProjectleaderLabel.Text = 'Project leader:';
            app.InmaHernezRiojaLabel_3 = uilabel(app.InformationPanel);
            app.InmaHernezRiojaLabel_3.Text = 'Inma Hernáez Rioja';
            app.DeveloperLabel = uilabel(app.InformationPanel);
            app.DeveloperLabel.Text = 'Developer:';
            app.EderdelBlancoSierraLabel_3 = uilabel(app.InformationPanel);
            app.EderdelBlancoSierraLabel_3.Text = 'Eder del Blanco Sierra';
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_5 = uilabel(app.InformationPanel);
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_5.Text = 'AHOLAB Signal Processing Laboratory (UPV/EHU)';
            app.ConsultantfromMusikeneLabel = uilabel(app.InformationPanel);
            app.ConsultantfromMusikeneLabel.Text = 'Consultant from Musikene:';
            app.JosMaraBretosLabel_3 = uilabel(app.InformationPanel);
            app.JosMaraBretosLabel_3.Text = 'José María Bretos';
            app.ProgramiconandlogomadebyLabel = uilabel(app.InformationPanel);
            app.ProgramiconandlogomadebyLabel.Text = {'Program icon and logo made by:'; ''};
            app.SergioSantamaraMartnezLabel_3 = uilabel(app.InformationPanel);
            app.SergioSantamaraMartnezLabel_3.Text = 'Sergio Santamaría Martínez';
            app.OthercollaboratorsLabel = uilabel(app.InformationPanel);
            app.OthercollaboratorsLabel.Text = 'Other collaborators:';
            app.MikelDezGarcaLabel_3 = uilabel(app.InformationPanel);
            app.MikelDezGarcaLabel_3.Text = 'Mikel Díez García';
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_6 = uilabel(app.InformationPanel);
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel_6.Text = {'References:'; 'Function for creating brown (or red) noise made by Hristo Zhivomirov:'; 'Hristo Zhivomirov (2020). Pink, Red, Blue and Violet Noise Generation with Matlab'; '(https://www.mathworks.com/matlabcentral/fileexchange/42919-pink-red-blue-and-violet-noise-generation-with-matlab),'; 'MATLAB Central File Exchange. Retrieved August 4, 2020.'; ''; 'Function for creating a Rosenberg pulse train made by Matt Montag:'; 'Matt Montag (sin fecha). Matt Montag - EEN 540 Speech Signal Processing - Project 2'; '(https://www.mattmontag.com/projects-page/academic/speech) Retrieved August 4, 2020.'; 'The function has been modified by Eder del Blanco.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = info_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end