classdef noise_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        AmplitudmxSlider     matlab.ui.control.Slider
        TipoderuidoDropDown  matlab.ui.control.DropDown
        NuevamuestraButton   matlab.ui.control.Button
        GenerarButton        matlab.ui.control.Button
        GenerarruidoLabel    matlab.ui.control.Label
        AmplitudmxLabel      matlab.ui.control.Label
        infoButton           matlab.ui.control.Button
        timeSlider           matlab.ui.control.Slider
        timeLabel            matlab.ui.control.Label
        playButton           matlab.ui.control.Button
        stopButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        t % Array de tiempo
        current_data % Muestra actual
        fs % Frecuencia de muestreo
        type % Tipo de ruido actual
        amp % Amplitud
        CallingApp % App principal
        windowOriginalWidth = 640; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 530; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        window_warning = 'Cierra una ventana de visualización antes de abrir otra';
        
        player
        
        plot_color;
        
        help_url = 'Help files/es/Generar ruido/Generarruido.html';
    end
    
    methods (Access = private)
        
        % Genera un array de n muestras de ruido blanco
        % Con valores entre -1 y 1
        function wn = whiteNoise(~,n)
            wn = randn(1,n);
            wn = wn./max(abs(wn));
        end
        
        % Lo mismo, con ruido rosa
        function pn = pinkNoise(~,n)
            pn = pinknoise(n)';
            pn = pn./max(abs(pn));
        end
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 651;
            originalh = 672;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element)
            
            warning('off') % Muestra warning pq no se puede cambiar height de los slider horizontales.
            % No merece la pena solucionar el problema.
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
            warning('on')
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %    Red Noise Generation with MATLAB Implementation   %
        %                                                      %
        % Author: M.Sc. Eng. Hristo Zhivomirov        07/31/13 %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function y = redNoise(~,n)
            % Esta función sirve para generar una señal de ruido marrón (o
            % rojo) de n muestras
            
            % m - number of matrix rows
            % n - number of matrix columns
            % y - matrix with red (Brownian) noise samples
            %     with mu = 0 and sigma = 1 (columnwise)
            % The function generates a matrix of red (Brownian) noise samples
            % (columnwise). In terms of power at a constant bandwidth, red
            % noise falls off at 6 dB/oct, i.e. 20 dB/dec.
            % difine the length of the noise vector and ensure
            % that M is even, this will simplify the processing
            m = 1; n = round(n); N = m*n;
            if rem(N, 2)
                M = N+1;
            else
                M = N;
            end
            % generate white noise sequence
            x = randn(1, M);
            % FFT
            X = fft(x);
            % prepare a vector with frequency indexes
            NumUniquePts = M/2 + 1;     % number of the unique fft points
            k = 1:NumUniquePts;         % vector with frequency indexes
            % manipulate the left half of the spectrum so the PSD
            % is proportional to the frequency by a factor of 1/(f^2),
            % i.e. the amplitudes are proportional to 1/f
            X = X(1:NumUniquePts);
            X = X./k;
            % prepare the right half of the spectrum - a conjugate copy of the left
            % one except the DC component and the Nyquist component - they are unique,
            % and reconstruct the whole spectrum
            X = [X conj(X(end-1:-1:2))];
            % IFFT
            y = real(ifft(X));
            % ensure that the length of y is N
            y = y(1, 1:N);
            % form the noise matrix and ensure unity standard
            % deviation and zero mean value (columnwise)
            y = reshape(y, [m, n]);
            y = bsxfun(@minus, y, mean(y));
            y = bsxfun(@rdivide, y, std(y));
            
            y = y./max(abs(y));
        end
        
        function translate(app,language)
            load('language.mat','noise');
            
            % Título de la ventana
            app.UIFigure.Name = noise.name{language};
            
            % Etiquetas de los ejes
            app.UIAxes.YLabel.String = noise.amplitude{language};
            app.UIAxes.XLabel.String = noise.time{language};
            
            % Título del módulo
            app.GenerarruidoLabel.Text = noise.title{language};
            
            % Selector de tipo de ruido
            app.TipoderuidoDropDown.Items = noise.dropdown{language};
            
            % Etiquetas y botones
            app.NuevamuestraButton.Text = noise.new_sample{language};
            app.GenerarButton.Text = noise.generate{language};
            
            app.AmplitudmxLabel.Text = noise.max_amplitude{language};            
            app.timeLabel.Text = noise.duration{language};
            
            % Mensaje de warning
            app.window_warning = noise.window_warning{language};
                        
            clear('noise')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, user_data)
            app.CallingApp = mainApp; % Referencua al módulo principal            
            
            app.translate(user_data.lang_ind);
            app.plot_color = user_data.plot_color;
            
            % Inicializa las variables
            app.fs = 48000;
            time = 1;
            app.t = linspace(0,time,app.fs*time);
            app.type = 1;
            app.amp = 1;
            
            % Genera muestra predeterminada y la dibuja
            app.current_data = app.whiteNoise(app.fs*app.t(end));
            
            plot(app.UIAxes,app.t,app.current_data,"Color",app.plot_color)
            xlim(app.UIAxes,[0,app.t(end)])
            app.UIAxes.Color = user_data.bg_color;
        end

        % Value changed function: TipoderuidoDropDown
        function TipoderuidoDropDownValueChanged(app, event)
            % Cambia el tipo de ruido y crea una nueva muestra de la
            % duración indicada por la persona usuaria
            app.type = find(contains(app.TipoderuidoDropDown.Items,app.TipoderuidoDropDown.Value));
            
            switch app.type
                case 1
                    app.current_data = app.amp*app.whiteNoise(app.fs*app.t(end));
                case 2
                    app.current_data = app.amp*app.pinkNoise(app.fs*app.t(end));
                case 3
                    app.current_data = app.amp*app.redNoise(app.fs*app.t(end));
            end
            
            plot(app.UIAxes,app.t,app.current_data,"Color",app.plot_color)
            xlim(app.UIAxes,[0,app.t(end)])
        end

        % Value changed function: AmplitudmxSlider
        function AmplitudmxSliderValueChanged(app, event)
            % Cambia la amplitud seleccionada y modifica la amplitud de
            % la muestra actual
            app.amp = round(app.AmplitudmxSlider.Value,2);
            
            app.current_data = app.current_data.*(app.amp/max(abs(app.current_data)));
            
            plot(app.UIAxes,app.t,app.current_data,"Color",app.plot_color)
        end

        % Button pushed function: NuevamuestraButton
        function NuevamuestraButtonPushed(app, event)
            % Genera nueva muestra con los valores seleccionados
            
            switch app.type
                case 1
                    app.current_data = app.amp*app.whiteNoise(app.fs*app.t(end));
                case 2
                    app.current_data = app.amp*app.pinkNoise(app.fs*app.t(end));
                case 3
                    app.current_data = app.amp*app.redNoise(app.fs*app.t(end));
            end
            
            plot(app.UIAxes,app.t,app.current_data,"Color",app.plot_color)
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            % Envía la señal a la aplicación principal y cierra

            success = createPlot(app.CallingApp,app.current_data,app.t,app.TipoderuidoDropDown.Items{app.type});
            
            if success == 0
                warn = warndlg(app.window_warning);
                uiwait(warn);
                return
            end
            % Habilita los menús de la aplicación principal
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Al cerrar la ventana pulsando el botón
            
            % Habilita los menús de la aplicación principal
            if isvalid(app.CallingApp)
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            end
            
            delete(app)
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            for i = 1:length(app.UIFigure.Children)
                app.resizeelement(app.UIFigure.Children(i));
            end
            
            position = app.UIFigure.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            % Crea o actualiza la ventana de ayuda
            
            app.CallingApp.createHelp(6); % 6 = Noise
        end

        % Value changed function: timeSlider
        function timeSliderValueChanged(app, event)
            % Redondea el valor para seleccionar la duración en segundos,
            % con valores enteros
            time = round(app.timeSlider.Value);
            app.t = linspace(0,time,app.fs*time);
            
            app.TipoderuidoDropDownValueChanged()
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            % Comienza la reproducción de audio
            
            app.player = audioplayer(app.current_data,app.fs);
            play(app.player)
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % Detiene la reproducción de audio
            
            if ~isempty(app.player)
                stop(app.player)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1 1];
            app.AmplitudmxSlider = uislider(app.UIFigure);
            app.AmplitudmxSlider.Limits = [0 1];
            app.AmplitudmxSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AmplitudmxSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudmxSliderValueChanged, true);
            app.AmplitudmxSlider.Value = 1;
            app.TipoderuidoDropDown = uidropdown(app.UIFigure);
            app.TipoderuidoDropDown.Items = {'Ruido blanco', 'Ruido rosa', 'Ruido marrón'};
            app.TipoderuidoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoderuidoDropDownValueChanged, true);
            app.TipoderuidoDropDown.Value = 'Ruido blanco';
            app.NuevamuestraButton = uibutton(app.UIFigure, 'push');
            app.NuevamuestraButton.ButtonPushedFcn = createCallbackFcn(app, @NuevamuestraButtonPushed, true);
            app.NuevamuestraButton.Text = 'Nueva muestra';
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.GenerarruidoLabel = uilabel(app.UIFigure);
            app.GenerarruidoLabel.Text = 'Generar ruido';
            app.AmplitudmxLabel = uilabel(app.UIFigure);
            app.AmplitudmxLabel.Text = 'Amplitud máx.';
            app.infoButton = uibutton(app.UIFigure, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Icon = 'info.png';
            app.infoButton.Text = '';
            app.timeSlider = uislider(app.UIFigure);
            app.timeSlider.Limits = [1 30];
            app.timeSlider.MajorTicks = [1 30];
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @timeSliderValueChanged, true);
            app.timeSlider.MinorTicks = [];
            app.timeSlider.Value = 1;
            app.timeLabel = uilabel(app.UIFigure);
            app.timeLabel.Text = {'Duración'; 'total'};
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play_button.png';
            app.playButton.Text = '';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop_button.png';
            app.stopButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noise_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end