classdef rosenberg_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        axes1              matlab.ui.control.UIAxes
        amp_slider         matlab.ui.control.Slider
        frec_slider        matlab.ui.control.Slider
        rising_slider      matlab.ui.control.Slider
        generar_btm        matlab.ui.control.Button
        amp_label          matlab.ui.control.Label
        freq_label         matlab.ui.control.Label
        rising_label       matlab.ui.control.Label
        amp_box            matlab.ui.control.EditField
        frec_box           matlab.ui.control.EditField
        rising_box         matlab.ui.control.EditField
        text6              matlab.ui.control.Label
        text10             matlab.ui.control.Label
        decreasing_slider  matlab.ui.control.Slider
        decreasing_label   matlab.ui.control.Label
        decreasing_box     matlab.ui.control.EditField
        text6_2            matlab.ui.control.Label
        text6_3            matlab.ui.control.Label
        infoButton         matlab.ui.control.Button
        time_slider        matlab.ui.control.Slider
        time_label         matlab.ui.control.Label
        time_box           matlab.ui.control.EditField
        text6_4            matlab.ui.control.Label
        playButton         matlab.ui.control.Button
        stopButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        amp % Amplitud del pulso
        frec % Frecuencia
        rising % Tiempo de subida
        t % Array de tiempo
        current_data % Señal actual
        CallingApp % Aplicación principal
        fs % Frecuencia de muestreo
        decreasing % Tiempo de bajada
        windowOriginalWidth = 651; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 672; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        
        % Mensajes predefinidos de warning, por si falla translate()
        rising_warning = "El valor debe estar entre 10 y 90";
        numerical_warning = "El valor introducido debe ser numérico";
        add_warning = "La suma del tiempo de subida y de bajada no puede ser superior a 100";
        frec_warning = "La frecuencia debe estar entre 0 y 4800";
        amp_warning = "La amplitud debe estar entre 0 y 1";
        time_warning = "La duración debe estar entre 1 y 30 segundos";
        window_warning = 'Cierra una ventana de visualización antes de abrir otra';
        
        player % Audioplayer
        
        % Título que tendrá la ventana de visualización y el botón de la
        % aplicación principal
        visualizationName = 'Pulso de Rosenberg';
        
        % Color del plot
        plot_color;        
    end
    
    methods (Access = private)
        
        % Genera un array de t segundos con pulsos de Rosenberg
        % Función creada por Matt Montag y modificada por Eder del Blanco
        function signal = rosenberg(~, f0, t, N1, N2, fs)
            %ROSENBERG
            %   f0: Frecuencia fundamental
            %   t:  Duración en s de la señal de salida
            %   N1: Porcentaje del periodo dedicado al ciclo de subida
            %   N2: Porcentaje del periodo dedicado al ciclo de bajada
            
            % Validación
            if (N1 + N2) > 100
                error('Error: N1 + N2 no pueden ser mayor que 100');
            end
            
            signal = zeros(1,fs*t);
            T=1/f0;     % Periodo en segundos
            pulselength=floor(T*fs);    % Tamaño de un periodo en muestras
            % Determina muestras del periodo dedicadas al ciclo de subida y
            % de bajada
            N2=floor(pulselength*N2/100);
            N1=floor(pulselength*N1/100);
            gn=zeros(1,pulselength);
            % Calcula un periodo de la señal
            for n=1:N1
                gn(n)=0.5*(1-cos(pi*(n-1)/N1));
            end
            for n=N1:N2+N1
                gn(n)=cos(pi*(n-N1)/(2*N2));
            end
            
            % Repite el periodo hasta completar t segundos de señal
            for n = 1:ceil(length(signal)/pulselength)
                if n*pulselength > length(signal)
                    signal((n-1)*pulselength+1:end) = gn(1:length(signal((n-1)*pulselength+1:end)));
                else
                    signal((n-1)*pulselength+1:n*pulselength) = gn;
                end
            end
            
        end
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 651;
            originalh = 672;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.figure1.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element)
            
            warning('off') % Muestra warning pq no se puede cambiar height de los slider horizontales.
            % No merece la pena solucionar el problema.
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.figure1.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
            warning('on')
        end
        
        function translate(app,language)
            load('language.mat','rosenberg');
            
            % Título de la ventana
            app.figure1.Name = rosenberg.name{language};
            
            % Título del módulo
            app.text10.Text = rosenberg.title{language};
            
            % Nombres de los ejes
            app.axes1.YLabel.String = rosenberg.amplitude{language};
            app.axes1.XLabel.String = rosenberg.time{language};
            
            % Etiquetas y botones del módulo
            app.amp_label.Text = rosenberg.amplitude{language};
            app.freq_label.Text = rosenberg.frequency{language};
            app.rising_label.Text = rosenberg.rising_time{language};
            app.decreasing_label.Text = rosenberg.decreasing_time{language};
            app.time_label.Text = rosenberg.duration{language};
            
            app.generar_btm.Text = rosenberg.generate{language};
            
            % Mensajes de warning
            app.rising_warning = rosenberg.rising_warning{language};
            app.numerical_warning = rosenberg.numerical_warning{language};
            app.add_warning = rosenberg.add_warning{language};
            app.frec_warning = rosenberg.frec_warning{language};
            app.amp_warning = rosenberg.amp_warning{language};
            app.time_warning = rosenberg.time_warning{language};
            app.window_warning = rosenberg.window_warning{language};
            
            % Título que tendrá la ventana de visualización y el botón de
            % la aplicación principal
            app.visualizationName = rosenberg.visualizationName{language};
                        
            clear('rosenberg');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainApp, user_data)
            
            app.CallingApp = mainApp; % Referencia al módulo principal
            
            app.translate(user_data.lang_ind);
            app.plot_color = user_data.plot_color;
            
            % Inicializa las variables
            app.fs = 48000;
            time = 1;
            app.t = linspace(0,time,app.fs*time);
            app.time_box.Value = num2str(time);
            
            app.amp = 0.5;
            app.amp_box.Value = num2str(app.amp);
            
            app.frec = 1;
            app.frec_box.Value = num2str(app.frec);
            
            app.rising = 30;
            app.rising_box.Value = num2str(app.rising);
            
            app.decreasing = 50;
            app.decreasing_box.Value = num2str(app.decreasing);
            
            % Calcula señal predetermianda y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.Color = user_data.bg_color;
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
        end

        % Value changed function: amp_box
        function amp_box_Callback(app, event)
            
            str = app.amp_box.Value;
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0 || num > 1
                num = 0;
                warn = warndlg(app.amp_warning);
                uiwait(warn);
            end
            
            % Redondea el valor y actualiza la UI
            app.amp = round(num,2);
            app.amp_box.Value = num2str(app.amp);
            app.amp_slider.Value = app.amp;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
            
        end

        % Value changed function: amp_slider
        function amp_slider_Callback(app, event)
            % Redondea el valor y actualiza la UI
            app.amp = round(app.amp_slider.Value,2);
            app.amp_box.Value = num2str(app.amp);
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
            
        end

        % Value changed function: rising_box
        function rising_box_Callback(app, event)
            
            str = app.rising_box.Value;
            num = str2double(str);
            
            % Valida el input
            if isempty(num) || isnan(num)
                num = 100 - app.decreasing;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 10 || num > 90
                num = 100 - app.decreasing;
                warn = warndlg(app.rising_warning);
                uiwait(warn);
            elseif (num + app.decreasing) > 100 % Comprueba si los porcentajes de los tiempos de subida y de bajada suman más del 100%
                % En caso de que así sea, ajusta el valor para evitarlo
                num = 100 - app.decreasing;
                warn = warndlg(app.add_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            app.rising = round(num,1);
            app.rising_box.Value = num2str(app.rising);
            app.rising_slider.Value = app.rising;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
            
        end

        % Value changed function: rising_slider
        function rising_slider_Callback(app, event)
            
            app.rising = round(app.rising_slider.Value);
            
            % Comprueba si los porcentajes de los tiempoos de subida y de
            % bajada suman más del 100%
            if (app.decreasing + app.rising) > 100
                % En caso de que así sea, ajusta el valor para evitarlo
                app.rising = 100 - app.decreasing;
                warn = warndlg(app.add_warning);
                uiwait(warn);
            end
            
            % Actualiza la UI
            app.rising_slider.Value = app.rising;
            app.rising_box.Value = num2str(app.rising);
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
            
        end

        % Value changed function: frec_box
        function frec_box_Callback(app, event)
            
            str = app.frec_box.Value;
            num = str2double(str);
            
            % Valida el input
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0 || num > 4800 % Por motivos del algoritmo generador de pulsos de Rosenberg y con fs = 48000, la frec. máxima debe ser 4800
                num = 0;                    % Para f0 mayores, un ciclo del 10% dispondría de 0 muestras
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            app.frec = round(num);
            app.frec_box.Value = num2str(app.frec);
            app.frec_slider.Value = app.frec;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
            
        end

        % Value changed function: frec_slider
        function frec_slider_Callback(app, event)
            % Redondea y actualiza la UI
            app.frec = round(app.frec_slider.Value);
            app.frec_box.Value = num2str(app.frec,'%u');
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
            
        end

        % Button pushed function: generar_btm
        function generar_btm_Callback(app, event)
            % Envía la señal a la aplicación principal y cierra
            % Actualiza el plot de la app principal
            
            success = createPlot(app.CallingApp,app.current_data,app.t,app.visualizationName,'Positive');
            
            if success == 0
                warn = warndlg(app.window_warning);
                uiwait(warn);
                return
            end
            % Habilita los menús de la app principal
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            
            delete(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            % Al cerrar la ventana con el botón
            % Habilita los menús de la app principal
            if isvalid(app.CallingApp)
                app.CallingApp.SignalVisualizerMenu.Enable = 'on';
                app.CallingApp.GenerarMenu.Enable = 'on';
                app.CallingApp.InputMenu.Enable = 'on';
                app.CallingApp.OpcionesMenu.Enable = 'on';
            end
            
            delete(app)
        end

        % Value changed function: decreasing_slider
        function decreasing_sliderValueChanged(app, event)
            app.decreasing = round(app.decreasing_slider.Value);
            
            % Comprueba si la suma de los porcentajes del tiempo de subida y de bajada
            % es superior al 100%
            if (app.decreasing + app.rising) > 100
                % En caso de que lo sea, elige el porcentaje de tiempo de bajada tal que sume el 100% junto con el de subida
                app.decreasing = 100 - app.rising;
                warn = warndlg(app.add_warning);
                uiwait(warn);
            end
            
            % Actualiza los valores de la UI
            app.decreasing_slider.Value = app.decreasing;
            app.decreasing_box.Value = num2str(app.decreasing,'%u');
            
            % Calcula la nueva señal
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            % Actualiza el plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
        end

        % Value changed function: decreasing_box
        function decreasing_boxValueChanged(app, event)
            str = app.decreasing_box.Value;
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 100 - app.rising;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 10 || num > 90
                num = 100 - app.rising;
                warn = warndlg(app.rising_warning);
                uiwait(warn);
            elseif (num + app.rising) > 100 % Comprueba si la suma de los porcentajes de tiempopo de subida y de bajada supera el 100%
                % En caso de que así sea, ajusta el valor para evitarlo 
                num = 100 - app.rising;
                warn = warndlg(app.add_warning);
                uiwait(warn);
            end
            
            % Actualiza la UI
            app.decreasing = round(num);
            app.decreasing_box.Value = num2str(app.decreasing);
            app.decreasing_slider.Value = app.decreasing;
            
            % Calcula la nueva señal
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            % Actualiza el plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
        end

        % Size changed function: figure1
        function figure1SizeChanged(app, event)
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            for i = 1:length(app.figure1.Children)
                app.resizeelement(app.figure1.Children(i));
            end
            
            position = app.figure1.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            % Abre o actualiza la ventana de ayuda
            
            app.CallingApp.createHelp(5); % 5 = Rosenberg
        end

        % Value changed function: time_slider
        function time_sliderValueChanged(app, event)
            % Redondea y actualiza la UI
            time = round(app.time_slider.Value);
            app.t = linspace(0,time,app.fs*time);
            app.time_box.Value = num2str(time,'%u');
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
        end

        % Value changed function: time_box
        function time_boxValueChanged(app, event)
            str = app.time_box.Value;
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 1;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 1 || num > 30
                num = 1;
                warn = warndlg(app.time_warning);
                uiwait(warn);
            end
            
            % Redondea el valor y actualiza la UI
            time = round(num);
            app.t = linspace(0,time,app.fs*time);
            app.time_box.Value = num2str(time);
            app.time_slider.Value = time;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*app.rosenberg(app.frec,app.t(end),app.rising,app.decreasing,app.fs);
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            ylim(app.axes1,[0,1])
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            signalAux = detrend(app.current_data); % Elimina el offset
            if(max(abs(signalAux)) > 1) % Normaliza la señal si su amplitud es > 1
                signalAux = signalAux./max(abs(signalAux));
            end
            
            app.player = audioplayer(signalAux,app.fs);
            play(app.player)
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % Detiene la reproducción del audio
            if ~isempty(app.player)
                stop(app.player)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'tone';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
            app.axes1 = uiaxes(app.figure1);
            xlabel(app.axes1, 'Tiempo (s)')
            ylabel(app.axes1, 'Amplitud')
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.amp_slider = uislider(app.figure1);
            app.amp_slider.Limits = [0 1];
            app.amp_slider.MajorTicks = [];
            app.amp_slider.ValueChangedFcn = createCallbackFcn(app, @amp_slider_Callback, true);
            app.amp_slider.MinorTicks = [];
            app.amp_slider.Value = 0.5;
            app.frec_slider = uislider(app.figure1);
            app.frec_slider.Limits = [0 4800];
            app.frec_slider.MajorTicks = [];
            app.frec_slider.ValueChangedFcn = createCallbackFcn(app, @frec_slider_Callback, true);
            app.frec_slider.MinorTicks = [];
            app.frec_slider.Value = 1;
            app.rising_slider = uislider(app.figure1);
            app.rising_slider.Limits = [10 90];
            app.rising_slider.MajorTicks = [];
            app.rising_slider.ValueChangedFcn = createCallbackFcn(app, @rising_slider_Callback, true);
            app.rising_slider.MinorTicks = [];
            app.rising_slider.Value = 30;
            app.generar_btm = uibutton(app.figure1, 'push');
            app.generar_btm.ButtonPushedFcn = createCallbackFcn(app, @generar_btm_Callback, true);
            app.generar_btm.Text = 'Generar';
            app.amp_label = uilabel(app.figure1);
            app.amp_label.Text = 'Amplitud';
            app.freq_label = uilabel(app.figure1);
            app.freq_label.Text = 'Frecuencia';
            app.rising_label = uilabel(app.figure1);
            app.rising_label.Text = 'Tiempo subida';
            app.amp_box = uieditfield(app.figure1, 'text');
            app.amp_box.ValueChangedFcn = createCallbackFcn(app, @amp_box_Callback, true);
            app.amp_box.Value = 'Edit Text';
            app.frec_box = uieditfield(app.figure1, 'text');
            app.frec_box.ValueChangedFcn = createCallbackFcn(app, @frec_box_Callback, true);
            app.frec_box.Value = 'Edit Text';
            app.rising_box = uieditfield(app.figure1, 'text');
            app.rising_box.ValueChangedFcn = createCallbackFcn(app, @rising_box_Callback, true);
            app.rising_box.Value = 'Edit Text';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Hz';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'Generar pulso de Rosenberg';
            app.decreasing_slider = uislider(app.figure1);
            app.decreasing_slider.Limits = [10 90];
            app.decreasing_slider.MajorTicks = [];
            app.decreasing_slider.ValueChangedFcn = createCallbackFcn(app, @decreasing_sliderValueChanged, true);
            app.decreasing_slider.MinorTicks = [];
            app.decreasing_slider.Value = 50;
            app.decreasing_label = uilabel(app.figure1);
            app.decreasing_label.Text = 'Tiempo bajada';
            app.decreasing_box = uieditfield(app.figure1, 'text');
            app.decreasing_box.ValueChangedFcn = createCallbackFcn(app, @decreasing_boxValueChanged, true);
            app.decreasing_box.Value = 'Edit Text';
            app.text6_2 = uilabel(app.figure1);
            app.text6_2.Text = '%';
            app.text6_3 = uilabel(app.figure1);
            app.text6_3.Text = '%';
            app.infoButton = uibutton(app.figure1, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Icon = 'info.png';
            app.infoButton.Text = '';
            app.time_slider = uislider(app.figure1);
            app.time_slider.Limits = [1 30];
            app.time_slider.MajorTicks = [];
            app.time_slider.ValueChangedFcn = createCallbackFcn(app, @time_sliderValueChanged, true);
            app.time_slider.MinorTicks = [];
            app.time_slider.Value = 1;
            app.time_label = uilabel(app.figure1);
            app.time_label.Text = {'Duración'; 'total'};
            app.time_box = uieditfield(app.figure1, 'text');
            app.time_box.ValueChangedFcn = createCallbackFcn(app, @time_boxValueChanged, true);
            app.time_box.Value = 'Edit Text';
            app.text6_4 = uilabel(app.figure1);
            app.text6_4.Text = 's';
            app.playButton = uibutton(app.figure1, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play_button.png';
            app.playButton.Text = '';
            app.stopButton = uibutton(app.figure1, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop_button.png';
            app.stopButton.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rosenberg_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end