classdef square_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        axes1          matlab.ui.control.UIAxes
        amp_slider     matlab.ui.control.Slider
        frec_slider    matlab.ui.control.Slider
        fase_slider    matlab.ui.control.Slider
        generar_btm    matlab.ui.control.Button
        amp_label      matlab.ui.control.Label
        frec_label     matlab.ui.control.Label
        fase_label     matlab.ui.control.Label
        amp_box        matlab.ui.control.EditField
        frec_box       matlab.ui.control.EditField
        fase_box       matlab.ui.control.EditField
        text6          matlab.ui.control.Label
        text10         matlab.ui.control.Label
        duty_slider    matlab.ui.control.Slider
        ciclo_label    matlab.ui.control.Label
        duty_box       matlab.ui.control.EditField
        text6_2        matlab.ui.control.Label
        text6_3        matlab.ui.control.Label
        infoButton     matlab.ui.control.Button
        time_slider    matlab.ui.control.Slider
        time_label     matlab.ui.control.Label
        time_box       matlab.ui.control.EditField
        text6_4        matlab.ui.control.Label
        offset_slider  matlab.ui.control.Slider
        offset_label   matlab.ui.control.Label
        offset_box     matlab.ui.control.EditField
        playButton     matlab.ui.control.Button
        stopButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        amp % Amplitud máxima de la señal
        frec % Frecuencia de la señal
        theta % Fase
        t % Array de tiempo
        current_data % Señal actual
        CallingApp % Aplicación principal
        fs % Frecuencia de muestreo
        duty % Ciclo de trabajo
        offset % Offset de la señal
        
        player % Audioplayer
        
        windowOriginalWidth = 651; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 750; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        
        % Mensajes predefinidos de warning, por si falla translate()
        numerical_warning = 'El valor introducido debe ser numérico';
        phase_warning = 'La fase debe estar entre -1 y 1';
        frec_warning = 'La frecuencia debe estar entre 0 y 20000';
        amp_warning = 'La amplitud debe estar entre 0 y 1';
        cycle_warning = 'El valor debe estar entre 0 y 100';
        time_warning = 'La duración debe estar entre 1 y 30 segundos';
        offset_warning = 'El offset debe estar entre -2 y 2';
        window_warning = 'Cierra una ventana de visualización antes de abrir otra';
        
        % Título que tendrá la ventana de visualización y el botón de la
        % aplicación principal
        visualizationName = 'Onda cuadrada';
        
        plot_color % Color del plot                
    end
    
    methods (Access = private)
        
        function resizeWindow(app) % Adaptar el tamaño de la ventana al tamaño de la pantalla
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 651;
            originalh = 750;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.figure1.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element) % Adapta un elemento al nuevo tamaño de ventana
            
            warning('off') % Muestra warning pq no se puede cambiar height de los slider horizontales.
            % No merece la pena solucionar el problema.
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.figure1.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
            warning('on')
            
        end
        
        function translate(app,language) % Traducir módulo
            load('language.mat','square')
            
            % Título de la ventana
            app.figure1.Name = square.name{language};
            
            % Título de los ejes
            app.axes1.YLabel.String = square.amplitude{language};
            app.axes1.XLabel.String = square.time{language};
            
            % Etiquetas y botones del módulo
            app.generar_btm.Text = square.generate{language};
            app.amp_label.Text = square.amplitude{language};
            app.frec_label.Text = square.frequency{language};
            app.fase_label.Text = square.phase{language};
            app.ciclo_label.Text = square.active_cycle{language};
            app.time_label.Text = square.duration{language};
            
            % Título del módulo
            app.text10.Text = square.title{language};
            
            % Mensajes de warning
            app.numerical_warning = square.numerical_warning{language};
            app.phase_warning = square.phase_warning{language};
            app.frec_warning = square.frec_warning{language};
            app.amp_warning = square.amp_warning{language};
            app.cycle_warning = square.cycle_warning{language};
            app.offset_warning = square.offset_warning{language};
            app.time_warning = square.time_warning{language};
            app.window_warning = square.window_warning{language};
            
            % Título que tendrá la ventana de visualización y el botón de
            % la aplicación principal
            app.visualizationName = square.visualizationName{language};
                        
            clear('square')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainApp, user_data)
            
            app.CallingApp = mainApp; % Referencia al módulo principal
            
            app.translate(user_data.lang_ind);
            app.plot_color = user_data.plot_color;
            
            % Inicialización de las variables
            app.fs = 48000;
            
            time = 1;
            app.time_box.Value = num2str(time);
            app.t = linspace(0,time,app.fs*time);
            %
            app.amp = 0.5;
            app.amp_box.Value = num2str(app.amp);
            %
            app.frec = 2;
            app.frec_box.Value = num2str(app.frec);
            %
            app.theta = 0;
            app.fase_box.Value = num2str(app.theta);
            
            app.duty = 50;
            app.duty_box.Value = num2str(app.duty);
            
            app.offset = 0;
            app.offset_box.Value = num2str(app.offset);
            %
            % Calcula y dibuja la señal predeterminada
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.Color = user_data.bg_color;
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])            
        end

        % Value changed function: amp_box
        function amp_box_Callback(app, event)
            
            str = app.amp_box.Value;
            num = str2double(str);
            
            % Valida el input
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0 || num > 1
                num = 0;
                warn = warndlg(app.amp_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            app.amp = round(num,2);
            app.amp_box.Value = num2str(app.amp);
            app.amp_slider.Value = app.amp;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
            
        end

        % Value changed function: amp_slider
        function amp_slider_Callback(app, event)
            % Redondea y actualiza la UI
            app.amp = round(app.amp_slider.Value,2);
            app.amp_box.Value = num2str(app.amp);
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Value changed function: fase_box
        function fase_box_Callback(app, event)
            
            str = app.fase_box.Value;
            num = str2double(str);
            
            % Valida el input
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif abs(num) > 1
                num = 0;
                warn = warndlg(app.phase_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            app.theta = round(num,1);
            app.fase_box.Value = num2str(app.theta);
            app.fase_slider.Value = app.theta;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Value changed function: fase_slider
        function fase_slider_Callback(app, event)
            % Redondea y actualiza la UI
            app.theta = round(app.fase_slider.Value,1);
            app.fase_box.Value = num2str(app.theta);
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % COnfiguración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Value changed function: frec_box
        function frec_box_Callback(app, event)
            str = app.frec_box.Value;
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0 || num > 20000
                num = 0;
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            app.frec = round(num);
            app.frec_box.Value = num2str(app.frec);
            app.frec_slider.Value = app.frec;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Value changed function: frec_slider
        function frec_slider_Callback(app, event)
            % Redondea y actualiza la UI
            app.frec = round(app.frec_slider.Value);
            app.frec_box.Value = num2str(app.frec,'%u');
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Button pushed function: generar_btm
        function generar_btm_Callback(app, event)
            % Envía la señal a la app principal y se cierra
            % Actualiza el plot de la aplicación principal
            success = createPlot(app.CallingApp,app.current_data,app.t,app.visualizationName);
            
            % Si no puede abrir más ventanas de visualización, muestra
            % un mensaje de advertencia y sale de la función
            if success == 0
                warn = warndlg(app.window_warning);
                uiwait(warn);
                return
            end
            % Habilita los menús de la app principal
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            
            delete(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            % Al cerrar la ventana con el botón
            % Habilita los menús de la app principal
            if isvalid(app.CallingApp)
                app.CallingApp.SignalVisualizerMenu.Enable = 'on';
                app.CallingApp.GenerarMenu.Enable = 'on';
                app.CallingApp.InputMenu.Enable = 'on';
                app.CallingApp.OpcionesMenu.Enable = 'on';
            end
            
            delete(app)
        end

        % Value changed function: duty_slider
        function duty_sliderValueChanged(app, event)
            % Redondea y actualiza la UI
            app.duty = round(app.duty_slider.Value);
            app.duty_box.Value = num2str(app.duty,'%u');
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Value changed function: duty_box
        function duty_boxValueChanged(app, event)
            str = app.duty_box.Value;
            num = str2double(str);
            
            % Valida el input
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0 || num > 100
                num = 0;
                warn = warndlg(app.cycle_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            app.duty = round(num);
            app.duty_box.Value = num2str(app.duty);
            app.duty_slider.Value = app.duty;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Size changed function: figure1
        function figure1SizeChanged(app, event)
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            % Redimensiona todos los elementos de la interfaz
            for i = 1:length(app.figure1.Children)
                app.resizeelement(app.figure1.Children(i));
            end
            
            % Guarda en variables el nuevo tamaño de la ventana
            position = app.figure1.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            app.CallingApp.createHelp(3); % 3 = Squarewave
        end

        % Value changed function: offset_slider
        function offset_sliderValueChanged(app, event)
            % Redondea y actualiza la UI
            app.offset = round(app.offset_slider.Value,2);
            app.offset_box.Value = num2str(app.offset);
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Value changed function: offset_box
        function offset_boxValueChanged(app, event)
            str = app.offset_box.Value;
            num = str2double(str);
            
            % Valida el input
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < -2 || num > 2
                num = 0;
                warn = warndlg(app.offset_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            app.offset = round(num,2);
            app.offset_box.Value = num2str(app.offset);
            app.offset_slider.Value = app.offset;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Value changed function: time_slider
        function time_sliderValueChanged(app, event)
            % Redondea y actualiza la UI
            time = round(app.time_slider.Value);
            app.time_box.Value = num2str(time,'%u');
            app.t = linspace(0,time,app.fs*time);
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Value changed function: time_box
        function time_boxValueChanged(app, event)
            str = app.time_box.Value;
            num = str2double(str);
            
            % Valida el input
            if isempty(num) || isnan(num)
                num = 1;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 1 || num > 30
                num = 1;
                warn = warndlg(app.time_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            time = round(num);
            app.t = linspace(0,time,app.fs*time);
            app.time_box.Value = num2str(time);
            app.time_slider.Value = time;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*(square(2*pi*app.frec*app.t + pi*app.theta,app.duty)/2) + app.offset*ones(size(app.t));
            
            % Configuración del plot
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-ceil(max(abs(app.current_data))),ceil(max(abs(app.current_data)))])
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            signalAux = detrend(app.current_data); % Elimina el offset
            if(max(abs(signalAux)) > 1) % Normaliza la señal si su amplitud es > 1
                signalAux = signalAux./max(abs(signalAux));
            end
            
            app.player = audioplayer(signalAux,app.fs);
            play(app.player)
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % Comprueba si el audioplayer ha sido ya creado, antes de
            % detener la reproducción en curso
            if ~isempty(app.player)
                stop(app.player)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'tone';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.amp_slider = uislider(app.figure1);
            app.amp_slider.Limits = [0 1];
            app.amp_slider.MajorTicks = [];
            app.amp_slider.ValueChangedFcn = createCallbackFcn(app, @amp_slider_Callback, true);
            app.amp_slider.MinorTicks = [];
            app.amp_slider.Value = 0.5;
            app.frec_slider = uislider(app.figure1);
            app.frec_slider.Limits = [0 20000];
            app.frec_slider.MajorTicks = [];
            app.frec_slider.ValueChangedFcn = createCallbackFcn(app, @frec_slider_Callback, true);
            app.frec_slider.MinorTicks = [];
            app.frec_slider.Value = 2;
            app.fase_slider = uislider(app.figure1);
            app.fase_slider.Limits = [-1 1];
            app.fase_slider.MajorTicks = [];
            app.fase_slider.ValueChangedFcn = createCallbackFcn(app, @fase_slider_Callback, true);
            app.fase_slider.MinorTicks = [];
            app.generar_btm = uibutton(app.figure1, 'push');
            app.generar_btm.ButtonPushedFcn = createCallbackFcn(app, @generar_btm_Callback, true);
            app.generar_btm.Text = 'Generar';
            app.amp_label = uilabel(app.figure1);
            app.amp_label.Text = 'Amplitud';
            app.frec_label = uilabel(app.figure1);
            app.frec_label.Text = 'Frecuencia';
            app.fase_label = uilabel(app.figure1);
            app.fase_label.Text = 'Fase';
            app.amp_box = uieditfield(app.figure1, 'text');
            app.amp_box.ValueChangedFcn = createCallbackFcn(app, @amp_box_Callback, true);
            app.amp_box.Value = 'Edit Text';
            app.frec_box = uieditfield(app.figure1, 'text');
            app.frec_box.ValueChangedFcn = createCallbackFcn(app, @frec_box_Callback, true);
            app.frec_box.Value = 'Edit Text';
            app.fase_box = uieditfield(app.figure1, 'text');
            app.fase_box.ValueChangedFcn = createCallbackFcn(app, @fase_box_Callback, true);
            app.fase_box.Value = 'Edit Text';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Hz';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'Generar onda cuadrada';
            app.duty_slider = uislider(app.figure1);
            app.duty_slider.Limits = [10 90];
            app.duty_slider.MajorTicks = [];
            app.duty_slider.ValueChangedFcn = createCallbackFcn(app, @duty_sliderValueChanged, true);
            app.duty_slider.MinorTicks = [];
            app.duty_slider.Value = 50;
            app.ciclo_label = uilabel(app.figure1);
            app.ciclo_label.Text = 'Ciclo activo';
            app.duty_box = uieditfield(app.figure1, 'text');
            app.duty_box.ValueChangedFcn = createCallbackFcn(app, @duty_boxValueChanged, true);
            app.duty_box.Value = 'Edit Text';
            app.text6_2 = uilabel(app.figure1);
            app.text6_2.Text = 'π rad';
            app.text6_3 = uilabel(app.figure1);
            app.text6_3.Text = '%';
            app.infoButton = uibutton(app.figure1, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Icon = 'info.png';
            app.infoButton.Text = '';
            app.time_slider = uislider(app.figure1);
            app.time_slider.Limits = [1 30];
            app.time_slider.MajorTicks = [];
            app.time_slider.ValueChangedFcn = createCallbackFcn(app, @time_sliderValueChanged, true);
            app.time_slider.MinorTicks = [];
            app.time_slider.Value = 1;
            app.time_label = uilabel(app.figure1);
            app.time_label.Text = {'Duración'; 'total'};
            app.time_box = uieditfield(app.figure1, 'text');
            app.time_box.ValueChangedFcn = createCallbackFcn(app, @time_boxValueChanged, true);
            app.time_box.Value = 'Edit Text';
            app.text6_4 = uilabel(app.figure1);
            app.text6_4.Text = 's';
            app.offset_slider = uislider(app.figure1);
            app.offset_slider.Limits = [-2 2];
            app.offset_slider.MajorTicks = [];
            app.offset_slider.ValueChangedFcn = createCallbackFcn(app, @offset_sliderValueChanged, true);
            app.offset_slider.MinorTicks = [];
            app.offset_label = uilabel(app.figure1);
            app.offset_label.Text = 'Offset';
            app.offset_box = uieditfield(app.figure1, 'text');
            app.offset_box.ValueChangedFcn = createCallbackFcn(app, @offset_boxValueChanged, true);
            app.offset_box.Value = 'Edit Text';
            app.playButton = uibutton(app.figure1, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play_button.png';
            app.playButton.Text = '';
            app.stopButton = uibutton(app.figure1, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop_button.png';
            app.stopButton.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = square_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end