classdef visualization_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VentanaDropDown                matlab.ui.control.DropDown
        nfftDropDown                   matlab.ui.control.DropDown
        TamaodeventanaEditField        matlab.ui.control.NumericEditField
        VentanaLabel                   matlab.ui.control.Label
        TamaodeventanaLabel            matlab.ui.control.Label
        nfftLabel                      matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        PlusxButton1                   matlab.ui.control.Button
        MinusxButton1                  matlab.ui.control.Button
        PlusyButton1                   matlab.ui.control.Button
        MinusyButton1                  matlab.ui.control.Button
        UpButton1                      matlab.ui.control.Button
        DownButton1                    matlab.ui.control.Button
        RightButton1                   matlab.ui.control.Button
        LeftButton1                    matlab.ui.control.Button
        ResetButton1                   matlab.ui.control.Button
        exportButton1                  matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        PlusxButton2                   matlab.ui.control.Button
        MinusxButton2                  matlab.ui.control.Button
        PlusyButton2                   matlab.ui.control.Button
        MinusyButton2                  matlab.ui.control.Button
        UpButton2                      matlab.ui.control.Button
        DownButton2                    matlab.ui.control.Button
        RightButton2                   matlab.ui.control.Button
        LeftButton2                    matlab.ui.control.Button
        ResetButton2                   matlab.ui.control.Button
        exportButton2                  matlab.ui.control.Button
        infoButton                     matlab.ui.control.Button
        Panel_Reproduccion             matlab.ui.container.Panel
        stopButton                     matlab.ui.control.Button
        playSegmentButton              matlab.ui.control.Button
        playButton                     matlab.ui.control.Button
        Panel_layout_3                 matlab.ui.container.Panel
        middle_layout3                 matlab.ui.control.Button
        up_layout3                     matlab.ui.control.Button
        restore_layout3                matlab.ui.control.Button
        down_layout3                   matlab.ui.control.Button
        Panel_layout_2                 matlab.ui.container.Panel
        down_layout2                   matlab.ui.control.Button
        up_layout2                     matlab.ui.control.Button
        restore_layout2                matlab.ui.control.Button
        fsField                        matlab.ui.control.NumericEditField
        fsLabel                        matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        ExportarimagenMenu_UIAxes      matlab.ui.container.Menu
        GuardarcomoMenu                matlab.ui.container.Menu
        CopiarcomomapadebitsMenu       matlab.ui.container.Menu
        CopiarcomoimagenvectorialMenu  matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        EscaladelejeYMenu_UIAxes2      matlab.ui.container.Menu
        LinealMenu_2                   matlab.ui.container.Menu
        LogartmicaMenu_2               matlab.ui.container.Menu
        ExportarimagenMenu_UIAxes2     matlab.ui.container.Menu
        GuardarcomoMenu_2              matlab.ui.container.Menu
        CopiarcomomapadebitsMenu_2     matlab.ui.container.Menu
        CopiarcomoimagenvectorialMenu_2  matlab.ui.container.Menu
        ContextMenu3                   matlab.ui.container.ContextMenu
        ExportarimagenMenu_UIAxes3     matlab.ui.container.Menu
        GuardarcomoMenu_3              matlab.ui.container.Menu
        CopiarcomomapadebitsMenu_3     matlab.ui.container.Menu
        CopiarcomoimagenvectorialMenu_3  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        windowNumber % Número de plotApp asignado
        
        time % array de tiempos
        fs = 48000; % Frecuencia de muestreo
        CallingApp % Aplicación principal
        mode % 1: ft 2: stft 3: spectrogram 4: spectrogram + stft
        layout = 0 % 0: dos plots 1: tres plots
        wSize % Tamaño de ventana del SFTF
        nfft % Número de puntos de la DFT
        overlap = 0.66; % Overlap de ventanas
        wType % Tipo de ventana
        center = 0;% Situación del puntero sobre la señal
        center2 = 0; % Situación del puntero sobre el espectro
        startPlaying = 0; % Punto desde donde se empieza a reproducir la señal
        player
        
        maxWindow = 1; % Máximo tamaño de ventana permitido (en segundos)
        
        lockedButtons = cell(20); % Para la función lock buttoms, guarda qué botones estaban bloqueados
        
        yScale2 = 'log'; % Escala del eje frecuencial del espectro/espectrograma
        
        windowOriginalWidth = 720;%634; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 554; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        
        % Dimensiones originales de los ejes en los diferentes layout
        % Layout con dos ejes
        axes1_layout2 = [10,305,631,220];
        axes2_layout2 = [10,82,631,220];
        % Layout con tres ejes
        axes1_layout3 = [10,376,631,146.67];
        axes2_layout3 = [10,229,631,146.67];
        axes3_layout3 = [10,82,631,146.67];
        
        % Layout plot ampliado
        mainPlot_size = [10,82,631,443];
        
        % Factores de zoom
        zoomxFactor1 = 1;
        zoomyFactor1 = 1;
        zoomxFactor2 = 1;
        zoomyFactor2 = 1;
        
        % Límites de los ejes (con el zoom óptimo que calcula la aplicación para ver el espectro)
        % Se guardan para que, al pulsar el botón reset, vuelva a la vista
        % original
        yLim1
        xLim2
        yLim2
        
        % Mensajes de warning por defecto, por si falla la función
        % translate()
        numerical_warning = 'El valor introducido debe ser un número'; % Description
        even_warning = 'El valor introducido debe ser par'; % Description
        wsize_warning = 'El valor introducido debe estar entre 0.001 y 5 segundos.';
        wsize2_warning = 'El valor introducido no puede ser mayor que la duración de la señal.';
        window_warning = 'La ventana no puede situarse fuera de la señal'; % Description
        
        % Labels por defecto, por si falla la función translate()
        ft_title = 'Espectro';
        stft_title = 'Espectro';
        spectrogram_title = 'Espectrograma';
        export_signal = 'Exportar datos de forma de onda';
        export_spectrum = 'Exportar datos de espectro';
        freq_label = 'Frecuencia (Hz)';
        time_label = 'Tiempo (s)';
        amp_label = 'Amplitud';
        amp_spectrum_label = 'Amplitud (dB)';
        
        % Colores del plot personalizados
        plot_color; % Color del trazo
        spectrogram_colormap; % Mapa de colores del espectrograma
        text_color; % Color del texto (se calcula automáticamente para que contraste)
        window_color; % Color de la ventana
        
        % Esta variable se usa para saber si calcular el zoom o no
        state = 0; % 0 = Inicio del programa // 1 = FT, STFT o STFT + espectrograma // 2 = Espectrograma
        
    end
    
    properties (Access = public)
        signal = []; % Array con la señal cargada
        spectrum = []; % Espectro
        freq = []; % Array de frecuencias
    end
    
    methods (Access = public)
        
        function updatePlot(app,signal,t,varargin)
            % Esta función se utiliza para calcular el espectro la primera
            % vez, según se abre el módulo
            
            app.signal = signal;
            app.time = t;
            
            %Realizar fft de la señal
            Y = fft(app.signal);
            P2 = abs(Y/length(app.signal));
            app.spectrum = P2(1:length(app.signal)/2+1);
            app.spectrum(2:end-1) = 2*app.spectrum(2:end-1);
                
            app.freq = app.fs*(0:(length(app.signal)/2))/length(app.signal); % Calcula array de frecuencias
            
            % Representa la forma de onda y anula las herramientas de UIAxes
            plot(app.UIAxes,app.time,app.signal,"Color",app.plot_color);
            axtoolbar(app.UIAxes,{});
            
            % Ajusta el eje Y a la señal, si se ha especificado en varargin
            % que es un caso espectial
            if ~isempty(varargin)
                % Para las señales que no tienen valores negativos.
                % Actualmente se utiliza para Rosenberg
                if strcmp(varargin{1},'Positive')
                    app.UIAxes.YLim = [0,1];
                else % Si varargin(1) es 'SoundFile'
                    app.UIAxes.YLim = [-1,1];
                end
            % Si no es un caso especial, redondea los límites al número
            % entero
            else
                app.UIAxes.YLim = [-ceil(max(abs(app.signal))),ceil(max(abs(app.signal)))];
            end
            
            app.UIAxes.XLim = [0,app.time(end)];
            app.yLim1 = app.UIAxes.YLim;
                        
            plot(app.UIAxes2,app.freq,app.spectrum,"Color",app.plot_color,'Marker','.');
            axtoolbar(app.UIAxes2,{});
            
            % Ajusta el eje X a las frecuencias relevantes del fft (esta parte es mejorable)
            % Busca cuál es el rango frecuencial en el que la potencia del
            % espectro supera el límite establecido. No siempre funciona bien
            xa = find(app.spectrum > 10^-2,1);
            xb = find(app.spectrum > 10^-2,1,'last');
            
            if isempty(xa) % Ajusta límite inferior
                xa = 0;
            else
                xa = app.freq(xa);
            end
            
            if isempty(xb) % Ajusta límite superior
                xb = app.fs/2;
            else
                xb = app.freq(xb);
            end
            
            % Para que se vea mejor, amplia un poco los límites superior e
            % inferior
            if xa > 10
                xa = round(xa*0.8); % Este método no funciona bien cuando el límite inferior es muy bajo
            else
                xa = 0; % Es este caso es preferible mostrar desde 0
            end
            
            if xb < 10 % Si el límite superior es muy bajo, se mostrará al menos hasta 10 Hz
                xb = 10;
            % Amplía un poco el límite superior siempre que quede dentro del rango de frecuencias para el que se ha calculado el espectro
            elseif xb*1.2 < app.fs/2
                xb = xb*1.2;
            end
            
            % Establece los límites calculados y los guarda
            app.xLim2 = [xa,xb];
            app.UIAxes2.XLim = app.xLim2;
            app.yLim2 = app.UIAxes2.YLim;
            
            % Calcula que porcentaje del rango total representa el rango de
            % frecuencias demarcado por los límites
            app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
            if  app.zoomxFactor2 < 0.011 % Si el porcentaje es menor al 11%, no se permite hacer más zoom
                app.PlusxButton2.Enable = 'off';
            else
                app.PlusxButton2.Enable = 'on';
            end
            
            % Si el porcentaje es mayor al 91%, no se permite quitar zoom
            % (91% pq el zoom pasa del 100% al 90%)
            if app.zoomxFactor2 < 0.91
                app.MinusxButton2.Enable = 'on';
            else
                app.MinusxButton2.Enable = 'off';
            end
            
            % Para cargar la señal en el reproductor de audio, su amplitud
            % máxima no debe superar la unidad. En caso contrario, se normaliza
            if(max(abs(app.signal)) > 1)
                signalAux = app.signal./max(abs(app.signal));
            else
                signalAux = app.signal;
            end                        
            
            app.player = audioplayer(signalAux,app.fs);
            app.playButton.Enable = 'on';
            app.playSegmentButton.Enable = 'on';
            app.stopButton.Enable = 'on';
            
            app.state = 1; % Estado 1 -> FT
        end
    end
    
    methods (Access = private)
        
        
        function resizeWindow(app)
            % Ajusta el tamaño de la ventana a la resolución de la pantalla
            % que se utiliza
            
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 720;
            originalh = 554;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element)
            % Ajusta el tamaño de element al nuevo tamaño de ventana
            
            if strcmp(element.Type,'uicontextmenu') || strcmp(element.Type,'uimenu') % Al intentar redimensionar un UIContext Menu, salta un error y detiene la ejecución
                return
            end
            
            warning('off') % Muestra warning pq no se puede cambiar height de los slider horizontales.
            % No merece la pena solucionar el problema.
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
            warning('on')
            
        end
        
        function translate(app,language)
            load('language.mat','visualization')
                        
            % Etiquetas de los plot
            app.UIAxes.YLabel.String = visualization.amplitude{language};
            app.UIAxes.XLabel.String = visualization.time{language};
            app.time_label = visualization.time{language};
            app.UIAxes.Title.String = visualization.signal{language};
            
            app.amp_label = visualization.amplitude{language};
            app.amp_spectrum_label = strcat(app.amp_label,' (dB)');
            app.UIAxes2.YLabel.String = app.amp_spectrum_label;            
            app.UIAxes2.XLabel.String = visualization.frequency{language};
            app.freq_label = visualization.frequency{language};
            app.ft_title = visualization.ft_title{language};
            app.stft_title = visualization.stft_title{language};
            app.spectrogram_title = visualization.spectrum_title{language};
            title(app.UIAxes2,app.ft_title);
            
            % Títulos de las ventanas de exportación
            app.export_signal = visualization.export_signal{language};
            app.export_spectrum = visualization.export_spectrum{language};
            
            % Menús contextuales (clic derecho)
            app.EscaladelejeYMenu_UIAxes2.Text = visualization.yscale{language};
            app.LinealMenu_2.Text = visualization.linear{language};
            app.LogartmicaMenu_2.Text = visualization.logarithmic{language};
            app.ExportarimagenMenu_UIAxes.Text = visualization.exportImage{language};
            app.ExportarimagenMenu_UIAxes2.Text = visualization.exportImage{language};
            app.ExportarimagenMenu_UIAxes3.Text = visualization.exportImage{language};
            app.GuardarcomoMenu.Text = visualization.saveAs{language};
            app.GuardarcomoMenu_2.Text = visualization.saveAs{language};
            app.GuardarcomoMenu_3.Text = visualization.saveAs{language};
            app.CopiarcomomapadebitsMenu.Text = visualization.copyBitmap{language};
            app.CopiarcomomapadebitsMenu_2.Text = visualization.copyBitmap{language};
            app.CopiarcomomapadebitsMenu_3.Text = visualization.copyBitmap{language};
            app.CopiarcomoimagenvectorialMenu.Text = visualization.copyVector{language};
            app.CopiarcomoimagenvectorialMenu_2.Text = visualization.copyVector{language};
            app.CopiarcomoimagenvectorialMenu_3.Text = visualization.copyVector{language};
            
            % Elementos de la interfaz
            app.VentanaLabel.Text = visualization.window{language};
            app.VentanaDropDown.Items = visualization.window_type{language};
            app.TamaodeventanaLabel.Text = visualization.window_size{language};
            app.DropDown.Items = visualization.mode{language};
            
            % Mensajes de warning
            app.even_warning = visualization.pair_warning{language};
            app.numerical_warning = visualization.numerical_warning{language};
            app.wsize_warning = visualization.wsize_warning{language};
            app.window_warning = visualization.window_warning{language};
            app.wsize2_warning = visualization.wsize2_warning{language};
                        
            clear('visualization')
        end
        
        
        function plotCenterLine(app,pt,newClick)
            % Esta función marca el centro del segmento enventanado y
            % dibuja la ventana aplicada
            
            % Si se ha hecho clic fuera del plot, establece la ventana en
            % el medio de la señal
            if pt(1) < 0
                pt(1) = mean(app.UIAxes.XLim);
            end
            
            % Borra el plot para dibujar de nuevo por capas
            cla(app.UIAxes);
            hold(app.UIAxes,'off')
            
            % xa -> Punto seleccionado, marca la posición en segundos
            xa = pt(1) - (app.wSize-1)/(2*app.fs);
            xwidth = app.wSize/app.fs;
            % Si la ventana se sale de los límites de la señal, muestra un
            % mensaje de warning y se sitúa en mitad de la señal
            if(xa < 0 || xa + xwidth > app.time(end))
                pt(1) = mean(app.UIAxes.XLim);
                if newClick == 1
                    warn = warndlg(app.window_warning);
                    uiwait(warn);
                end
            end

            % Establece el centro de la ventana y calcula el eje de tiempos
            % dentro del rango en que se dibujará la ventana
            app.center = round(pt(1)*app.fs);
            t_ventana = linspace((app.center-app.wSize/2)/app.fs,(app.center+app.wSize/2)/app.fs,app.wSize);
            
            % Cuando se pulse el botón de reproducción, el audio comienza
            % al principio de la ventana
            app.startPlaying = round(app.center-app.wSize/2);
            
            % Obtiene la forma de la ventana, escalada para que su máximo
            % coincida con el de la señal (por estética)
            switch app.wType
                case 1
                    window = rectwin(app.wSize)*max(abs(app.signal));
                case 2
                    window = hann(app.wSize)*max(abs(app.signal));
                case 3
                    window = hamming(app.wSize)*max(abs(app.signal));
                case 4
                    window = gausswin(app.wSize)*max(abs(app.signal));
                case 5
                    window = triang(app.wSize)*max(abs(app.signal));
                case 6
                    window = chebwin(app.wSize)*max(abs(app.signal));
                case 7
                    window = taylorwin(app.wSize)*max(abs(app.signal));
                case 8
                    window = bartlett(app.wSize)*max(abs(app.signal));
                case 9
                    window = kaiser(app.wSize)*max(abs(app.signal));
            end
            
            % Dibuja la mitad superior...
            stem(app.UIAxes,t_ventana,window,'.','Color',app.window_color)
            
            hold(app.UIAxes,'on')
            
            % ... y la mitad inferior
            stem(app.UIAxes,t_ventana,-window,'.','Color',app.window_color)
            
            % Superpone la forma de onda y, encima, el eje que marca el
            % centro de la ventana
            plot(app.UIAxes,app.time,app.signal,"Color",app.plot_color)
            plot(app.UIAxes,[pt(1),pt(1)],app.UIAxes.YLim,'.-',"Color",app.text_color)
            
            if app.mode == 4 % Si está en modo STFT + Espectrograma
                app.calculateSpectrogram(); % Recalcula y redibuja el espectrograma en UIAxes3
                hold(app.UIAxes3,'on')
                app.calculateSTFT(); % Recalcula y dibuja la STFT en UIAxes2
                % Muestra el mismo rango frecuencial en la STFT y en el espectrograma
                app.UIAxes3.YLim = app.UIAxes2.XLim;
                % Marca el lugar que correspoonde al segmento enventanado
                % sobre el espectrograma (para ver correspondencia entre la STFT y el espectrograma)
                plot(app.UIAxes3,[pt(1),pt(1)],app.UIAxes3.YLim,'.-',"Color",app.text_color);
            else % Si está en el modo STFT, simplemente recalcula y redibuja la STFT
                app.calculateSTFT();
            end
        end
        
        function addDataTip(app,pt)
            % Esta función dibuja el marcador de posición sobre la forma de
            % onda al hacer clic sobre ella (en modo FT o espectrograma)
            
            % ptx -> Muestra de la forma de onda correspondiente con la
            % posición seleccionada
            % pt(1) -> Posición seleccionada en el eje X, en segundos
            ptx = round(pt(1)*app.fs);
            if ptx < 1 % Si el punto seleccionado queda fuera del plot, elimina el marcador anterior, si lo hubiese
                hold(app.UIAxes,'off') % Es decir, borra todo y dibuja la señal
                plot(app.UIAxes,app.time,app.signal,"Color",app.plot_color)
                return
            end
            
            % Borra el plot para redibujar por capas:
            hold(app.UIAxes,'off')
            % Dibuja la forma de onda
            plot(app.UIAxes,app.time,app.signal,"Color",app.plot_color)
            hold(app.UIAxes,'on')
            % Dibuja el cursor vertical y luego el horizontal
            plot(app.UIAxes,[pt(1),pt(1)],app.UIAxes.YLim,':',"Color",app.text_color)
            plot(app.UIAxes,app.UIAxes.XLim,[1 1]*app.signal(ptx),':',"Color",app.text_color)
            
            app.startPlaying = round(ptx); % Lugar desde donde debe empezar a reproducir el sonido si se hace clic en "Reproducir a partir del cursor"
            app.center = pt(1); % Si se cambia al modo STFT o STFT + Espectrograma, el centro de la ventana será este
            
            % Escribe los valores [x,y] en el punto seleccionado
            if pt(1) < mean(app.UIAxes.XLim) % Si el cursor está en la parte izquierda, escribe en la parte derecha
                text(app.UIAxes,app.UIAxes.XLim(2),app.UIAxes.YLim(1),sprintf(' x: %.2f\n y: %.2f',pt(1),app.signal(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom",'HorizontalAlignment',"right","Color",app.text_color)
            else % Y viceversa
                text(app.UIAxes,app.UIAxes.XLim(1),app.UIAxes.YLim(1),sprintf(' x: %.2f\n y: %.2f',pt(1),app.signal(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom","Color",app.text_color)
            end
        end
        
        function addDataTipSpectrum(app,pt)
            % Esta función dibuja el valor del punto seleccionado en el
            % espectro al hacer clic sobre él. No funciona con el espectrograma.
            
            % Si se ha hecho clic fuera del plot, no hace nada
            if pt(1) < 0
                return
            end
            
            % Busca el valor del array de frecuencias más cercano al punto
            % seleccionado
            % ptx -> Muestra del array de frecuencias donde está el valor
            % seleccionado
            % p(1) -> Valor seleccionado, en Hz (puede no coincidir con un valor exacto en el array de frecuencias)
            [~,ptx] = min(abs(app.freq - pt(1)));
            
            % Redibuja por capas. En primer lugar, el espectro
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,app.freq,app.spectrum,"Color",app.plot_color,'Marker','.')
            hold(app.UIAxes2,'on')
            
            % A continuación, el cursor vertical y luego el horizontal
            plot(app.UIAxes2,[1,1]*app.freq(ptx),app.UIAxes2.YLim,':',"Color",app.text_color)
            plot(app.UIAxes2,app.UIAxes2.XLim,[1 1]*app.spectrum(ptx),':',"Color",app.text_color)
            app.center2 = pt(1);
            
            % Escribe los valores [x,y] del punto seleccionado
            if app.freq(ptx) < mean(app.UIAxes2.XLim) % Si el cursor está a la izquierda del plot, escribe los valores en la derecha
                text(app.UIAxes2,app.UIAxes2.XLim(2),app.UIAxes2.YLim(1),sprintf(' x: %.2f\n y: %.2f',app.freq(ptx),app.spectrum(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom","HorizontalAlignment","right","Color",app.text_color)
            else % Y viceversa
                text(app.UIAxes2,app.UIAxes2.XLim(1),app.UIAxes2.YLim(1),sprintf(' x: %.2f\n y: %.2f',app.freq(ptx),app.spectrum(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom","Color",app.text_color)
            end
        end
        
        function calculateSTFT(app)
            % Esta función calcula y representa la STFT de la señal
            
            lockButtoms(app,0) % Bloquear controles de usuario mientras se calcula
            
            % Se queda con el segmento a enventanar
            x = app.signal(app.center - (app.wSize-1)/2:app.center + (app.wSize-1)/2);
            
            % Obtiene forma de ventana para la duración especificada
            switch app.wType
                case 1
                    window = rectwin(app.wSize);
                case 2
                    window = hann(app.wSize);
                case 3
                    window = hamming(app.wSize);
                case 4
                    window = gausswin(app.wSize);
                case 5
                    window = triang(app.wSize);
                case 6
                    window = chebwin(app.wSize);
                case 7
                    window = taylorwin(app.wSize);
                case 8
                    window = bartlett(app.wSize);
                case 9
                    window = kaiser(app.wSize);
            end
            
            % Calcula la STFT y el array de frecuencias
            app.spectrum = pwelch(x,window,0,app.nfft);
            app.freq = linspace(0,app.fs/2,length(app.spectrum));
            
            % Limpia el UIAxes 2 y dibuja el espectro
            cla(app.UIAxes2);            
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,app.freq,app.spectrum,"Color",app.plot_color,'Marker','.');
            app.UIAxes2.YScale = app.yScale2; % Establece la escala del eje de amplitud (log o lineal)
            
            switch app.state
                % Creo que el caso 0 no se da nunca, pq en updatePlot cambia a state = 1 y ya no vuelve.
                % Código puesto por si se pudiera dar por error
                case 0                                        
                    % Restablece el zoom de amplitud
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    app.yLim2 = app.UIAxes2.YLim;     
                    
                    app.UIAxes2.XLim = app.xLim2; % Rango de frecuencias mostrado: el calculado en updatePlot()
                    % Calcula qué porcentaje representa el rango de
                    % frecuencias mostrado con respecto al rango total
                    app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
                    
                    % Si el rango mostrado representa menos del 11%, no se
                    % permite aumentar el zoom
                    if  app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    else
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    % Si el rango mostrado representa más del 91%, no se
                    % permite quitar zoom
                    % (91% pq el zoom pasa del 100% al 90%)
                    if app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    else
                        app.MinusxButton2.Enable = 'off';
                    end
                                        
                    app.center2 = 0;
                    
                % Si viene de mostrar la FT o la STFT en UIAxes2
                case 1
                    % Se restaura el zoom en amplitud. No se cambia el
                    % rango de frecuencias mostrado
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                % Si viene de mostrar el espectrograma en UIAxes2
                case 2
                    % En el espectrograma, el eje de frecuencias está en el
                    % eje Y, y en la STFT está en el eje X
                    % El rango de frecuencias mostrado (el zoom) se debe
                    % mantener
                    
                    % Los botones de zoom frecuencial pasan de ser los del
                    % eje X a los del eje Y. Se conserva el zoom -> se
                    % conserva su estado
                    app.PlusxButton2.Enable = app.PlusyButton2.Enable;
                    app.MinusxButton2.Enable = app.MinusyButton2.Enable;
                    
                    ylim = app.UIAxes2.YLim; % ylim -> rango frecuencial mostrado en el espectrograma
                    
                    % Se ajustan los límites del eje Y a la amplitud del
                    % espectro
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    % Se establece en el eje X el rango de frecuencias que
                    % se mostraba en el espectrograma
                    app.UIAxes2.XLim = ylim;
            end
            app.state = 1; % Estado 1 -> STFT
            
            % Etiquetas del plot
            app.UIAxes2.XLabel.String = app.freq_label;
            app.UIAxes2.YLabel.String = app.amp_spectrum_label;
            title(app.UIAxes2,app.stft_title)
            
            lockButtoms(app,1) % Desbloquear controles de usuario
        end
        
        function calculateFT(app)
            % Esta función calcula la FT de la señal y la representa en
            % UIAxes2
            % (Cuando se calcula la FT la primera vez, al abrir el módulo,
            % se hace en la función updatePlot, pq requiere calcular los
            % límites y otras cosas.
            
            lockButtoms(app,0) % Bloquea botones de usuario durante el proceso
            
            %Calcula fft de la señal
            Y = fft(app.signal);
            P2 = abs(Y/length(app.signal));
            app.spectrum = P2(1:length(app.signal)/2+1);
            app.spectrum(2:end-1) = 2*app.spectrum(2:end-1);
            
            app.freq = app.fs*(0:(length(app.signal)/2))/length(app.signal);
            
            % Dibuja el espectro
            cla(app.UIAxes2);
            
            plot(app.UIAxes2,app.freq,app.spectrum,"Color",app.plot_color,'Marker','.');
            app.UIAxes2.YScale = app.yScale2; % Establece la escala del eje de amplitud (log o lineal)
            
            switch app.state
                % Creo que el caso 0 no se da nunca, pq en updatePlot cambia a state = 1 y ya no vuelve.
                % Código puesto por si se pudiera dar por error
                case 0                    
                    % Restablece el zoom de amplitud
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    app.yLim2 = app.UIAxes2.YLim;     
                    
                    app.UIAxes2.XLim = app.xLim2; % Rango de frecuencias mostrado: el calculado en updatePlot()
                    % Calcula qué porcentaje representa el rango de
                    % frecuencias mostrado con respecto al rango total
                    app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
                    
                    % Si el rango mostrado representa menos del 11%, no se
                    % permite aumentar el zoom
                    if  app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    else
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    % Si el rango mostrado representa más del 91%, no se
                    % permite quitar zoom
                    % (91% pq el zoom pasa del 100% al 90%)
                    if app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    else
                        app.MinusxButton2.Enable = 'off';
                    end
                                        
                    app.center2 = 0;
                    
                % Si viene de mostrar la FT o la STFT en UIAxes2
                case 1
                    % Restablece el zoom de la amplitud del espectro
                    % Mantiene el zoom en el eje de frecuencias
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                % Si viene de mostrar el espectrograma en UIAxes2
                case 2
                    % En el espectrograma, el eje de frecuencias está en el
                    % eje Y, y en la STFT está en el eje X
                    % El rango de frecuencias mostrado (el zoom) se debe
                    % mantener
                    
                    % Los botones de zoom frecuencial pasan de ser los del
                    % eje X a los del eje Y. Se conserva el zoom -> se
                    % conserva su estado
                    app.PlusxButton2.Enable = app.PlusyButton2.Enable;
                    app.MinusxButton2.Enable = app.MinusyButton2.Enable;
                    
                    ylim = app.UIAxes2.YLim; % ylim -> rango frecuencial mostrado en el espectrograma
                    
                    % Se ajustan los límites del eje Y a la amplitud del
                    % espectro
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    % Se establece en el eje X el rango de frecuencias que
                    % se mostraba en el espectrograma
                    app.UIAxes2.XLim = ylim;
            end
            app.state = 1; % Estado 1 -> Muestra la FT
            
            % Labels del plot
            app.UIAxes2.XLabel.String = app.freq_label;
            app.UIAxes2.YLabel.String = app.amp_spectrum_label;
            title(app.UIAxes2,app.ft_title)
            
            lockButtoms(app,1) % Desbloquea controles de usuario
        end
        
        function calculateSpectrogram(app)
            
            lockButtoms(app,0) % Bloquea botones de usuario durante el proceso
            
            % Crea una ventana del tamaño y la forma elegidos
            switch app.wType
                case 1
                    window = rectwin(app.wSize);
                case 2
                    window = hann(app.wSize);
                case 3
                    window = hamming(app.wSize);
                case 4
                    window = gausswin(app.wSize);
                case 5
                    window = triang(app.wSize);
                case 6
                    window = chebwin(app.wSize);
                case 7
                    window = taylorwin(app.wSize);
                case 8
                    window = bartlett(app.wSize);
                case 9
                    window = kaiser(app.wSize);
            end
            
            % Calcula el espectrograma
            [s,f,t] = spectrogram(app.signal,window,round(app.overlap*app.wSize),app.nfft,app.fs);
            
            % En el modo STFT + espectrograma
            if app.mode == 4
                % Dibuja el espectrograma en UIAxes3
                cla(app.UIAxes3);
                imagesc(app.UIAxes3,t,f,20*log10(abs(s)));
                set(app.UIAxes3,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
                colormap(app.UIAxes3,app.spectrogram_colormap);
                
                axtoolbar(app.UIAxes3,{'export'}); % Parece que el ContextMenu3 no funciona, así que en UIAxes3 se usa la herramienta exportar
                
                % Se coordina la vista con los otros dos plots
                app.UIAxes3.YLim = app.UIAxes2.XLim; % En la STFT y en el espectrograma -> Mismo rango de frecuencias
                app.UIAxes3.XLim = app.UIAxes.XLim; % En la forma de onda y en el espectrograma -> Mismo segmento de tiempo
                
                % Se colocan las etiquetas del plot
                app.UIAxes3.YLabel.String = app.freq_label;
                app.UIAxes3.XLabel.String = app.time_label;
                app.UIAxes3.Title.String = app.spectrogram_title;
            % En el modo espectrograma
            else
                % Se guarda el rango de frecuencias mostrado, para
                % mantener el zoom
                xlim = app.UIAxes2.XLim;
                
                app.UIAxes2.YScale = 'linear';
                
                cla(app.UIAxes2);
                
                % Se representa el espectrograma en escala logarítmica o
                % lineal
                if strcmp(app.yScale2,'log')
                    imagesc(app.UIAxes2,t,f,20*log10(abs(s)));
                else
                    imagesc(app.UIAxes2,t,f,abs(s));
                end
                
                set(app.UIAxes2,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
                colormap(app.UIAxes2,app.spectrogram_colormap);
                
                switch app.state
                    % Este caso no se da nunca. El código está puesto por
                    % si se diera el caso por error
                    case 0
                        % Se mantiene el zoom en el rango frecuencial
                        app.UIAxes2.YLim = app.xLim2;
                        % Muestra el mismo segmento temporal que la forma
                        % de onda
                        app.UIAxes2.XLim = app.UIAxes.XLim;
                        
                        % Botones de zoom horizontal como en la forma de
                        % onda
                        app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                        app.MinusxButton2.Enable = app.MinusxButton1.Enable;
                        
                        % Se calcula el porcentaje que representa el rango
                        % frecuencial mostrado con respecto al rango de
                        % frecuencias calculado
                        app.zoomyFactor2 = (app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1))/app.freq(end);
                        
                        % Si representa menos de un 11%, no permite hacer
                        % zoom
                        if  app.zoomyFactor2 < 0.011
                            app.PlusyButton2.Enable = 'off';
                        else
                            app.PlusyButton2.Enable = 'on';
                        end
                        
                        % Si representa más de un 91%, no se permite
                        % disminuir el zoom
                        % (91% pq el zoom pasa del 100% al 90%)
                        if app.zoomyFactor2 < 0.91
                            app.MinusyButton2.Enable = 'on';
                        else
                            app.MinusyButton2.Enable = 'off';
                        end
                        
                    % En el caso de que venga de mostrar una FT o una STFT
                    % en UIAxes2
                    case 1
                        % En el FT y en el STFT, el eje frecuencial es el
                        % eje X, mientras que en el espectrograma es el Y
                        % Se quiere mostrar el mismo rango de frecuencias
                        % (mantener el zoom)
                        app.PlusyButton2.Enable = app.PlusxButton2.Enable;
                        app.MinusyButton2.Enable = app.MinusxButton2.Enable;
                        
                        app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                        app.MinusxButton2.Enable = app.MinusxButton1.Enable;
                        
                        app.UIAxes2.XLim = app.UIAxes.XLim; % El segmento de tiempos que se muestra -> El mismo que en la forma de onda
                        app.UIAxes2.YLim = xlim;
                end
                app.state = 2; % Estado 2 -> Espectrograma
                
                % Etiquetas del plot
                app.UIAxes2.YLabel.String = app.freq_label;
                app.UIAxes2.XLabel.String = app.time_label;
                app.UIAxes2.Title.String = app.spectrogram_title;
                
            end
            
            lockButtoms(app,1) % Desbloquea botones de usuario
            
        end
        
        function layout3(app) % Cambiar a vista de tres ejes
            % Posición de los ejes para layout de tres plots
            app.UIAxes.Position = app.axes1_layout3;
            app.UIAxes2.Position = app.axes2_layout3;
            app.UIAxes3.Position = app.axes3_layout3;
            
            % Hacer visibles los tres plots
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'on';
            
            % Hacer visibles ambos paneles de cambio de layout (Uno de ellos podría estar oculto)
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'on';
            
            % Mostrar botones del layout de tres plots
            app.Panel_layout_2.Visible = 'off';
            app.Panel_layout_3.Visible = 'on';
            
            app.layout = 1;
        end
        
        function layout2(app) % Cambiar a vista de dos ejes
            % Posición de los ejes para layout de dos plot
            app.UIAxes.Position = app.axes1_layout2;
            app.UIAxes2.Position = app.axes2_layout2;
            app.UIAxes3.Position = app.axes3_layout3;
            
            % Hacer visibles los ejes 1 y 2, y ocultar el 3
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            
            % Hacer visibles ambos paneles de cambio de layout (Uno de ellos podría estar oculto)
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'on';
            
            % Mostrar botones correspondientes al layout de 2 plot
            app.Panel_layout_3.Visible = 'off';
            app.Panel_layout_2.Visible = 'on';
            
            app.layout = 0;
        end
        
        function resizeLayout(app)
            % Al cambiar el tamaño de la ventana, esta función redimensiona
            % el tamaño de los ejes en los diferentes layout, para que se adecúen al
            % nuevo tamaño
            % layout2 -> Vista de 2 plots. En el modo FT, STFT y espectrograma
            % layout3 -> Vista de 3 plots. En el modo STFT + espectrograma
            
            originalhpos = [app.axes1_layout2(1),app.axes1_layout3(1),app.axes2_layout2(1),app.axes2_layout3(1),app.axes3_layout3(1),app.mainPlot_size(1)];
            originalvpos = [app.axes1_layout2(2),app.axes1_layout3(2),app.axes2_layout2(2),app.axes2_layout3(2),app.axes3_layout3(2),app.mainPlot_size(2)];
            originalElementWidth = [app.axes1_layout2(3),app.axes1_layout3(3),app.axes2_layout2(3),app.axes2_layout3(3),app.axes3_layout3(3),app.mainPlot_size(3)];
            originalElementHeight = [app.axes1_layout2(4),app.axes1_layout3(4),app.axes2_layout2(4),app.axes2_layout3(4),app.axes3_layout3(4),app.mainPlot_size(4)];
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            app.axes1_layout2 = [newhpos(1) newvpos(1) neww(1) newh(1)];
            app.axes1_layout3 = [newhpos(2) newvpos(2) neww(2) newh(2)];
            app.axes2_layout2 = [newhpos(3) newvpos(3) neww(3) newh(3)];
            app.axes2_layout3 = [newhpos(4) newvpos(4) neww(4) newh(4)];
            app.axes3_layout3 = [newhpos(5) newvpos(5) neww(5) newh(5)];
            app.mainPlot_size = [newhpos(6) newvpos(6) neww(6) newh(6)];
        end
        
        
        function lockButtoms(app,lock)
            % Función para bloquear o desbloquear los botones de usuario
            % mientras se calculan el espectro o el espectrograma
            
            if lock == 0 % Bloquear todos los botones
                modo = 'off';
                
                % Guarda el estado de los botones y los bloquea
                lockedButtomIndex = 1;
                
                for i = 1:length(app.Panel.Children)
                    app.lockedButtons{lockedButtomIndex} = app.Panel.Children(i).Enable;
                    app.Panel.Children(i).Enable = 'off';
                    lockedButtomIndex = lockedButtomIndex + 1;
                end
                
                for i = 1:length(app.Panel_2.Children)
                    app.lockedButtons{lockedButtomIndex} = app.Panel_2.Children(i).Enable;
                    app.Panel_2.Children(i).Enable = 'off';
                    lockedButtomIndex = lockedButtomIndex + 1;
                end
            else
                modo = 'on';
                
                % Restaura el estado de los botones
                lockedButtomIndex = 1;
                
                for i = 1:length(app.Panel.Children)
                    app.Panel.Children(i).Enable = app.lockedButtons{lockedButtomIndex};
                    lockedButtomIndex = lockedButtomIndex + 1;
                end
                
                for i = 1:length(app.Panel_2.Children)
                    app.Panel_2.Children(i).Enable = app.lockedButtons{lockedButtomIndex};
                    lockedButtomIndex = lockedButtomIndex + 1;
                end
            end
            
            % Bloquear/desbloquear los demás botones
            app.DropDown.Enable = modo;
            app.VentanaDropDown.Enable = modo;
            app.TamaodeventanaEditField.Enable = modo;
            app.nfftDropDown.Enable = modo;


            for i = 1:length(app.Panel_Reproduccion.Children)
                app.Panel_Reproduccion.Children(i).Enable = modo;
            end
            
            for i = 1:length(app.Panel_layout_2.Children)
                app.Panel_layout_2.Children(i).Enable = modo;
            end
            
            for i = 1:length(app.Panel_layout_3.Children)
                app.Panel_layout_3.Children(i).Enable = modo;
            end
            
            pause(0.001)     % Si no se pone, los botones no se bloquean (no sé por qué)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, user_data, signal, t, windowN, name, varargin)
            app.CallingApp = mainApp; % Referencia al módulo principal
            
            app.translate(user_data.lang_ind);
            
            % Colores personalizados de los plots
            app.UIAxes.Color = user_data.bg_color;
            app.UIAxes2.Color = user_data.bg_color;
            app.plot_color = user_data.plot_color;
            app.spectrogram_colormap = user_data.spectrogram_colormap;
            app.window_color = user_data.selection_color;
            bg_color = user_data.bg_color;
            
            % Selecciona el color del texto y de la línea de selección para
            % que haya buen contraste
            if (bg_color(1)*0.299 + bg_color(2)*0.587 + bg_color(3)*0.114) < 0.73
                app.text_color = [1,1,1];
            else
                app.text_color = [0,0,0];
            end
            
            app.fs = 48000; % La frecuencia de muestreo por defecto es 48 kHz
            
            % Si se está cargando un fichero de sonido, se coge su fs
            if ~isempty(varargin)
                if strcmp(varargin{1},'SoundFile')
                    app.fs = varargin{2};
                end
            end
            
            app.fsField.Value = app.fs;
            
            app.UIFigure.Name = name; % Título de la ventana
            app.mode = 1; % Modo 1 -> FT
            app.wType = 1; % 1 -> Ventana rectangular
            app.wSize = app.TamaodeventanaEditField.Value;
            
            app.windowNumber = windowN;
            
            %% Calcular posibles valores de NFFT
            nfft1 = max(256,2^nextpow2(app.wSize));
            
            memoryAux = memory; % Array con datos sobre memoria
            maxElements = floor(memoryAux.MaxPossibleArrayBytes/16); % Nº máximo de elementos de un array de double complex
            segmentN = ceil(length(signal)/(app.wSize*(1-app.overlap))); % Número de puntos en los que se calcula al hacer el barrido de ventana
            maxNfft = 0.5*maxElements/segmentN; % Se permite el 50% del máximo posible
            
            i = 1;
            arrayNFFT = cell(20);
            while nfft1 < maxNfft
                arrayNFFT{i} = num2str(nfft1);
                nfft1 = nfft1*2;
                i = i + 1;
            end
            
            arrayNFFT = arrayNFFT(1:i - 1);
            
            app.nfftDropDown.Items = arrayNFFT;
            app.nfftDropDown.Value = arrayNFFT{1};
            app.nfft = str2double(arrayNFFT{1});
            
            app.updatePlot(signal,t,varargin{:}) % Dibuja la señal
            
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            % Reproducir audio
            
            play(app.player)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Se ejecuta al pulsar el botón de cierre de ventana
            
            % Si está disponible la app principal (a no ser que haya habido un error)
            if isvalid(app.CallingApp)
                % Se decrementa el contador de ventanas de visualización
                % activas
                app.CallingApp.plotApp_counter = app.CallingApp.plotApp_counter - 1;
                % Desactiva el botón asignado a esta ventana
                app.CallingApp.Panel.Children(length(app.CallingApp.Panel.Children) - app.windowNumber + 1).Enable = 'off';                
                app.CallingApp.Panel.Children(length(app.CallingApp.Panel.Children) - app.windowNumber + 1).Text = num2str(app.windowNumber);
            end
            
            % Borra la ventana de visualización
            delete(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % Esta función se activa al hacer clic sobre la ventana
            
            % Si se ha hecho clic con el botón derecho, sale. (Alt -> Clic dcho. Open -> Doble clic dcho)
            if strcmp(event.Source.SelectionType,'alt') || strcmp(event.Source.SelectionType,'open')
                return
            end
            
            % Define los límites de UIAxes
            signalPlotXa = app.UIAxes.Position(1); % Izquierda
            signalPlotYa = app.UIAxes.Position(2); % Abajo
            signalPlotXb = app.UIAxes.Position(3) + signalPlotXa; % Derecha
            signalPlotYb = app.UIAxes.Position(4) + signalPlotYa; % Arriba
            
            % Cordenadas [x,y] del punto donde se ha hecho clic
            currentPoint = event.Source.CurrentPoint;
            
            % Se comprueba si se ha hecho clic sobre UIAxes
            if (currentPoint(1) > signalPlotXa && currentPoint(1) < signalPlotXb) && (currentPoint(2) > signalPlotYa && currentPoint(2) < signalPlotYb)
                pt = app.UIAxes.CurrentPoint(1,1:2);
                if app.mode == 2 || app.mode == 4 % Modo STFT o STFT + espectrograma -> Segmento enventanado
                    app.plotCenterLine(pt,1);
                else % Modo FFT o espectrograma -> Cursor de posición
                    app.addDataTip(pt);
                end
                return
            end
            
            % Define los límites de UIAxes2
            spectrumPlotXa = app.UIAxes2.Position(1); % Izquierda
            spectrumPlotYa = app.UIAxes2.Position(2); % Abajo
            spectrumPlotXb = app.UIAxes2.Position(3) + spectrumPlotXa; % Derecha
            spectrumPlotYb = app.UIAxes2.Position(4) + spectrumPlotYa; % Arriba
            
            % Comprueba si se ha hecho clic en UIAxes2
            if (currentPoint(1) > spectrumPlotXa && currentPoint(1) < spectrumPlotXb) && (currentPoint(2) > spectrumPlotYa && currentPoint(2) < spectrumPlotYb) && app.mode ~= 3
                pt = app.UIAxes2.CurrentPoint(1,1:2);
                app.addDataTipSpectrum(pt); % Coloca cursor de posición
            end
        end

        % Value changed function: TamaodeventanaEditField
        function TamaodeventanaEditFieldValueChanged(app, event)
            num = app.TamaodeventanaEditField.Value;
            
            % Valida el número introducido
            if isempty(num) || isnan(num)
                num = min(app.time(end) * 0.2,1);
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0.001 || num > 1
                num = min(app.time(end) * 0.2,1);
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(app.wsize_warning);
                uiwait(warn);
    elseif num > app.time(end) % La ventana no puede tener mayor duración que la señal
                num = min(app.time(end) * 0.2,1);
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(strcat(app.wsize2_warning,' (',num2str(round(app.time(end),4)),' s)'));
                uiwait(warn);
            end
            
            app.TamaodeventanaEditField.Value = round(num,3);
            % Dibuja la nueva ventana
            app.wSize = round(num*app.fs);
            app.wSize = app.wSize - mod(app.wSize + 1,2); % Si el número es par, se le suma 1
            
            %% Calcular posibles valores de NFFT
            
            memoryAux = memory; % Array con datos sobre memoria
            maxElements = floor(memoryAux.MaxPossibleArrayBytes/16); % Nº máximo de elementos de un array de double complex
            segmentN = ceil(length(app.signal)/(app.wSize*(1-app.overlap))); % Número de puntos en los que se calcula al hacer el barrido de ventana
            maxNfft = 0.5*maxElements/segmentN; % Se ajusta al 50% del máximo posible
            
            nfft1 = max(256,2^nextpow2(app.wSize));
            arrayNFFT = cell(20);
            arrayNFFT{1} = num2str(nfft1);
            nfft1 = nfft1*2;
            i = 2;
            while nfft1 < maxNfft
                arrayNFFT{i} = num2str(nfft1);
                nfft1 = nfft1*2;
                i = i + 1;
            end
            
            arrayNFFT = arrayNFFT(1:i - 1);
            
            app.nfftDropDown.Items = arrayNFFT;
            app.nfftDropDown.Value = arrayNFFT{1};
            app.nfft = str2double(arrayNFFT{1});
            
            switch app.mode
                % Si está en modo STFT, dibuja la ventana y recalcula el
                % espectro
                case 2
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();  
                % Modo espectrograma -> Lo recalcula
                case 3
                    app.calculateSpectrogram();
                % Modo STFT + espectrograma
                case 4
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();
                    app.calculateSpectrogram();
                    
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Value changed function: nfftDropDown
        function nfftDropDownValueChanged(app, event)
            % Al seleccionar otro valor de NFFT
            
            app.nfft = str2double(app.nfftDropDown.Value);
            
            switch app.mode
                % Si está en modo STFT, dibuja la ventana y recalcula el
                % espectro
                case 2
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();  
                % Modo espectrograma -> Lo recalcula
                case 3
                    app.calculateSpectrogram();
                % Modo STFT + espectrograma
                case 4
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();
                    app.calculateSpectrogram();
                    
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Value changed function: VentanaDropDown
        function VentanaDropDownValueChanged(app, event)
            app.wType = find(contains(app.VentanaDropDown.Items,app.VentanaDropDown.Value));
            
            switch app.mode
                % Si está en modo STFT, dibuja la ventana y recalcula el
                % espectro
                case 2
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();  
                % Modo espectrograma -> Lo recalcula
                case 3
                    app.calculateSpectrogram();
                % Modo STFT + espectrograma
                case 4
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();
                    app.calculateSpectrogram();
                    
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % Al cambiar el modo
            
            app.mode = find(contains(app.DropDown.Items,app.DropDown.Value));
            app.mode = app.mode(1); %Si hay varios valores, como en el caso de buscar "FT", se queda con el primero
            
            switch app.mode
                % Modo 1 -> FT
                case 1
                    % Si viene del modo 4, con layout de 3 plots, establece
                    % el layout de 2 plots
                    if app.layout == 1
                        layout2(app)
                    end
                    
                    app.calculateFT();
                    app.addDataTip(app.UIAxes.CurrentPoint(1,1:2)); % Si había un cursor sobre la forma de onda, lo redibuja
                    
                    % Oculta los controles relacionados con las ventanas y
                    % el NFFT
                    app.VentanaLabel.Visible = 'off';
                    app.TamaodeventanaLabel.Visible = 'off';
                    app.nfftLabel.Visible = 'off';
                    app.VentanaDropDown.Visible = 'off';
                    app.VentanaDropDown.Enable = 'off';
                    app.TamaodeventanaEditField.Visible = 'off';
                    app.TamaodeventanaEditField.Enable = 'off';
                    app.nfftDropDown.Visible = 'off';
                    app.nfftDropDown.Enable = 'off';
                    
                % Modo 2 -> STFT
                case 2
                    % Si viene del modo 4, con layout de 3 plots, establece
                    % el layout de 2 plots
                    if app.layout == 1
                        layout2(app)
                    end
                    
                    % Establece una duración de ventana por defecto del 20%
                    % de la duración de la señal
                    app.TamaodeventanaEditField.Value = min(round(app.time(end) * 0.2,3),1);
                    TamaodeventanaEditFieldValueChanged(app,[]) % Ejecuta este callback para calcular la STFT
                    
                    % Muestra los controles relacionados con la ventana y
                    % los puntos NFFT
                    app.VentanaLabel.Visible = 'on';
                    app.TamaodeventanaLabel.Visible = 'on';
                    app.nfftLabel.Visible = 'on';
                    app.VentanaDropDown.Visible = 'on';
                    app.VentanaDropDown.Enable = 'on';
                    app.TamaodeventanaEditField.Visible = 'on';
                    app.TamaodeventanaEditField.Enable = 'on';
                    app.nfftDropDown.Visible = 'on';
                    app.nfftDropDown.Enable = 'on';
                    
                % Modo 3 -> Espectrograma
                case 3
                    % Si viene del modo 4, con layout de 3 plots, establece
                    % el layout de 2 plots
                    if app.layout == 1
                        layout2(app)
                    end
                    
                    app.UIAxes2.YScale = 'linear'; % Devuelve el eje Y a escala lineal
                    
                    % Establece una duración de ventana por defecto del 2,1%
                    % de la duración de la señal
                    app.TamaodeventanaEditField.Value = min(round(app.time(end)*0.021,3),1);
                    TamaodeventanaEditFieldValueChanged(app,[])
                    app.addDataTip(app.UIAxes.CurrentPoint(1,1:2)); % Ejecuta este callback para calcular el espectrograma
                    
                    % Muestra los controles relacionados con la ventana y
                    % los puntos NFFT
                    app.VentanaLabel.Visible = 'on';
                    app.TamaodeventanaLabel.Visible = 'on';
                    app.nfftLabel.Visible = 'on';
                    app.VentanaDropDown.Visible = 'on';
                    app.VentanaDropDown.Enable = 'on';
                    app.TamaodeventanaEditField.Visible = 'on';
                    app.TamaodeventanaEditField.Enable = 'on';
                    app.nfftDropDown.Visible = 'on';
                    app.nfftDropDown.Enable = 'on';
                    
                % Modo 4 -> STFT + espectrograma
                case 4
                    % Si viene de otro modo con un layout de 2 plots,
                    % cambia al layout de 3 plots
                    if app.layout == 0
                        layout3(app)
                    end
                    
                    % Establece una duración de ventana por defecto del 20%
                    % de la duración de la señal
                    app.TamaodeventanaEditField.Value = min(round(app.time(end) * 0.2,3),1);
                    TamaodeventanaEditFieldValueChanged(app,[]) % Llama a este callback para calcular la STFT y el espectrograma
                    
                    % Muestra los controles relacionados con la ventana y
                    % los puntos NFFT
                    app.VentanaLabel.Visible = 'on';
                    app.TamaodeventanaLabel.Visible = 'on';
                    app.nfftLabel.Visible = 'on';
                    app.VentanaDropDown.Visible = 'on';
                    app.VentanaDropDown.Enable = 'on';
                    app.TamaodeventanaEditField.Visible = 'on';
                    app.TamaodeventanaEditField.Enable = 'on';
                    app.nfftDropDown.Visible = 'on';
                    app.nfftDropDown.Enable = 'on';
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % Este callback se ejecuta al abrir el módulo y cada vez que se
            % cambia el tamaño de la ventana
            
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            % Redimensiona los elementos que no estén dentro de un panel
            for i = 1:length(app.UIFigure.Children)
                app.resizeelement(app.UIFigure.Children(i));
            end
            
            % Redimensiona los elementos que estén dentro de un panel
            for i = 1:length(app.Panel.Children)
                app.resizeelement(app.Panel.Children(i));
            end
            
            for i = 1:length(app.Panel_2.Children)
                app.resizeelement(app.Panel_2.Children(i));
            end
            
            for i = 1:length(app.Panel_Reproduccion.Children)
                app.resizeelement(app.Panel_Reproduccion.Children(i));
            end
            
            for i = 1:length(app.Panel_layout_2.Children)
                app.resizeelement(app.Panel_layout_2.Children(i));
            end
            
            for i = 1:length(app.Panel_layout_3.Children)
                app.resizeelement(app.Panel_layout_3.Children(i));
            end
            
            % Redimensiona las posiciones de los Layout
            resizeLayout(app);
            
            % Guarda el tamaño actual de la ventana para usarlo como
            % referencia para futuros cambios
            position = app.UIFigure.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % Detiene la reproducción de sonido
            
            stop(app.player)
        end

        % Button pushed function: ResetButton1
        function ResetButton1Pushed(app, event)
            % Resetea el zoom en UIAxes. Vuelve a la vista inicial
            
            app.zoomxFactor1 = 1;
            app.zoomyFactor1 = 1;
            
            app.MinusxButton1.Enable = 'off';
            app.PlusxButton1.Enable = 'on';
            app.MinusyButton1.Enable = 'off';
            app.PlusyButton1.Enable = 'on';
            
            app.UIAxes.XLim = [0,app.time(end)];
            app.UIAxes.YLim = app.yLim1;
            
            % Si hay un espectrograma, resetea el zoom en el eje de tiempos
            if app.mode == 3
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.MinusxButton1.Enable = 'off';
                app.PlusxButton1.Enable = 'on';
            elseif app.mode == 4
                app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: PlusxButton1
        function PlusxButton1Pushed(app, event)
            % Al aumentar el zoom del eje X en UIAxes
            
            maxLim = app.time(end);
            
            % Si no hay ningún cursor, el zoom se hace con respecto al
            % centro de la señal
            if app.center == 0
                centro = mean(app.UIAxes.XLim);
            % Si no, el zoom se hace con respecto al cursor o al centro de
            % la ventana colocada
            else
                centro = app.center;
            end
            
            % Si se muestra más del 11% de la señal, el rango mostrado se
            % reduce en un 10%
            if app.zoomxFactor1 > 0.11
                app.zoomxFactor1 = app.zoomxFactor1 - 0.1;
            % Si se muestra menos del 11% de la señal, el rango mostrado se
            % reduce en un 1%
            elseif app.zoomxFactor1 < 0.11
                app.zoomxFactor1 = app.zoomxFactor1 - 0.01;
            end
            
            % Si se muestra menos del 1,1% de la señal, no permite ampliar
            % más el zoom
            if app.zoomxFactor1 < 0.011
                app.PlusxButton1.Enable = 'off';
            % Si el factor de zoom es inferior al 0.91, permite quitar
            % el zoom
            % (0.91 pq pasa de 1 a 0.9)
            elseif app.zoomxFactor1 < 0.91
                app.MinusxButton1.Enable = 'on';
            end
            
            % Si al hacer zoom con respecto a un cursor, el área ampliada
            % quedase fuera de los límites de la señal, se corrige para
            % que no ocurra esto
            if centro-maxLim*app.zoomxFactor1/2 < 0
                app.UIAxes.XLim = [0,maxLim*app.zoomxFactor1];
            elseif centro+maxLim*app.zoomxFactor1/2 > app.time(end)
                app.UIAxes.XLim = [(app.time(end) - maxLim*app.zoomxFactor1),app.time(end)];
            else
                app.UIAxes.XLim = [centro-maxLim*app.zoomxFactor1/2,centro+maxLim*app.zoomxFactor1/2];
            end
            
            % Si hay un espectrograma, se le aplica también el zoom en el
            % eje de tiempos
            if app.mode == 3
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                app.MinusxButton2.Enable = app.MinusxButton1.Enable;
            elseif app.mode == 4
                app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: MinusxButton1
        function MinusxButton1Pushed(app, event)
        % Al disminuir el zoom en el eje X de UIAxes
            maxLim = app.time(end);
            
            % Si no hay un cursor o una ventana, el zoom se centra con
            % respecto al centro de la señal
            if app.center == 0
                centro = mean(app.UIAxes.XLim);
            % Si no, el zoom se centra con respecto al cursor o el centro
            % de la ventana
            else
                centro = app.center/app.fs;
            end
            
            % Si se muestra más del 9% de la señal, el rango mostrado
            % aumenta en un 10%
            if app.zoomxFactor1 > 0.09
                app.zoomxFactor1 = app.zoomxFactor1 + 0.1;
            % Si no, aumenta en un 1%
            else
                app.zoomxFactor1 = app.zoomxFactor1 + 0.01;
            end
            
            % Cuando el zoom es menor del 91% (100%), no se permite
            % disminuir el zoom
            if app.zoomxFactor1 > 0.91
                app.MinusxButton1.Enable = 'off';
            % Cuando se muestra una región mayor al 1.1% del total, se permite aumentar el
            % zoom
            elseif app.zoomxFactor1 > 0.011
                app.PlusxButton1.Enable = 'on';
            end
            
            % Si, al retirar el zoom centrándolo en el cursor, el resultado
            % quedase fuera de los límites, se corregiría
            if centro-maxLim*app.zoomxFactor1/2 < 0
                centro = maxLim*app.zoomxFactor1/2;
            elseif centro+maxLim*app.zoomxFactor1/2 > app.time(end)
                centro = app.time(end) - maxLim*app.zoomxFactor1/2;
            end
            
            % Se quita zoom
            app.UIAxes.XLim = [centro-maxLim*app.zoomxFactor1/2,centro+maxLim*app.zoomxFactor1/2];
            
            % Si hay un espectrograma, el zoom se aplica también en el eje
            % de tiempos
            if app.mode == 3
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                app.MinusxButton2.Enable = app.MinusxButton1.Enable;
            elseif app.mode == 4
                app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: RightButton1
        function RightButton1Pushed(app, event)
        % Al desplazar el plot de UIAxes hacia la derecha
        
            % Se redondean las cifras en función del orden del zoom
            if app.zoomxFactor1 > 0.09
                roundFactor = 3;
            else
                roundFactor = 4;
            end
            
            % Se desplaza hacia la derecha 1/4 de la zona mostrada
            step = (app.UIAxes.XLim(2) - app.UIAxes.XLim(1))/4;
            yLim = app.UIAxes.YLim;
            
            % Se aplica el desplazamiento. Si se sale de los límites, se
            % corrige
            if round(app.UIAxes.XLim(2) + step,roundFactor) > app.time(end)
                app.UIAxes.XLim = [(app.time(end) - (app.UIAxes.XLim(2) - app.UIAxes.XLim(1))),app.time(end)];
            else
                app.UIAxes.XLim = app.UIAxes.XLim + step;
            end
            
            % Se conserva el zoom vertical (necesario pq a veces no lo hace)
            app.UIAxes.YLim = yLim;
            
            % Si hay un espectrograma, se aplica también el desplazamiento en el
            % eje de tiempos
            if app.mode == 3
                yLim = app.UIAxes2.YLim;
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.UIAxes2.YLim = yLim;
            elseif app.mode == 4
                yLim = app.UIAxes3.YLim;
                app.UIAxes3.XLim = app.UIAxes.XLim;
                app.UIAxes3.YLim = yLim;
            end
        end

        % Button pushed function: LeftButton1
        function LeftButton1Pushed(app, event)
            % Al desplazar el plot de UIAxes hacia la izquierda
            
            % Se redondean las cifras en función del orden del zoom
            if app.zoomxFactor1 > 0.09
                roundFactor = 3;
            else
                roundFactor = 4;
            end
            
            % Se desplaza hacia la izquierda 1/4 de la zona mostrada
            step = (app.UIAxes.XLim(2) - app.UIAxes.XLim(1))/4;
            
            yLim = app.UIAxes.YLim;
            
            % Se aplica el desplazamiento. Si se sale de los l´´imites, se
            % corrige
            if round(app.UIAxes.XLim(1) - step,roundFactor) < 0
                app.UIAxes.XLim = [0, (app.UIAxes.XLim(2) - app.UIAxes.XLim(1))];
            else
                app.UIAxes.XLim = app.UIAxes.XLim - step;
            end
            
            % Se conserva el zoom vertical
            app.UIAxes.YLim = yLim;
            
            % Si hay un espectrograma, se aplica también el desplazamiento
            % en el eje de tiempo
            if app.mode == 3
                yLim = app.UIAxes2.YLim;
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.UIAxes2.YLim = yLim;
            elseif app.mode == 4
                yLim = app.UIAxes3.YLim;
                app.UIAxes3.XLim = app.UIAxes.XLim;
                app.UIAxes3.YLim = yLim;
            end
        end

        % Button pushed function: PlusyButton1
        function PlusyButton1Pushed(app, event)
            % Al aumentar el zoom del eje Y de UIAxes
            
            % El zoom se hace con respecto al centro de la región mostrada
            maxLim = app.yLim1(2) - app.yLim1(1);
            centro = mean(app.UIAxes.YLim);
            
            % El zoom se aumenta a pasos del 10%
            app.zoomyFactor1 = app.zoomyFactor1 - 0.1;
            
            % Si el zoom muestra el 10% del total, no se permite hacer más
            % zoom
            if app.zoomyFactor1 < 0.11
                app.PlusyButton1.Enable = 'off';
            % Si el zoom muestra menos del 91% del total, se permite quitar
            % zoom
            elseif app.zoomyFactor1 < 0.91
                app.MinusyButton1.Enable = 'on';
            end
            
            % Se aplica el zoom
            app.UIAxes.YLim = [centro-maxLim*app.zoomyFactor1/2,centro+maxLim*app.zoomyFactor1/2];
        end

        % Button pushed function: MinusyButton1
        function MinusyButton1Pushed(app, event)
            % Al disminuir el zoom del eje Y de UIAxes
            
            % El zoom se disminuye centrado
            maxLim = app.yLim1(2) - app.yLim1(1);
            centro = mean(app.UIAxes.YLim);
            
            % Se disminuye en pasos del 10%
            app.zoomyFactor1 = app.zoomyFactor1 + 0.1;
            
            % Si el zoom muestra el 100% del total, no se permite quitar
            % zoom
            if app.zoomyFactor1 > 0.91
                app.MinusyButton1.Enable = 'off';
            % Si muestra más de un 11% del total, se permite hacer zoom
            elseif app.zoomyFactor1 > 0.11
                app.PlusyButton1.Enable = 'on';
            end
            
            % Se aplica el zoom
            app.UIAxes.YLim = [centro-maxLim*app.zoomyFactor1/2,centro+maxLim*app.zoomyFactor1/2];
        end

        % Button pushed function: UpButton1
        function UpButton1Pushed(app, event)
            % Al desplazar hacia arriba el plot de UIAxes
            
            % Se desplaza hacia arriba 1/4 de lo que se muestra
            step = (app.UIAxes.YLim(2) - app.UIAxes.YLim(1))/4;
            xLim = app.UIAxes.XLim;
            
            % Si el desplazamiento supera el valor máximo de la señal, se
            % corrige
            if round(app.UIAxes.YLim(2) + step,3) > app.yLim1(2)
                app.UIAxes.YLim = [app.yLim1(2) - (app.UIAxes.YLim(2) - app.UIAxes.YLim(1)),app.yLim1(2)];
            else
                app.UIAxes.YLim = app.UIAxes.YLim + step;
            end
            
            % Se aplica el desplazamiento
            app.UIAxes.XLim = xLim;
        end

        % Button pushed function: DownButton1
        function DownButton1Pushed(app, event)
            % Al desplazazar hacia abajo el plot de UIAxes
            
            % Se desplaza hacia arriba 1/4 de lo que se muestra
            step = (app.UIAxes.YLim(2) - app.UIAxes.YLim(1))/4;
            xLim = app.UIAxes.XLim;
            
            % Si el desplazamiento hacia abajo va más allá del valor mínimo
            % de la señal, se corrige
            if round(app.UIAxes.YLim(1) - step,3) < app.yLim1(1)
                app.UIAxes.YLim = [app.yLim1(1),app.yLim1(1) + (app.UIAxes.YLim(2) - app.UIAxes.YLim(1))];
            else
                app.UIAxes.YLim = app.UIAxes.YLim - step;
            end
            
            % Se aplica el desplazamiento
            app.UIAxes.XLim = xLim;
        end

        % Button pushed function: PlusxButton2
        function PlusxButton2Pushed(app, event)
            % Al ampliar el zoom del eje X de UIAxes 2
            
            switch app.mode
                % En los modos FT y STFT
                case {1,2}
                    maxLim = app.freq(end);
                    
                    % El zoom se amplía centrado en el centro de la vista
                    if app.center2 == 0
                        centro = mean(app.UIAxes2.XLim);
                    % Excepto si hay un cursor colocado. En ese caso, se
                    % centra en el cursor
                    else
                        centro = app.center2;
                    end
                    
                    % Si se muestra más del 11% de la señal, el zoom se
                    % amplía en pasos del 10%
                    if app.zoomxFactor2 > 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.1;
                    % Si no, se amplía en pasos del 1%
                    elseif app.zoomxFactor2 < 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.01;
                    end
                    
                    % Si se muestra menos del 1.1% del total de la señal,
                    % no se permite ampliar más el zoom
                    if app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    % Si se muestra menos del 91% del total de la señal, se
                    % se permite disminuir el zoom
                    elseif app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    end
                    
                    % Si al hacer zoom centrado en un cursor, la región
                    % mostrada rebasa los límites, se corrige
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        app.UIAxes2.XLim = [0, maxLim*app.zoomxFactor2];
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        app.UIAxes2.XLim = [app.freq(end) - maxLim*app.zoomxFactor2,app.freq(end)];
                    else
                        app.UIAxes2.XLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
                    end
                
                % En el modo 3, espectrograma, tiene el mismo efecto que
                % ampliar el eje X en UIAxes, pq va coordinado
                case 3
                    app.PlusxButton1Pushed(app);
                
                % En el modo 4, STFT + espectrograma, se hace zoom en el
                % eje frecuencial (X) del espectro en UIAxes 2, y se coordina con el zoom
                % en el eje Y del espectrograma, en UIAxes3
                case 4
                    maxLim = app.freq(end);
                    
                    % Si hay cursor, el zoom se centra en él. Si no, se
                    % centra en el centro de la señal
                    if app.center2 == 0
                        centro = mean(app.UIAxes2.XLim);
                    else
                        centro = app.center2;
                    end
                    
                    % Si se muestra más del 11% de la señal, el zoom se
                    % hace en pasos del 10%
                    if app.zoomxFactor2 > 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.1;
                    % Si no, en pasos del 1%
                    elseif app.zoomxFactor2 < 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.01;
                    end
                    
                    % Si se muestra menos del 1.1% de la señal, no se
                    % permite ampliar más el zoom
                    if app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    % Si se muestra menos del 91% de la señal, se permite
                    % disminuir el zoom
                    elseif app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    end
                    
                    % Si al hacer zoom centrado en un cursor, la región
                    % mostrada rebasa los límites, se corrige
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        app.UIAxes2.XLim = [0, maxLim*app.zoomxFactor2];
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        app.UIAxes2.XLim = [app.freq(end) - maxLim*app.zoomxFactor2,app.freq(end)];
                    else
                        app.UIAxes2.XLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
                    end
                    
                    % Se coordina el zoom con el eje frecuencial del
                    % espectrograma en UIAxes3
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Button pushed function: MinusxButton2
        function MinusxButton2Pushed(app, event)
            % Al disminuir el zoom en el eje X de UIAxes2
            switch app.mode
                
                % Modo 1 o 2 -> FT o STFT
                case {1,2}
                    maxLim = app.freq(end);
                
                    % Si hay cursor, el zoom se centra en él. Si no, se
                    % centra en el centro de la señal
                    if app.center2 == 0
                        centro = mean(app.UIAxes2.XLim);
                    else
                        centro = app.center2;
                    end
                    
                    % Si la región que se muestra representa más de un 9%
                    % del total, el zoom se disminuye en un porcentaje del
                    % 10%
                    if app.zoomxFactor2 > 0.09
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.1;
                     
                    % Si se muestra menos de un 9%, el zoom se disminuye en
                    % un porcentaje del 1%
                    else
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.01;
                    end
                    
                    % Si se muestra más de un 91% del total, no se permite
                    % disminuir el zoom
                    if app.zoomxFactor2 > 0.91
                        app.MinusxButton2.Enable = 'off';
                        
                    % Si se muestra más de un 1.1%, se permite hacer zoom
                    elseif app.zoomxFactor2 > 0.011
                        app.PlusxButton2.Enable = 'on';
                    end
                    % Si se disminuye el zoom centrándolo en el cursor,
                    % y el rango resultante queda fuera de los límites de
                    % la señal, se corrige
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        centro = maxLim*app.zoomxFactor2/2;
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        centro = app.freq(end) - maxLim*app.zoomxFactor2/2;
                    end
                    
                    % Se aplica la disminución de zoom
                    app.UIAxes2.XLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
                % Modo 3 -> Espectrograma. Mismo efecto que disminuir el
                % zoom en el eje X de UIAxes, porque están coordinados
                case 3
                    app.MinusxButton1Pushed(app);
                % Modo 4 -> STFT + Espectrograma. Se disminuye el zoom en
                % el eje frecuencial del STFT (eje X de UIAxes2) y se
                % aplica también en el eje frecuencial del espectrograma (UIAxes3)
                case 4
                    maxLim = app.freq(end);
                
                    % Si hay cursor, el zoom se centra en él. Si no, se
                    % centra en el centro de la señal
                    if app.center2 == 0
                        centro = mean(app.UIAxes2.XLim);
                    else
                        centro = app.center2;
                    end
                    
                    % Si se muestra más de un 9% de la señal, el zoom va en
                    % pasos del 10%
                    if app.zoomxFactor2 > 0.09
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.1;
                    % Si se muestra menos, en pasos del 1%
                    else
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.01;
                    end
                    
                    % Si se muestra más del 91% de la señal, no se permite
                    % disminuir más el zoom
                    if app.zoomxFactor2 > 0.91
                        app.MinusxButton2.Enable = 'off';
                    % Si se muestra más del 1.1% de la señal, se permite
                    % hacer zoom
                    elseif app.zoomxFactor2 > 0.011
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    % Si se hace zoom centrado en el cursor y el rango
                    % resultante queda fuera de los límites del espectro,
                    % se corrige
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        centro = maxLim*app.zoomxFactor2/2;
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        centro = app.freq(end) - maxLim*app.zoomxFactor2/2;
                    end
                    
                    % Se aplica el zoom, y se coordina con UIAxes3
                    app.UIAxes2.XLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Button pushed function: PlusyButton2
        function PlusyButton2Pushed(app, event)
            % Al aumentar el zoom en el eje Y de UIAxes3
            
            switch app.mode
                % En los modos FT, STFT y STFT + Espectrograma
                case {1,2,4}
                    % Límite máximo: rango dinámico del espectro
                    maxLim = app.yLim2(2) - app.yLim2(1);
                    centro = mean(app.UIAxes2.YLim);
                    
                    % El zoom va en pasos del 10%
                    app.zoomyFactor2 = app.zoomyFactor2 - 0.1;
                    
                    % Si se muestra menos de un 11% del rango total, no se
                    % permite aumentar más el zoom
                    if app.zoomyFactor2 < 0.11
                        app.PlusyButton2.Enable = 'off';
                    % Si se muestra menos de un 91% del rango total, se
                    % permite hacer zoom
                    elseif app.zoomyFactor2 < 0.91
                        app.MinusyButton2.Enable = 'on';
                    end
                    
                    % Se aplica el zoom
                    app.UIAxes2.YLim = [centro-maxLim*app.zoomyFactor2/2,centro+maxLim*app.zoomyFactor2/2];
                    
                % Modo 3 -> Espectrograma: Eje Y = Eje frecuencial
                case 3
                    maxLim = app.freq(end);
                    centro = mean(app.UIAxes2.YLim);
                    
                    % Si se muestra más del 11% del total, el zoom va en
                    % pasos del 10%
                    if app.zoomxFactor2 > 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.1;
                    % Si se muestra menos del 11%, el zoom va en pasos del
                    % 1%
                    elseif app.zoomxFactor2 < 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.01;
                    end
                    
                    % Si se muestra menos del 11% del total, no se permite
                    % ampliar más el zoom
                    if app.zoomxFactor2 < 0.011
                        app.PlusyButton2.Enable = 'off';
                    % Si se muestra menos del 91% del total, se permite
                    % disminuir el zoom
                    elseif app.zoomxFactor2 < 0.91
                        app.MinusyButton2.Enable = 'on';
                    end
                    
                    % Se aplica el zoom
                    app.UIAxes2.YLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
            end
        end

        % Button pushed function: MinusyButton2
        function MinusyButton2Pushed(app, event)
            % Al disminuir el zoom en el eje Y de UIAxes2
            
            switch app.mode
                % Modos 1,2 y 4 -> FT, STFT y STFT + espectrograma
                case {1,2,4}
                    % Rango máximo: Rango dinámico del espectro
                    maxLim = app.yLim2(2) - app.yLim2(1);
                    centro = mean(app.UIAxes2.YLim);
                    
                    % El zoom va en pasos del 10%
                    app.zoomyFactor2 = app.zoomyFactor2 + 0.1;
                    
                    % Si se muestra más del 91% del total, no se permite
                    % disminuir más el zoom
                    if app.zoomyFactor2 > 0.91
                        app.MinusyButton2.Enable = 'off';
                    % Si se muestra más del 11% del total, se permite hacer
                    % zoom
                    elseif app.zoomyFactor2 > 0.11
                        app.PlusyButton2.Enable = 'on';
                    end
                    
                    % Se aplica el zoom
                    app.UIAxes2.YLim = [centro-maxLim*app.zoomyFactor2/2,centro+maxLim*app.zoomyFactor2/2];
                % Modo espectrograma. Eje Y = Eje frecuencial
                case 3
                    maxLim = app.freq(end);
                    centro = mean(app.UIAxes2.YLim);
                    
                    % Si se muestra más del 9% del total, el zoom va en
                    % pasos del 10%
                    if app.zoomxFactor2 > 0.09
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.1;
                    % Si no, va en pasos del 1%
                    else
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.01;
                    end
                    
                    % Si se muestra más del 91% del total, no se permite
                    % disminuir más el zoom
                    if app.zoomxFactor2 > 0.91
                        app.MinusyButton2.Enable = 'off';
                    % Si se muestra más del 1.1% del total, se permite
                    % hacer zoom
                    elseif app.zoomxFactor2 > 0.011
                        app.PlusyButton2.Enable = 'on';
                    end
                    
                    % Si el rangoo mostrado tras hacer zoom queda fuera de
                    % los límites de la señal, se corrige
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        centro = maxLim*app.zoomxFactor2/2;
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        centro = app.freq(end) - maxLim*app.zoomxFactor2/2;
                    end
                    
                    % Se aplica el zoom
                    app.UIAxes2.YLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
            end
        end

        % Button pushed function: ResetButton2
        function ResetButton2Pushed(app, event)
            % Restaurar el zoom de UIAxes2
            
            switch app.mode
                % En los modos FT y STFT
                case {1,2}
                    % Se restaura el rango de frecuencias calculado en
                    % updatePlot()
                    app.UIAxes2.XLim = app.xLim2;
                    
                    % En el eje Y se muestra desde 0 hasta el máximo de la
                    % señal
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    
                    % En el eje Y, el zoom está al mínimo (100%)
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    % Se calcula qué porcentaje representa el rango de
                    % frecuencias mostrado en el eje X con respecto del
                    % total
                    app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
                    % Si es menor al 1.1%, no se permite ampliar el zoom
                    if  app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    else
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    % Si es mayor al 91%, no se permite disminuir el zoom
                    if app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    else
                        app.MinusxButton2.Enable = 'off';
                    end 
                % Modo 3: Espectrograma
                case 3
                    % El rango frecuencial que se muestra (en el eje Y) es
                    % el calculado en updatePlot()
                    app.UIAxes2.YLim = app.xLim2;
                    
                    % El zoom en el eje temporal se hace coincidir con el
                    % de UIAxes
                    app.UIAxes2.XLim = app.UIAxes.XLim;
                    
                    app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                    app.MinusxButton2.Enable = app.MinusxButton1.Enable;
                    
                    % Se calcula qué porcentaje representa el rango
                    % frecuencial mostrado con respecto del total
                    app.zoomxFactor2 = (app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1))/app.freq(end);
                    
                    % Si es menor del 1.1%, no se permite ampliar más el
                    % zoom
                    if  app.zoomxFactor2 < 0.011
                        app.PlusyButton2.Enable = 'off';
                    else
                        app.PlusyButton2.Enable = 'on';
                    end
                    
                    % Si es mayor del 91%, no se permite disminuir el zoom
                    if app.zoomxFactor2 < 0.91
                        app.MinusyButton2.Enable = 'on';
                    else
                        app.MinusyButton2.Enable = 'off';
                    end
                    
                % Modo STFT + espectrograma: Los ejes frecuenciales se
                % coordinan
                case 4
                    % Se restablece el rango frecuencial calculado en
                    % updatePlot()
                    app.UIAxes2.XLim = app.xLim2;
                    
                    % Se muestra el rango de amplitud completo del espectro
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.xLim2 = app.UIAxes2.XLim;

                    % En el eje Y de UIAxes2, el zoom está al mínimo (100%)
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    % Se calcúla que porcentaje representa el rango de
                    % frecuencias mostrado con respecto al total
                    app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
                    % Si es menor del 1.1%, no se permite ampliar más el
                    % zoom
                    if  app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    else
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    % Si es mayor del 91%, no se permite disminuir el zoom
                    if app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    else
                        app.MinusxButton2.Enable = 'off';
                    end
                    
                    % El eje de tiempos del espectrograma se coordina con el
                    % de forma de onda
                    app.UIAxes3.XLim = app.UIAxes.XLim;
                    % El eje frecuencial del espectrograma se coordina con
                    % el del STFT
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Button pushed function: RightButton2
        function RightButton2Pushed(app, event)
            % Desplazar el plot de UIAxes a la derecha
            switch app.mode
                % Modo FT o STFT
                case {1,2}
                    % Se desplaza a la derecha 1/4 de la región mostrada
                    step = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/4;
                                        
                    yLim = app.UIAxes2.YLim;
                    
                    % Si al desplazar se exceden los límites del espectro,
                    % se corrige
                    if round(app.UIAxes2.XLim(2) + step) > app.freq(end)
                        app.UIAxes2.XLim = [(app.freq(end) - (app.UIAxes2.XLim(2) - app.UIAxes2.XLim(1))),app.freq(end)];
                    else
                        app.UIAxes2.XLim = app.UIAxes2.XLim + step;
                    end
                    
                    % Se coonserva el zoom en el eje Y
                    app.UIAxes2.YLim = yLim;
                % Modo espectrograma -> Es equivalente a desplazar a la
                % derecha el plot de UIAxes, porque van coordinados
                case 3
                    app.RightButton1Pushed(app);
                % Modo STFT + espectrograma: ejes frecuenciales coordinados
                case 4
                    % Se desplaza a la derecha 1/4 de la región total que
                    % se muestra
                    step = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/4;
                    
                    yLim = app.UIAxes2.YLim;
                    
                    % Si al moverlo se exceden los límites del espectro, se
                    % corrige
                    if round(app.UIAxes2.XLim(2) + step) > app.freq(end)
                        app.UIAxes2.XLim = [(app.freq(end) - (app.UIAxes2.XLim(2) - app.UIAxes2.XLim(1))),app.freq(end)];
                    else
                        app.UIAxes2.XLim = app.UIAxes2.XLim + step;
                    end
                    
                    % Se mantiene el zoom en el eje Y
                    app.UIAxes2.YLim = yLim;
                    
                    % Eje X de UIAxes3, coordinado con eje X de UIAxes (tiempo)
                    % Eje Y de UIAxes3, coordinado con eje Y de UIAxes2 (frecuencia)
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
                    app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: LeftButton2
        function LeftButton2Pushed(app, event)
            % Se desplaza a la izquierda UIAxes2
            switch app.mode
                % Modos FT y STFT
                case {1,2}
                    % Se desplaza a la izquierda 1/4 del rango total
                    % mostrado
                    step = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/4;
                    
                    yLim = app.UIAxes2.YLim;
                    
                    % Si al desplazarse queda fuera del rango del espectro,
                    % se corrige
                    if round(app.UIAxes2.XLim(1) - step) < 0
                        app.UIAxes2.XLim = [0 (app.UIAxes2.XLim(2) - app.UIAxes2.XLim(1))];
                    else
                        app.UIAxes2.XLim = app.UIAxes2.XLim - step;
                    end
                    
                    % Se conserva el zoom en el eje Y
                    app.UIAxes2.YLim = yLim;
                % Modo espectrograma: Mismo efecto que desplazar a la
                % izquierdad UIAxes. Van coordinados
                case 3
                    app.LeftButton1Pushed(app);
                % Modo STFT + espectrograma: eje frecuencial coordinado
                case 4
                    % Se desplaza a la izquierda 1/4 del rango mostrado
                    step = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/4;
                    
                    yLim = app.UIAxes2.YLim;
                    
                    % Si al desplazar se exceden los límites del espectro,
                    % se corrige
                    if round(app.UIAxes2.XLim(1) - step) < 0
                        app.UIAxes2.XLim = [0 (app.UIAxes2.XLim(2) - app.UIAxes2.XLim(1))];
                    else
                        app.UIAxes2.XLim = app.UIAxes2.XLim - step;
                    end
                    
                    % Se mantiene el zoom en el eje Y
                    app.UIAxes2.YLim = yLim;
                    
                    % Eje Y de UIAxes3 coordinado con eje X de UIAxes2 (frecuencia)
                    % Eje X de UIAxes3 coordinado con eje X de UIAxes (tiempo)
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
                    app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: UpButton2
        function UpButton2Pushed(app, event)
            % Al desplazar hacia arriba el plot de UIAxes2
            
            switch app.mode
                % ModoFT, STFT y STFT + espectrograma
                case {1,2,4}
                    % Se desplaza 1/4 del rango mostrado
                    step = (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))/4;
                    xLim = app.UIAxes2.XLim;
                    
                    % Si al desplazarse sobrepasa el máximo de la señal, se
                    % corrige
                    if round(app.UIAxes2.YLim(2) + step,3) > app.yLim2(2)
                        app.UIAxes2.YLim = [app.yLim2(2) - (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1)),app.yLim2(2)];
                    else
                        app.UIAxes2.YLim = app.UIAxes2.YLim + step;
                    end
                    
                    % Se mantiene el zoom en el eje X
                    app.UIAxes2.XLim = xLim;
                % Modo espectrograma (eje frecuencial)
                case 3
                    % Se desplaza 1/4 de la región mostrada
                    step = (app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1))/4;
                    
                    xLim = app.UIAxes2.XLim;
                    
                    % Si al desplazarse excede el límite de frecuencia
                    % máximo, se corrige
                    if round(app.UIAxes2.YLim(2) + step) > app.freq(end)
                        app.UIAxes2.YLim = [(app.freq(end) - (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))),app.freq(end)];
                    else
                        app.UIAxes2.YLim = app.UIAxes2.YLim + step;
                    end
                    
                    % Se mantiene el zoom en el eje X
                    app.UIAxes2.XLim = xLim;                                    
            end
        end

        % Button pushed function: DownButton2
        function DownButton2Pushed(app, event)
            % Desplazar hacia abajo el plot de UIAxes2
            
            switch app.mode
                % Modo FT, STFT y STFT + espectrograma
                case {1,2,4}
                    % Se desplaza hacia abajo 1/4 de la región mostrada
                    step = (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))/4;
                    xLim = app.UIAxes2.XLim;
                    
                    % Si al moverse excede el calor mínimo del espectro
                    % (0), se corrige
                    if round(app.UIAxes2.YLim(1) - step,3) < 0
                        app.UIAxes2.YLim = [0,(app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))];
                    else
                        app.UIAxes2.YLim = app.UIAxes2.YLim - step;
                    end
                    
                    % Se mantiene el zoom en el eje X
                    app.UIAxes2.XLim = xLim;
                % Modo espectrograma (eje frecuencial)
                case 3
                    % Se desplaza 1/4 de la región total mostrada
                    step = (app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1))/4;
                    
                    xLim = app.UIAxes2.XLim;
                    
                    % Si al desplazarse excede el 0, se corrige
                    if round(app.UIAxes2.YLim(1) - step) < 0
                        app.UIAxes2.YLim = [0 (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))];
                    else
                        app.UIAxes2.YLim = app.UIAxes2.YLim - step;
                    end
                    
                    % Se mantiene el zoom del eje X
                    app.UIAxes2.XLim = xLim;                    
            end
        end

        % Button pushed function: playSegmentButton
        function playSegmentButtonPushed(app, event)
            % Reproduce el sonido a partir del segmento colocado o del
            % comienzo del segmento enventanado
            
            play(app.player,app.startPlaying)
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            % Abre o actualiza la ventana de ayuda
            
            app.CallingApp.createHelp(9); % 9 = Visualization
        end

        % Button pushed function: exportButton1
        function exportButton1Pushed(app, event)
            % Exportar datos de señal
            [file,path] = uiputfile({'*.wav';'*.csv';'*.xls'},app.export_signal);
            if ~(isequal(file,0) || isequal(path,0))  % Si el usuario no cancela
                
                if contains(file,'.wav') % Si es un fichero .wav
                    signalAux = detrend(app.signal); % Elimina el offset, si lo tiene
                    
                    if(max(abs(signalAux)) > 1) % Normaliza la seña l si su amplitud es > 1
                        signalAux = signalAux./max(abs(signalAux));
                    end
                    
                    audiowrite(fullfile(path,file),signalAux,app.fs); % Escribe fichero wav
                    
                else % Si es un fichero .csv o .xls
                    writematrix(app.signal,fullfile(path,file));
                end
            end
        end

        % Button pushed function: exportButton2
        function exportButton2Pushed(app, event)
            % Exportar datos de espectro
            [file,path] = uiputfile({'*.csv';'*.xls'},app.export_spectrum);
            if ~(isequal(file,0) || isequal(path,0))  % Si el usuario no cancela
                writematrix(app.signal,fullfile(path,file))
            end
        end

        % Button pushed function: restore_layout2
        function restore_layout2ButtonPushed(app, event)
            % Recompone la vista de 2 plot
            
            % Cambiar tamaño de ambos ejes
            app.UIAxes.Position = app.axes1_layout2;
            app.UIAxes2.Position = app.axes2_layout2;
            
            % Hacer los dos ejes visibles
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            
            % Hacer ambos paneles de control visibles
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'on';
        end

        % Button pushed function: up_layout2
        function up_layout2ButtonPushed(app, event)
            % En la vista de 2 plot, amplía el plot superior
            
            % Agrandar el eje 1 y esconder el eje 2
            app.UIAxes.Position = app.mainPlot_size;
            app.UIAxes2.Position(3:4) = [0 0];
            
            % Hacer visible el eje 1 y esconder el eje 2
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'off';
            
            % Hacer visible el panel de control 1 y esconder el 2
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'off';
        end

        % Button pushed function: down_layout2
        function down_layout2ButtonPushed(app, event)
            % En la vista de 2 plot, amplía el plot inferior
            
            % Agrandar el eje 2 y esconder el eje 1
            app.UIAxes.Position(3:4) = [0 0];
            app.UIAxes2.Position = app.mainPlot_size;
            
            % Hacer visible el eje 2 y esconder el eje 1
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'on';
            
            % Hacer visible el panel de control 2 y esconder el 1
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'on';
        end

        % Button pushed function: restore_layout3
        function restore_layout3ButtonPushed(app, event)
            % Restaura la vista de 3 plot
            
            % Restaurar el tamaño de los 3 ejes
            app.UIAxes.Position = app.axes1_layout3;
            app.UIAxes2.Position = app.axes2_layout3;
            app.UIAxes3.Position = app.axes3_layout3;
            
            % Hacer visibles los tres ejes
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'on';
            
            % Hacer visibles los dos paneles de control
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'on';
        end

        % Button pushed function: up_layout3
        function up_layout3ButtonPushed(app, event)
            % En la vista de 3 plot, amplía el plot superior
            
            % Agrandar el eje 1 y esconder los otros dos
            app.UIAxes.Position = app.mainPlot_size;
            app.UIAxes2.Position(3:4) = [0 0];
            app.UIAxes3.Position(3:4) = [0 0];
            
            % Hacer visible el eje 1 y esconder los otros dos
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            
            % Hacer visible panel 1 y esconder el 2
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'off';
        end

        % Button pushed function: middle_layout3
        function middle_layout3ButtonPushed(app, event)
            % En la vista de 3 plot, amplía el plot central
            
            % Agrandar el eje 2 y esconder los otros dos
            app.UIAxes.Position(3:4) = [0 0];
            app.UIAxes2.Position = app.mainPlot_size;
            app.UIAxes3.Position(3:4) = [0 0];
            
            % Hacer visible el eje 2 y esconder los otros dos
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            
            % Mostrar panel de control 2 y ocultar el 1
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'on';
        end

        % Button pushed function: down_layout3
        function down_layout3ButtonPushed(app, event)
            % En la vista de 3 plot, amplía el plot inferior
            
            % Agrandar el eje 3 y esconder los otros dos
            app.UIAxes.Position(3:4) = [0 0];
            app.UIAxes2.Position(3:4) = [0 0];
            app.UIAxes3.Position = app.mainPlot_size;
            
            % Hacer visible el eje 3 y ocultar el otro
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'off';
        end

        % Callback function
        function LinealMenuSelected(app, event)
            app.UIAxes.YScale = 'linear';
        end

        % Callback function
        function LogartmicaMenuSelected(app, event)
            app.UIAxes.YScale = 'log';
        end

        % Menu selected function: LinealMenu_2
        function LinealMenu_2Selected(app, event)
            % Al seleccionar escala lineal para UIAxes2
            
            app.yScale2 = 'linear';
            
            % Modo espectrograma -> Recalcula espectrograma en modo
            % amplitud lineal
            if app.mode == 3
                calculateSpectrogram(app);
            % En otro modo, simplemente cambia la escala del eje Y
            else
                app.UIAxes2.YScale = 'linear';
                app.amp_spectrum_label = app.amp_label;
                app.UIAxes2.YLabel.String = app.amp_spectrum_label;
            end
        end

        % Menu selected function: LogartmicaMenu_2
        function LogartmicaMenu_2Selected(app, event)
            % Al seleccionar escala logarítmica para UIAxes2
            
            app.yScale2 = 'log';
            app.amp_spectrum_label = strcat(app.amp_label,' (dB)');
            
            % Modo espectrograma -> Recalcula el espectrograma en modo
            % amplitud logarítmica
            if app.mode == 3
                calculateSpectrogram(app);
            % En otro modo, simplemente cambia la escala del eje Y
            else
                app.UIAxes2.YScale = 'log';                
                app.UIAxes2.YLabel.String = app.amp_spectrum_label;
            end
        end

        % Menu selected function: GuardarcomoMenu
        function GuardarcomoMenuSelected(app, event)
            % Exportar plot de UIAxes como imagen
            matlab.graphics.internal.export.exportCallback(matlab.graphics.controls.internal.exportHelper(app.UIAxes))
        end

        % Menu selected function: CopiarcomomapadebitsMenu
        function CopiarcomomapadebitsMenuSelected(app, event)
            % Copiar plot de UIAxes como mapa de bits
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes),'target','clipboard','format','image')
        end

        % Menu selected function: CopiarcomoimagenvectorialMenu
        function CopiarcomoimagenvectorialMenuSelected(app, event)
            % Copiar plot de UIAxes como imagen vectorial
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes),'target','clipboard','format','vector','vector',true)
        end

        % Menu selected function: GuardarcomoMenu_2
        function GuardarcomoMenu_2Selected(app, event)
            % Exportar plot de UIAxes2 como imagen
            matlab.graphics.internal.export.exportCallback(matlab.graphics.controls.internal.exportHelper(app.UIAxes2))
        end

        % Menu selected function: CopiarcomomapadebitsMenu_2
        function CopiarcomomapadebitsMenu_2Selected(app, event)
            % Copiar plot de UIAxes2 como mapa de bits
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes2),'target','clipboard','format','image')
        end

        % Menu selected function: CopiarcomoimagenvectorialMenu_2
        function CopiarcomoimagenvectorialMenu_2Selected(app, event)
            % Copiar plot de UIAxes2 como imagen vectorial
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes2),'target','clipboard','format','vector','vector',true)
        end

        % Menu selected function: GuardarcomoMenu_3
        function GuardarcomoMenu_3Selected(app, event)
            % Exportar plot de UIAxes3 como imagen
            matlab.graphics.internal.export.exportCallback(matlab.graphics.controls.internal.exportHelper(app.UIAxes3))
        end

        % Menu selected function: CopiarcomomapadebitsMenu_3
        function CopiarcomomapadebitsMenu_3Selected(app, event)
            % Copiar plot de UIAxes3 como mapa de bits
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes3),'target','clipboard','format','image')
        end

        % Menu selected function: CopiarcomoimagenvectorialMenu_3
        function CopiarcomoimagenvectorialMenu_3Selected(app, event)
            % Copiar plot de UIAxes3 como imagen vectorial
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes3),'target','clipboard','format','vector','vector',true)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.VentanaDropDown = uidropdown(app.UIFigure);
            app.VentanaDropDown.Items = {'Rectangular', 'Hanning', 'Hamming', 'Gaussiana', 'Triangular', 'Chebyshev', 'Taylor', 'Bartlett', 'Kaiser'};
            app.VentanaDropDown.ValueChangedFcn = createCallbackFcn(app, @VentanaDropDownValueChanged, true);
            app.VentanaDropDown.Enable = 'off';
            app.VentanaDropDown.Visible = 'off';
            app.VentanaDropDown.Value = 'Rectangular';
            app.nfftDropDown = uidropdown(app.UIFigure);
            app.nfftDropDown.ValueChangedFcn = createCallbackFcn(app, @nfftDropDownValueChanged, true);
            app.nfftDropDown.Enable = 'off';
            app.nfftDropDown.Visible = 'off';
            app.TamaodeventanaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TamaodeventanaEditField.ValueChangedFcn = createCallbackFcn(app, @TamaodeventanaEditFieldValueChanged, true);
            app.TamaodeventanaEditField.Enable = 'off';
            app.TamaodeventanaEditField.Visible = 'off';
            app.TamaodeventanaEditField.Value = 10001;
            app.VentanaLabel = uilabel(app.UIFigure);
            app.VentanaLabel.Visible = 'off';
            app.VentanaLabel.Text = 'Ventana';
            app.TamaodeventanaLabel = uilabel(app.UIFigure);
            app.TamaodeventanaLabel.Visible = 'off';
            app.TamaodeventanaLabel.Text = 'Tamaño de ventana';
            app.nfftLabel = uilabel(app.UIFigure);
            app.nfftLabel.Visible = 'off';
            app.nfftLabel.Text = 'nfft';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'FT', 'STFT', 'Espectrograma', 'STFT + Espect.'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'FT';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Forma de onda')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Espectro')
            xlabel(app.UIAxes2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes2, 'Amplitud')
            app.UIAxes2.YScale = 'log';
            app.Panel = uipanel(app.UIFigure);
            app.PlusxButton1 = uibutton(app.Panel, 'push');
            app.PlusxButton1.ButtonPushedFcn = createCallbackFcn(app, @PlusxButton1Pushed, true);
            app.PlusxButton1.Icon = 'zoomright.png';
            app.PlusxButton1.Text = '';
            app.MinusxButton1 = uibutton(app.Panel, 'push');
            app.MinusxButton1.ButtonPushedFcn = createCallbackFcn(app, @MinusxButton1Pushed, true);
            app.MinusxButton1.Icon = 'zoomleft.png';
            app.MinusxButton1.Enable = 'off';
            app.MinusxButton1.Text = '';
            app.PlusyButton1 = uibutton(app.Panel, 'push');
            app.PlusyButton1.ButtonPushedFcn = createCallbackFcn(app, @PlusyButton1Pushed, true);
            app.PlusyButton1.Icon = 'zoomup.png';
            app.PlusyButton1.Text = '';
            app.MinusyButton1 = uibutton(app.Panel, 'push');
            app.MinusyButton1.ButtonPushedFcn = createCallbackFcn(app, @MinusyButton1Pushed, true);
            app.MinusyButton1.Icon = 'zoomdown.png';
            app.MinusyButton1.Enable = 'off';
            app.MinusyButton1.Text = '';
            app.UpButton1 = uibutton(app.Panel, 'push');
            app.UpButton1.ButtonPushedFcn = createCallbackFcn(app, @UpButton1Pushed, true);
            app.UpButton1.Icon = 'up.png';
            app.UpButton1.Text = '';
            app.DownButton1 = uibutton(app.Panel, 'push');
            app.DownButton1.ButtonPushedFcn = createCallbackFcn(app, @DownButton1Pushed, true);
            app.DownButton1.Icon = 'down.png';
            app.DownButton1.Text = '';
            app.RightButton1 = uibutton(app.Panel, 'push');
            app.RightButton1.ButtonPushedFcn = createCallbackFcn(app, @RightButton1Pushed, true);
            app.RightButton1.Icon = 'right.png';
            app.RightButton1.Text = '';
            app.LeftButton1 = uibutton(app.Panel, 'push');
            app.LeftButton1.ButtonPushedFcn = createCallbackFcn(app, @LeftButton1Pushed, true);
            app.LeftButton1.Icon = 'left.png';
            app.LeftButton1.Text = '';
            app.ResetButton1 = uibutton(app.Panel, 'push');
            app.ResetButton1.ButtonPushedFcn = createCallbackFcn(app, @ResetButton1Pushed, true);
            app.ResetButton1.Icon = 'zoomcenter.png';
            app.ResetButton1.Text = '';
            app.exportButton1 = uibutton(app.Panel, 'push');
            app.exportButton1.ButtonPushedFcn = createCallbackFcn(app, @exportButton1Pushed, true);
            app.exportButton1.Icon = 'export.png';
            app.exportButton1.Text = '';
            app.Panel_2 = uipanel(app.UIFigure);
            app.PlusxButton2 = uibutton(app.Panel_2, 'push');
            app.PlusxButton2.ButtonPushedFcn = createCallbackFcn(app, @PlusxButton2Pushed, true);
            app.PlusxButton2.Icon = 'zoomright.png';
            app.PlusxButton2.Text = '';
            app.MinusxButton2 = uibutton(app.Panel_2, 'push');
            app.MinusxButton2.ButtonPushedFcn = createCallbackFcn(app, @MinusxButton2Pushed, true);
            app.MinusxButton2.Icon = 'zoomleft.png';
            app.MinusxButton2.Enable = 'off';
            app.MinusxButton2.Text = '';
            app.PlusyButton2 = uibutton(app.Panel_2, 'push');
            app.PlusyButton2.ButtonPushedFcn = createCallbackFcn(app, @PlusyButton2Pushed, true);
            app.PlusyButton2.Icon = 'zoomup.png';
            app.PlusyButton2.Text = '';
            app.MinusyButton2 = uibutton(app.Panel_2, 'push');
            app.MinusyButton2.ButtonPushedFcn = createCallbackFcn(app, @MinusyButton2Pushed, true);
            app.MinusyButton2.Icon = 'zoomdown.png';
            app.MinusyButton2.Enable = 'off';
            app.MinusyButton2.Text = '';
            app.UpButton2 = uibutton(app.Panel_2, 'push');
            app.UpButton2.ButtonPushedFcn = createCallbackFcn(app, @UpButton2Pushed, true);
            app.UpButton2.Icon = 'up.png';
            app.UpButton2.Text = '';
            app.DownButton2 = uibutton(app.Panel_2, 'push');
            app.DownButton2.ButtonPushedFcn = createCallbackFcn(app, @DownButton2Pushed, true);
            app.DownButton2.Icon = 'down.png';
            app.DownButton2.Text = '';
            app.RightButton2 = uibutton(app.Panel_2, 'push');
            app.RightButton2.ButtonPushedFcn = createCallbackFcn(app, @RightButton2Pushed, true);
            app.RightButton2.Icon = 'right.png';
            app.RightButton2.Text = '';
            app.LeftButton2 = uibutton(app.Panel_2, 'push');
            app.LeftButton2.ButtonPushedFcn = createCallbackFcn(app, @LeftButton2Pushed, true);
            app.LeftButton2.Icon = 'left.png';
            app.LeftButton2.Text = '';
            app.ResetButton2 = uibutton(app.Panel_2, 'push');
            app.ResetButton2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton2Pushed, true);
            app.ResetButton2.Icon = 'zoomcenter.png';
            app.ResetButton2.Text = '';
            app.exportButton2 = uibutton(app.Panel_2, 'push');
            app.exportButton2.ButtonPushedFcn = createCallbackFcn(app, @exportButton2Pushed, true);
            app.exportButton2.Icon = 'export.png';
            app.exportButton2.Text = '';
            app.infoButton = uibutton(app.UIFigure, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Icon = 'info.png';
            app.infoButton.Text = '';
            app.Panel_Reproduccion = uipanel(app.UIFigure);
            app.stopButton = uibutton(app.Panel_Reproduccion, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop_button.png';
            app.stopButton.Enable = 'off';
            app.stopButton.Text = '';
            app.playSegmentButton = uibutton(app.Panel_Reproduccion, 'push');
            app.playSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @playSegmentButtonPushed, true);
            app.playSegmentButton.Icon = 'playSegment_button.png';
            app.playSegmentButton.Enable = 'off';
            app.playSegmentButton.Text = {''; ''};
            app.playButton = uibutton(app.Panel_Reproduccion, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play_button.png';
            app.playButton.Enable = 'off';
            app.playButton.Text = {''; ''};
            app.Panel_layout_3 = uipanel(app.UIFigure);
            app.Panel_layout_3.Visible = 'off';
            app.middle_layout3 = uibutton(app.Panel_layout_3, 'push');
            app.middle_layout3.ButtonPushedFcn = createCallbackFcn(app, @middle_layout3ButtonPushed, true);
            app.middle_layout3.Icon = 'medium_3_layout.png';
            app.middle_layout3.Text = '';
            app.up_layout3 = uibutton(app.Panel_layout_3, 'push');
            app.up_layout3.ButtonPushedFcn = createCallbackFcn(app, @up_layout3ButtonPushed, true);
            app.up_layout3.Icon = 'up_3_layout.png';
            app.up_layout3.Text = {''; ''};
            app.restore_layout3 = uibutton(app.Panel_layout_3, 'push');
            app.restore_layout3.ButtonPushedFcn = createCallbackFcn(app, @restore_layout3ButtonPushed, true);
            app.restore_layout3.Icon = 'restore_3_layout.png';
            app.restore_layout3.Text = {''; ''};
            app.down_layout3 = uibutton(app.Panel_layout_3, 'push');
            app.down_layout3.ButtonPushedFcn = createCallbackFcn(app, @down_layout3ButtonPushed, true);
            app.down_layout3.Icon = 'down_3_layout.png';
            app.down_layout3.Text = '';
            app.Panel_layout_2 = uipanel(app.UIFigure);
            app.down_layout2 = uibutton(app.Panel_layout_2, 'push');
            app.down_layout2.ButtonPushedFcn = createCallbackFcn(app, @down_layout2ButtonPushed, true);
            app.down_layout2.Icon = 'down_2_layout.png';
            app.down_layout2.Text = '';
            app.up_layout2 = uibutton(app.Panel_layout_2, 'push');
            app.up_layout2.ButtonPushedFcn = createCallbackFcn(app, @up_layout2ButtonPushed, true);
            app.up_layout2.Icon = 'up_2_layout.png';
            app.up_layout2.Text = {''; ''};
            app.restore_layout2 = uibutton(app.Panel_layout_2, 'push');
            app.restore_layout2.ButtonPushedFcn = createCallbackFcn(app, @restore_layout2ButtonPushed, true);
            app.restore_layout2.Icon = 'restore_2_layout.png';
            app.restore_layout2.Text = {''; ''};
            app.fsField = uieditfield(app.UIFigure, 'numeric');
            app.fsField.ValueDisplayFormat = '%.0f';
            app.fsField.Editable = 'off';
            app.fsLabel = uilabel(app.UIFigure);
            app.fsLabel.Text = 'fs:';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.ExportarimagenMenu_UIAxes = uimenu(app.ContextMenu);
            app.ExportarimagenMenu_UIAxes.Text = 'Exportar imagen';
            app.GuardarcomoMenu = uimenu(app.ExportarimagenMenu_UIAxes);
            app.GuardarcomoMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarcomoMenuSelected, true);
            app.GuardarcomoMenu.Text = 'Guardar como...';
            app.CopiarcomomapadebitsMenu = uimenu(app.ExportarimagenMenu_UIAxes);
            app.CopiarcomomapadebitsMenu.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomomapadebitsMenuSelected, true);
            app.CopiarcomomapadebitsMenu.Text = 'Copiar como mapa de bits';
            app.CopiarcomoimagenvectorialMenu = uimenu(app.ExportarimagenMenu_UIAxes);
            app.CopiarcomoimagenvectorialMenu.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomoimagenvectorialMenuSelected, true);
            app.CopiarcomoimagenvectorialMenu.Text = 'Copiar como imagen vectorial';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu2
            app.UIAxes2.ContextMenu = app.ContextMenu2;
            app.EscaladelejeYMenu_UIAxes2 = uimenu(app.ContextMenu2);
            app.EscaladelejeYMenu_UIAxes2.Text = 'Escala del eje Y';
            app.LinealMenu_2 = uimenu(app.EscaladelejeYMenu_UIAxes2);
            app.LinealMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_2Selected, true);
            app.LinealMenu_2.Text = 'Lineal';
            app.LogartmicaMenu_2 = uimenu(app.EscaladelejeYMenu_UIAxes2);
            app.LogartmicaMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LogartmicaMenu_2Selected, true);
            app.LogartmicaMenu_2.Text = 'Logarítmica';
            app.ExportarimagenMenu_UIAxes2 = uimenu(app.ContextMenu2);
            app.ExportarimagenMenu_UIAxes2.Text = 'Exportar imagen';
            app.GuardarcomoMenu_2 = uimenu(app.ExportarimagenMenu_UIAxes2);
            app.GuardarcomoMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GuardarcomoMenu_2Selected, true);
            app.GuardarcomoMenu_2.Text = 'Guardar como...';
            app.CopiarcomomapadebitsMenu_2 = uimenu(app.ExportarimagenMenu_UIAxes2);
            app.CopiarcomomapadebitsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomomapadebitsMenu_2Selected, true);
            app.CopiarcomomapadebitsMenu_2.Text = 'Copiar como mapa de bits';
            app.CopiarcomoimagenvectorialMenu_2 = uimenu(app.ExportarimagenMenu_UIAxes2);
            app.CopiarcomoimagenvectorialMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomoimagenvectorialMenu_2Selected, true);
            app.CopiarcomoimagenvectorialMenu_2.Text = 'Copiar como imagen vectorial';
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu3
            app.UIAxes3.ContextMenu = app.ContextMenu3;
            app.ExportarimagenMenu_UIAxes3 = uimenu(app.ContextMenu3);
            app.ExportarimagenMenu_UIAxes3.Text = 'Exportar imagen';
            app.GuardarcomoMenu_3 = uimenu(app.ExportarimagenMenu_UIAxes3);
            app.GuardarcomoMenu_3.MenuSelectedFcn = createCallbackFcn(app, @GuardarcomoMenu_3Selected, true);
            app.GuardarcomoMenu_3.Text = 'Guardar como...';
            app.CopiarcomomapadebitsMenu_3 = uimenu(app.ExportarimagenMenu_UIAxes3);
            app.CopiarcomomapadebitsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomomapadebitsMenu_3Selected, true);
            app.CopiarcomomapadebitsMenu_3.Text = 'Copiar como mapa de bits';
            app.CopiarcomoimagenvectorialMenu_3 = uimenu(app.ExportarimagenMenu_UIAxes3);
            app.CopiarcomoimagenvectorialMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomoimagenvectorialMenu_3Selected, true);
            app.CopiarcomoimagenvectorialMenu_3.Text = 'Copiar como imagen vectorial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualization_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end